classdef AddCircle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddCirclePrimitiveUIFigure  matlab.ui.Figure
        DoneButton                  matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        CircleInputDataPanel        matlab.ui.container.Panel
        CenterLabel                 matlab.ui.control.Label
        CENTER                      matlab.ui.control.EditField
        RadiusLabel                 matlab.ui.control.Label
        RADIUS                      matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp; % Description
    end
    
    methods
        function reset(app)
            app.RADIUS.Value = '';
            app.CENTER.Value = '';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off
            Pos = app.AddCirclePrimitiveUIFigure.Position;
            app.AddCirclePrimitiveUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);                    
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.AddCirclePrimitiveUIFigure.Icon = appIcon;                   
            
            if nargin == 2
                app.ParentApp=parentApp;
            end         
            warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            [~,R] = evalc(app.RADIUS.Value);
            c = strcat('[',app.CENTER.Value,']');
            [~,C] = evalc(c);

            try
                gm = saplab.addcirc(app.ParentApp.MODEL,C,R);
                msg = strcat('Generated Circle Primitive with LABEL (', num2str(gm), ')');
                app.ParentApp.updateMessage(msg);
            catch ME
                style = uistyle("FontColor",'r');
                app.ParentApp.updateMessage(ME.message,style);
                return
            end
            app.ParentApp.updadateCurrGeomDropDownValue(gm);
            saplab.ui.updateLayersAnalysis(app.ParentApp)
            %reset app
            app.reset();
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddCirclePrimitiveUIFigure = uifigure('Visible', 'off');
            app.AddCirclePrimitiveUIFigure.Name = 'Add Circle Primitive';
            app.AddCirclePrimitiveUIFigure.Icon = 'Application Icon.png';
            app.CircleInputDataPanel = uipanel(app.AddCirclePrimitiveUIFigure);
            app.CircleInputDataPanel.Title = 'Circle Input Data';
            app.RADIUS = uieditfield(app.CircleInputDataPanel, 'text');
            app.RADIUS.Tooltip = {'CIRCLE RADIUS'};
            app.RadiusLabel = uilabel(app.CircleInputDataPanel);
            app.RadiusLabel.Text = 'Radius';
            app.CENTER = uieditfield(app.CircleInputDataPanel, 'text');
            app.CENTER.Tooltip = {'X,Y Circle Center Coordinates'};
            app.CenterLabel = uilabel(app.CircleInputDataPanel);
            app.CenterLabel.Text = 'Center';
            app.ApplyButton = uibutton(app.AddCirclePrimitiveUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.AddCirclePrimitiveUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.AddCirclePrimitiveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddCircle(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddCirclePrimitiveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddCirclePrimitiveUIFigure)
        end
    end
end