classdef AddEllipse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GenerateEllipsePrimitiveUIFigure  matlab.ui.Figure
        ApplyButton                  matlab.ui.control.Button
        DoneButton                   matlab.ui.control.Button
        EllipseInputDataPanel        matlab.ui.container.Panel
        degLabel                     matlab.ui.control.Label
        InclinationOptionalLabel     matlab.ui.control.Label
        INCLINATION                  matlab.ui.control.EditField
        VerticalsemiaxissizeLabel    matlab.ui.control.Label
        SEMIAXISB                    matlab.ui.control.EditField
        CenterLabel                  matlab.ui.control.Label
        CENTER                       matlab.ui.control.EditField
        HorizontalsemiaxissizeLabel  matlab.ui.control.Label
        SEMIAXISA                    matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp; % Description
    end

    methods
        function reset(app)
            app.SEMIAXISA.Value = '';
            app.SEMIAXISB.Value = '';
            app.CENTER.Value = '';
            app.INCLINATION.Value = '';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off
            Pos = app.GenerateEllipsePrimitiveUIFigure.Position;
            app.GenerateEllipsePrimitiveUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);                    
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.GenerateEllipsePrimitiveUIFigure.Icon = appIcon;        
            if nargin == 2
                app.ParentApp=parentApp;
            end               
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            try
            [~,Rx] = evalc(app.SEMIAXISA.Value);
            [~,Ry] = evalc(app.SEMIAXISB.Value);
            c = strcat('[',app.CENTER.Value,']');
            [~,C] = evalc(c);          
            
            if isempty(app.INCLINATION.Value), I = 0; else, [~,I] = evalc(app.INCLINATION.Value);  end
            
                gm = saplab.addellip(app.ParentApp.MODEL,C,[Rx,Ry],I);
                Label = app.ParentApp.MODEL.Geometry.csgdb.tag{end};
                msg = strcat('Generate Ellipse Primitive with LABEL (', num2str(Label), ')');
                app.ParentApp.updateMessage(msg);
            catch ME
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage(ME.message,style);
                return
            end
            app.ParentApp.updadateCurrGeomDropDownValue(gm);
            
            saplab.ui.updateLayersAnalysis(app.ParentApp);
            %reset app
            app.reset();
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GenerateEllipsePrimitiveUIFigure = uifigure('Visible', 'off');
            app.GenerateEllipsePrimitiveUIFigure.Name = 'Generate Ellipse Primitive';
            app.GenerateEllipsePrimitiveUIFigure.Icon = 'Application Icon.png';
            app.EllipseInputDataPanel = uipanel(app.GenerateEllipsePrimitiveUIFigure);
            app.EllipseInputDataPanel.Title = 'Ellipse Input Data';
            app.SEMIAXISA = uieditfield(app.EllipseInputDataPanel, 'text');
            app.SEMIAXISA.Tooltip = {'Size'};
            app.HorizontalsemiaxissizeLabel = uilabel(app.EllipseInputDataPanel);
            app.HorizontalsemiaxissizeLabel.Text = 'Horizontal semi-axis size';
            app.CENTER = uieditfield(app.EllipseInputDataPanel, 'text');
            app.CENTER.Tooltip = {'X,Y Circle Center Coordinates'};
            app.CenterLabel = uilabel(app.EllipseInputDataPanel);
            app.CenterLabel.Text = 'Center';
            app.SEMIAXISB = uieditfield(app.EllipseInputDataPanel, 'text');
            app.SEMIAXISB.Tooltip = {'Size'};
            app.VerticalsemiaxissizeLabel = uilabel(app.EllipseInputDataPanel);
            app.VerticalsemiaxissizeLabel.Text = 'Vertical semi-axis size';
            app.INCLINATION = uieditfield(app.EllipseInputDataPanel, 'text');
            app.INCLINATION.Tooltip = {'RECTANGLE DIM Xmin,Xmax,Ymin,Ymax'};
            app.InclinationOptionalLabel = uilabel(app.EllipseInputDataPanel);
            app.InclinationOptionalLabel.Text = 'Inclination (Optional)';
            app.degLabel = uilabel(app.EllipseInputDataPanel);
            app.degLabel.Text = '[deg]';
            app.DoneButton = uibutton(app.GenerateEllipsePrimitiveUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ApplyButton = uibutton(app.GenerateEllipsePrimitiveUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.GenerateEllipsePrimitiveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddEllipse(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GenerateEllipsePrimitiveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GenerateEllipsePrimitiveUIFigure)
        end
    end
end