classdef AddLine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddNewStraightLineUIFigure      matlab.ui.Figure
        InsertingModeButtonGroup        matlab.ui.container.ButtonGroup
        ConnectButton                   matlab.ui.control.RadioButton
        CoordinatesButton               matlab.ui.control.RadioButton
        ApplyButton                     matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        DeleteLinePanel                 matlab.ui.container.Panel
        SelectlinetodeleteSpinner       matlab.ui.control.Spinner
        SelectlinetodeleteSpinnerLabel  matlab.ui.control.Label
        DoneButton                      matlab.ui.control.Button
        GenerateLinePanel               matlab.ui.container.Panel
        InsertingModeLabel              matlab.ui.control.Label
        InsertingModeEditField          matlab.ui.control.EditField
        EndingPointLocationxyzLabel     matlab.ui.control.Label
        StartingPointLocationxyzLabel   matlab.ui.control.Label
        POINT2                          matlab.ui.control.EditField
        POINT1                          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp; % Description
        Model;
        DeleteOption = false;
        InsertingMode = 'Coordinates';
    end

    
    methods
        function resetApp(app)
            app.POINT1.Value = '';          
            app.POINT2.Value = '';  
        end

        function insertingByCoordinates(app)
            try
                %get the input values
                p1Str  = strcat('[',app.POINT1.Value,']');
                p2Str  = strcat('[',app.POINT2.Value,']');
                [~,coord1] = evalc(p1Str);
                [~,coord2] = evalc(p2Str);
 
                if contains(app.Model.AnalysisType,'plane')
                    if numel(coord1) == 3 || numel(coord2) == 3
                        if coord1(3)~=0 || coord2(3) ~=0
                        app.ParentApp.updateMessage('Z coordinate has been set to zero.');
                        end
                    end
                    coord1(3) = 0;
                    coord2(3) = 0;
                end
                %add point. Use try/catch to catch exceptions.
                saplab.addline(app.ParentApp.MODEL,"Coordinates",coord1,coord2)
                if numel(coord1) == 2, coord1(3) = 0; end
                if numel(coord2) == 2, coord2(3) = 0; end
                x1 = coord1(1);
                x2 = coord2(1);
                y1 = coord1(2);
                y2 = coord2(2);
                z1 = coord1(3);
                z2 = coord2(3);
                msg = strcat('Generate straight line segment from point coordinate : (', num2str(x1),';',num2str(y1),';',num2str(z1) , ') to coordinate : (',num2str(x2),';',num2str(y2),';',num2str(z2),')');
                app.ParentApp.updateMessage(msg)
            catch ME
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage(ME.message,style);
            end
        end

        function insertingByConnections(app)
            try
                %get the input values
                [~,pid1] = evalc(app.POINT1.Value);
                [~,pid2] = evalc(app.POINT2.Value);
              
                %connect line. Use try/catch to catch exceptions.
            
                saplab.addline(app.ParentApp.MODEL,"Connect",[pid1,pid2])
                msg = strcat('Generated straight line segment by connecting points : (', num2str(pid1),') ; (',num2str(pid2),')');
                app.ParentApp.updateMessage(msg)
            catch ME
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage(ME.message,style);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off
            Pos = app.AddNewStraightLineUIFigure.Position;
            app.AddNewStraightLineUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);           
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.AddNewStraightLineUIFigure.Icon = appIcon;
            Pos = app.AddNewStraightLineUIFigure.Position;
            app.AddNewStraightLineUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);               
            if nargin == 2
            app.ParentApp = parentApp;
            app.Model = app.ParentApp.MODEL;      
            end
            warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if ~app.DeleteOption
                if strcmpi(app.InsertingMode,'Coordinates')
                    app.insertingByCoordinates();
                else
                    app.insertingByConnections();
                end
            else
                li = app.SelectlinetodeleteSpinner.Value;
                if li > app.ParentApp.MODEL.Geometry.NumLines
                    msg = ['Line (' num2str(li) ') not found.'];
                    app.ParentApp.updateMessage(msg);   return;                  
                end
                try
                    app.ParentApp.MODEL.Geometry.deleteLine(li)
                    msg = ['Line (' num2str(li) ') has been deleted.'];
                    app.ParentApp.updateMessage(msg); 
                    app.SelectlinetodeleteSpinner.Value = app.ParentApp.MODEL.Geometry.NumLines;
                catch ME
                    style = uistyle('FontColor','r');
                    app.ParentApp.updateMessage(ME.message,style);
                end               
            end

            app.ParentApp.plot();
            saplab.ui.updateLayersAnalysis(app.ParentApp);
            app.resetApp();            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if app.AddNewStraightLineUIFigure.Position ~= 520
            app.AddNewStraightLineUIFigure.Position(3) = 520;
            Pos = app.AddNewStraightLineUIFigure.Position;
            app.AddNewStraightLineUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);
            else
            app.AddNewStraightLineUIFigure.Position(3) = 280;
            Pos = app.AddNewStraightLineUIFigure.Position;
            app.AddNewStraightLineUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);  
            end   
            if ~app.DeleteOption
                app.DeleteOption = true;
                app.InsertingModeEditField.Value = 'Delete';
                app.AddNewStraightLineUIFigure.Name = 'Delete Straight Line';
            else
                app.DeleteOption = false;
                app.InsertingModeEditField.Value = 'Add';
                app.AddNewStraightLineUIFigure.Name = 'Add New Straight Line';

                underLineColor = [1 0.5 0.25];
                gobj = findall(app.ParentApp.UIAxes,'Type','Line','Color', underLineColor);
    
                for i=1:numel(gobj)
                    gobj(i).LineWidth = app.ParentApp.MODEL.Graphics.Appearance.LineWidth;
                    gobj(i).MarkerSize = app.ParentApp.MODEL.Graphics.Appearance.MarkerSize;
                    gobj(i).MarkerFaceColor = [0,0,1];
                    gobj(i).Color = [0,0,1];
                end 
            end            
        end

        % Value changed function: SelectlinetodeleteSpinner
        function SelectlinetodeleteSpinnerValueChanged(app, event)
            if isempty(app.ParentApp) 
                return
            end
            
            underLineColor = [1 0.5 0.25];
            gobj = findall(app.ParentApp.UIAxes,'Type','Line','Color', underLineColor);

            for i=1:numel(gobj)
                gobj(i).LineWidth = app.ParentApp.MODEL.Graphics.Appearance.LineWidth;
                gobj(i).MarkerSize = app.ParentApp.MODEL.Graphics.Appearance.MarkerSize;
                gobj(i).Color = [0,0,1];
            end             

            if isempty(app.ParentApp.MODEL.Geometry)
                return;
            end
            if app.ParentApp.MODEL.Geometry.NumLines == 0
                return
            end

            value = app.SelectlinetodeleteSpinner.Value;
            tag2find = ['Line ' num2str(value)];
            axChildren = app.ParentApp.UIAxes.Children;

            gObjects = [];
            for i= 1:numel(axChildren)
                if contains(axChildren(i).Tag,tag2find)
                    gObjects{end+1} = axChildren(i); %#ok
                end
            end
            if isempty(gObjects), return; end

            for i = 1:numel(gObjects)
                gObjects{i}.Color = underLineColor; %#ok
                gObjects{i}.LineWidth = app.ParentApp.MODEL.Graphics.Appearance.LineWidth+2; %#ok
            end
  
        end

        % Value changing function: SelectlinetodeleteSpinner
        function SelectlinetodeleteSpinnerValueChanging(app, event)
        
        end

        % Selection changed function: InsertingModeButtonGroup
        function InsertingModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.InsertingModeButtonGroup.SelectedObject;
            if selectedButton == app.CoordinatesButton
                app.InsertingMode = 'Coordinates';
                app.InsertingModeEditField.Value = 'Coordinates';
                app.StartingPointLocationxyzLabel.Text = 'Starting Point Location (x,y,z)';
                app.EndingPointLocationxyzLabel.Text = 'Ending Point Location (x,y,z)';
            else
                app.InsertingModeEditField.Value = 'Connect';
                app.StartingPointLocationxyzLabel.Text = 'Starting Point Identifier';
                app.EndingPointLocationxyzLabel.Text = 'Ending Point Identifier';
                app.InsertingMode = 'Connect';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddNewStraightLineUIFigure = uifigure('Visible', 'off');
            app.AddNewStraightLineUIFigure.Name = 'Add New Straight Line';
            app.AddNewStraightLineUIFigure.Icon = 'Application Icon.png';
            app.GenerateLinePanel = uipanel(app.AddNewStraightLineUIFigure);
            app.GenerateLinePanel.Title = 'Generate Line';
            app.POINT1 = uieditfield(app.GenerateLinePanel, 'text');
            app.POINT1.Tooltip = {'Format: X,Y,Z'};
            app.POINT2 = uieditfield(app.GenerateLinePanel, 'text');
            app.POINT2.Tooltip = {'Format: X,Y,Z'};
            app.StartingPointLocationxyzLabel = uilabel(app.GenerateLinePanel);
            app.StartingPointLocationxyzLabel.Text = 'Starting Point Location (x,y,z)';
            app.EndingPointLocationxyzLabel = uilabel(app.GenerateLinePanel);
            app.EndingPointLocationxyzLabel.Text = 'Ending Point Location (x,y,z)';
            app.InsertingModeEditField = uieditfield(app.GenerateLinePanel, 'text');
            app.InsertingModeEditField.Editable = 'off';
            app.InsertingModeEditField.Tooltip = {''};
            app.InsertingModeEditField.Value = 'Coordinates';
            app.InsertingModeLabel = uilabel(app.GenerateLinePanel);
            app.InsertingModeLabel.Text = 'Inserting Mode';
            app.DoneButton = uibutton(app.AddNewStraightLineUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.DeleteLinePanel = uipanel(app.AddNewStraightLineUIFigure);
            app.DeleteLinePanel.Title = 'Delete Line';
            app.SelectlinetodeleteSpinnerLabel = uilabel(app.DeleteLinePanel);
            app.SelectlinetodeleteSpinnerLabel.Text = 'Select line to delete';
            app.SelectlinetodeleteSpinner = uispinner(app.DeleteLinePanel);
            app.SelectlinetodeleteSpinner.ValueChangingFcn = createCallbackFcn(app, @SelectlinetodeleteSpinnerValueChanging, true);
            app.SelectlinetodeleteSpinner.Limits = [0 Inf];
            app.SelectlinetodeleteSpinner.RoundFractionalValues = 'on';
            app.SelectlinetodeleteSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectlinetodeleteSpinnerValueChanged, true);
            app.SelectlinetodeleteSpinner.Value = 1;
            app.DeleteButton = uibutton(app.AddNewStraightLineUIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete >>';
            app.ApplyButton = uibutton(app.AddNewStraightLineUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.InsertingModeButtonGroup = uibuttongroup(app.AddNewStraightLineUIFigure);
            app.InsertingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InsertingModeButtonGroupSelectionChanged, true);
            app.InsertingModeButtonGroup.Title = 'Inserting Mode';
            app.CoordinatesButton = uiradiobutton(app.InsertingModeButtonGroup);
            app.CoordinatesButton.Text = 'Coordinates';
            app.CoordinatesButton.Value = true;
            app.ConnectButton = uiradiobutton(app.InsertingModeButtonGroup);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.AddNewStraightLineUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddLine(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AddNewStraightLineUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AddNewStraightLineUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddNewStraightLineUIFigure)
        end
    end
end