classdef AddLine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure  matlab.ui.Figure
        EndingpointcoordinateBFormatXYZLabel  matlab.ui.control.Label
        StartingpointcoordinateAFormatXYZLabel  matlab.ui.control.Label
        InputargumentsLabel         matlab.ui.control.Label
        ApplyButton                 matlab.ui.control.Button
        ImportfromtxtorxlsCheckBox  matlab.ui.control.CheckBox
        HelpButton                  matlab.ui.control.Button
        DoneButton                  matlab.ui.control.Button
        GenerateStraightLineFromPointCoordinatesLabel  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        XYZinGCSLabel_2             matlab.ui.control.Label
        BLabel                      matlab.ui.control.Label
        ALabel                      matlab.ui.control.Label
        POINT2                      matlab.ui.control.EditField
        XYZinGCSLabel               matlab.ui.control.Label
        POINT1                      matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp; % Description
        Model;
    end

    
    methods
        function resetApp(app)
            app.POINT1.Value = '';          
            app.POINT2.Value = '';  
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            Pos = app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure.Position;
            app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);  
            saplab.ui.custom.customButton(app.ApplyButton)
            saplab.ui.custom.customButton(app.DoneButton)
            saplab.ui.custom.customButton(app.HelpButton)                
            
            if nargin == 2
            app.ParentApp = parentApp;
            app.Model = app.ParentApp.MODEL;      
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            try
                %get the input values
                p1Str  = strcat('[',app.POINT1.Value,']');
                p2Str  = strcat('[',app.POINT2.Value,']');
                [~,coord1] = evalc(p1Str);
                [~,coord2] = evalc(p2Str);
                
                if contains(app.Model.AnalysisType,'plane')
                    if numel(coord1) == 3 || numel(coord2) == 3
                        if coord1(3)~=0 || coord2(3) ~=0
                        style = uistyle('FontColor','b');
                        app.ParentApp.updateMessage('Z coordinate has been set to zero.',style);
                        end
                    end
                    coord1(3) = [];
                    coord2(3) = [];
                end
                %add point. Use try/catch to catch exceptions.
                addline(app.ParentApp.MODEL,"set",coord1,coord2)
                if numel(coord1) == 2, coord1(3) = 0; end
                if numel(coord2) == 2, coord2(3) = 0; end
                x1 = coord1(1);
                x2 = coord2(1);
                y1 = coord1(2);
                y2 = coord2(2);
                z1 = coord1(3);
                z2 = coord2(3);
                msg = strcat('Generate Straight Line From Coordinate :', num2str(x1),';',num2str(y1),';',num2str(z1) , ' To Coordinate : ',num2str(x2),';',num2str(y2),';',num2str(z2));
                app.ParentApp.updateMessage(msg)
            catch ME
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage(ME.message,style);
            end

            app.ParentApp.plot();
            saplab.ui.updateLayersAnalysis(app.ParentApp);
            app.resetApp();            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure = uifigure('Visible', 'off');
            app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure.Name = 'GENERATE STRAIGHT LINE FROM POINT COORDINATES';
            app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure.Icon = 'Application Icon.png';
            app.Panel = uipanel(app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure);
            app.POINT1 = uieditfield(app.Panel, 'text');
            app.POINT1.Tooltip = {'Format: X,Y,Z'};
            app.XYZinGCSLabel = uilabel(app.Panel);
            app.XYZinGCSLabel.Text = 'X,Y,Z in GCS';
            app.POINT2 = uieditfield(app.Panel, 'text');
            app.POINT2.Tooltip = {'Format: X,Y,Z'};
            app.ALabel = uilabel(app.Panel);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.Panel);
            app.BLabel.Text = 'B';
            app.XYZinGCSLabel_2 = uilabel(app.Panel);
            app.XYZinGCSLabel_2.Text = 'X,Y,Z in GCS';
            app.GenerateStraightLineFromPointCoordinatesLabel = uilabel(app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure);
            app.GenerateStraightLineFromPointCoordinatesLabel.Text = 'Generate Straight Line From Point Coordinates';
            app.DoneButton = uibutton(app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.HelpButton = uibutton(app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.ImportfromtxtorxlsCheckBox = uicheckbox(app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure);
            app.ImportfromtxtorxlsCheckBox.Text = 'Import from .txt or .xls';
            app.ApplyButton = uibutton(app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.InputargumentsLabel = uilabel(app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure);
            app.InputargumentsLabel.Text = 'Input arguments:';
            app.StartingpointcoordinateAFormatXYZLabel = uilabel(app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure);
            app.StartingpointcoordinateAFormatXYZLabel.Text = '- Starting point coordinate (A)  - Format (X,Y,Z)';
            app.EndingpointcoordinateBFormatXYZLabel = uilabel(app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure);
            app.EndingpointcoordinateBFormatXYZLabel.Text = '- Ending point coordinate (B) - Format (X,Y,Z)';
            % Show the figure after all components are created
            app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddLine(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GENERATESTRAIGHTLINEFROMPOINTCOORDINATESUIFigure)
        end
    end
end