classdef AddPoint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddNewPointUIFigure      matlab.ui.Figure
        deleteButton             matlab.ui.control.Button
        DeletePointPanel         matlab.ui.container.Panel
        SelectpointSpinner       matlab.ui.control.Spinner
        SelectpointSpinnerLabel  matlab.ui.control.Label
        ApplyButton              matlab.ui.control.Button
        DoneButton               matlab.ui.control.Button
        GeneratePointPanel       matlab.ui.container.Panel
        InsertingModeLabel       matlab.ui.control.Label
        InsertingModeEditField   matlab.ui.control.EditField
        ZCoordinateLabel         matlab.ui.control.Label
        ZCoordinate              matlab.ui.control.EditField
        YCoordinateLabel         matlab.ui.control.Label
        XCoordinateLabel         matlab.ui.control.Label
        YCoordinate              matlab.ui.control.EditField
        XCoordinate              matlab.ui.control.EditField
    end

    
    properties (Access = public)
        ParentApp; % Description
        Model;
        DeleteOption = false;
    end

    methods
        function reset(app)
            app.XCoordinate.Value = '';
            app.YCoordinate.Value = '';
            app.ZCoordinate.Value = '';            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off
            Pos = app.AddNewPointUIFigure.Position;
            app.AddNewPointUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);   

            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.AddNewPointUIFigure.Icon = appIcon;
            Pos = app.AddNewPointUIFigure.Position;
            app.AddNewPointUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);   

            if nargin == 2
            app.ParentApp = parentApp;
            app.Model = app.ParentApp.MODEL;
            if contains(app.Model.AnalysisType,'plane')
                app.ZCoordinate.Enable = 'off';
                app.ZCoordinateLabel.Enable = 'off';
            end
            end
            warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            if ~app.DeleteOption
                try
                %get the input values
                if ~isempty(app.XCoordinate.Value)
                    [~,x] = evalc(app.XCoordinate.Value);
                else
                    x = 0;
                end
                if ~isempty(app.YCoordinate.Value)
                    [~,y] = evalc(app.YCoordinate.Value);
                else
                    y = 0;
                end
                if ~isempty(app.ZCoordinate.Value)
                    [~,z] = evalc(app.ZCoordinate.Value);
                else
                    z = 0;
                end
    
                coord=[x,y,z];
                if contains(app.Model.AnalysisType,'plane')
                    coord = [x,y];
                end
                %add point. Use try/catch to catch exceptions.
                    saplab.addpoint(app.ParentApp.MODEL,coord);
                    msg = strcat('Generate Point with Coordinate in GCS (', num2str(x),';',num2str(y),';',num2str(z),')');
                    app.ParentApp.updateMessage(msg)
                catch ME
                    style = uistyle('FontColor','r');
                    app.ParentApp.updateMessage(ME.message,style);
                    return
                end
            else
                pi = app.SelectpointSpinner.Value;
                try
                    app.ParentApp.MODEL.Geometry.deletePoint(pi)
                    msg = ['Point (' num2str(pi) ') has been deleted.'];
                    app.ParentApp.updateMessage(msg); 
                    app.SelectpointSpinner.Value = app.ParentApp.MODEL.Geometry.NumPoints;
                catch ME
                    style = uistyle('FontColor','r');
                    app.ParentApp.updateMessage(ME.message,style);
                end

            end

            app.ParentApp.plot();
            saplab.ui.updateLayersAnalysis(app.ParentApp);
            app.reset();
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete();
        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
            if app.AddNewPointUIFigure.Position ~= 470
            app.AddNewPointUIFigure.Position(3) = 470;
            Pos = app.AddNewPointUIFigure.Position;
            app.AddNewPointUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);
            else
            app.AddNewPointUIFigure.Position(3) = 235;
            Pos = app.AddNewPointUIFigure.Position;
            app.AddNewPointUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);  
            end   
            if ~app.DeleteOption
                app.DeleteOption = true;
                app.InsertingModeEditField.Value = 'Delete';
                app.AddNewPointUIFigure.Name = 'Delete Point';
            else
                app.DeleteOption = false;
                app.InsertingModeEditField.Value = 'Add';
                app.AddNewPointUIFigure.Name = 'Add New Point';

                underLineColor = [1 0.5 0.25];
                gobj = findall(app.ParentApp.UIAxes,'Type','Line','Color', underLineColor);
    
                for i=1:numel(gobj)
                    gobj(i).LineWidth = app.ParentApp.MODEL.Graphics.Appearance.LineWidth;
                    gobj(i).MarkerSize = app.ParentApp.MODEL.Graphics.Appearance.MarkerSize;
                    gobj(i).MarkerFaceColor = [0,0,1];
                    gobj(i).Color = [0,0,1];
                end 

            end
        end

        % Value changed function: InsertingModeEditField
        function InsertingModeEditFieldValueChanged(app, event)

        end

        % Value changed function: SelectpointSpinner
        function SelectpointSpinnerValueChanged(app, event)
            underLineColor = [1 0.5 0.25];
            gobj = findall(app.ParentApp.UIAxes,'Type','Line','Color', underLineColor);

            for i=1:numel(gobj)
                gobj(i).LineWidth = app.ParentApp.MODEL.Graphics.Appearance.LineWidth;
                gobj(i).MarkerSize = app.ParentApp.MODEL.Graphics.Appearance.MarkerSize;
                gobj(i).MarkerFaceColor = [0,0,1];
                gobj(i).Color = [0,0,1];
            end             
            value = app.SelectpointSpinner.Value;
            if isempty(app.ParentApp) 
                return
            end
            if isempty(app.ParentApp.MODEL.Geometry)
                return;
            end
            if app.ParentApp.MODEL.Geometry.NumPoints == 0
                return
            end
            if value > app.ParentApp.MODEL.Geometry.NumPoints
                app.SelectpointSpinner.Value = app.ParentApp.MODEL.Geometry.NumPoints;
            end
            value = app.SelectpointSpinner.Value;
            
            tag2find = ['Point ' num2str(value)];
            obj = findobj(app.ParentApp.UIAxes,'Tag', tag2find);
            
            obj.MarkerSize = app.ParentApp.MODEL.Graphics.Appearance.MarkerSize+2;
            obj.Color = underLineColor;
            obj.MarkerFaceColor = underLineColor;
            
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddNewPointUIFigure = uifigure('Visible', 'off');
            app.AddNewPointUIFigure.Name = 'Add New Point';
            app.AddNewPointUIFigure.Icon = 'Application Icon.png';
            app.GeneratePointPanel = uipanel(app.AddNewPointUIFigure);
            app.GeneratePointPanel.Title = 'Generate Point';
            app.XCoordinate = uieditfield(app.GeneratePointPanel, 'text');
            app.XCoordinate.Tooltip = {'X'};
            app.YCoordinate = uieditfield(app.GeneratePointPanel, 'text');
            app.YCoordinate.Tooltip = {'Y'};
            app.XCoordinateLabel = uilabel(app.GeneratePointPanel);
            app.XCoordinateLabel.Text = 'X - Coordinate';
            app.YCoordinateLabel = uilabel(app.GeneratePointPanel);
            app.YCoordinateLabel.Text = 'Y - Coordinate';
            app.ZCoordinate = uieditfield(app.GeneratePointPanel, 'text');
            app.ZCoordinate.Tooltip = {'Z'};
            app.ZCoordinateLabel = uilabel(app.GeneratePointPanel);
            app.ZCoordinateLabel.Text = 'Z - Coordinate';
            app.InsertingModeEditField = uieditfield(app.GeneratePointPanel, 'text');
            app.InsertingModeEditField.ValueChangedFcn = createCallbackFcn(app, @InsertingModeEditFieldValueChanged, true);
            app.InsertingModeEditField.Editable = 'off';
            app.InsertingModeEditField.Tooltip = {''};
            app.InsertingModeEditField.Value = 'Add';
            app.InsertingModeLabel = uilabel(app.GeneratePointPanel);
            app.InsertingModeLabel.Text = 'Inserting Mode';
            app.DoneButton = uibutton(app.AddNewPointUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ApplyButton = uibutton(app.AddNewPointUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DeletePointPanel = uipanel(app.AddNewPointUIFigure);
            app.DeletePointPanel.Title = 'Delete Point';
            app.SelectpointSpinnerLabel = uilabel(app.DeletePointPanel);
            app.SelectpointSpinnerLabel.Text = 'Select point';
            app.SelectpointSpinner = uispinner(app.DeletePointPanel);
            app.SelectpointSpinner.Limits = [1 Inf];
            app.SelectpointSpinner.RoundFractionalValues = 'on';
            app.SelectpointSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectpointSpinnerValueChanged, true);
            app.SelectpointSpinner.Value = 1;
            app.deleteButton = uibutton(app.AddNewPointUIFigure, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Text = 'delete >>';
            % Show the figure after all components are created
            app.AddNewPointUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddPoint(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddNewPointUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddNewPointUIFigure)
        end
    end
end