classdef AddPolygon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddPolygonPrimitiveUIFigure  matlab.ui.Figure
        ClearButton                  matlab.ui.control.Button
        DeleteRowButton              matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        AddPolygonTablePanel         matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        DoneButton                   matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ParentApp; % Description
    end
    
    methods
        function reset(app)
            app.RADIUS.Value = '';
            app.CENTER.Value = '';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off
            saplab.ui.custom.customToolBarButton(app.AddButton)
            saplab.ui.custom.customToolBarButton(app.DeleteRowButton)
            saplab.ui.custom.customToolBarButton(app.ClearButton)

            addStyle(app.UITable,uistyle('HorizontalAlignment','center'));
            Pos = app.AddPolygonPrimitiveUIFigure.Position;
            app.AddPolygonPrimitiveUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);                    
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.AddPolygonPrimitiveUIFigure.Icon = appIcon;                   
            addIcon = struct(matlab.ui.internal.toolstrip.Icon.NEW_16).ImageSource;
            app.AddButton.Icon = addIcon;
            
            clearButton = struct(matlab.ui.internal.toolstrip.Icon.DELETE_16).ImageSource;
            app.DeleteRowButton.Icon = 'delete_24.png';
            app.ClearButton.Icon = clearButton;

            if nargin == 2
                app.ParentApp=parentApp;
            end         
            iMat = magic(3);
            app.UITable.Data = iMat(:,1:2);
            warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)

            try
            coords = app.UITable.Data;
            coords = double(coords);
            coords = rmmissing(coords);

            gm = saplab.addpoly(app.ParentApp.MODEL,coords(:,1),coords(:,2));
            msg = strcat('Generated Polygon Primitive with LABEL (', num2str(gm), ')');
            app.ParentApp.updateMessage(msg);
            catch ME
                style = uistyle("FontColor",'r');
                app.ParentApp.updateMessage(ME.message,style);
                return
            end
            app.ParentApp.updadateCurrGeomDropDownValue(gm);

            saplab.ui.updateLayersAnalysis(app.ParentApp)
            %reset app
            app.reset();
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete()
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.UITable.Data = [app.UITable.Data; "Inserting X", "Inserting Y"];

        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            if ~isempty(app.UITable.Data) && size(app.UITable.Data,1)>1
            app.UITable.Data(end,:) = [];
            end            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UITable.Data = [0,0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddPolygonPrimitiveUIFigure = uifigure('Visible', 'off');
            app.AddPolygonPrimitiveUIFigure.Name = 'Add Polygon Primitive';
            app.AddPolygonPrimitiveUIFigure.Icon = 'Application Icon.png';
            app.ApplyButton = uibutton(app.AddPolygonPrimitiveUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.AddPolygonPrimitiveUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.AddPolygonTablePanel = uipanel(app.AddPolygonPrimitiveUIFigure);
            app.AddPolygonTablePanel.Title = 'Add Polygon Table';
            app.UITable = uitable(app.AddPolygonTablePanel);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.ColumnEditable = true;
            app.AddButton = uibutton(app.AddPolygonPrimitiveUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '';
            app.DeleteRowButton = uibutton(app.AddPolygonPrimitiveUIFigure, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = '';
            app.ClearButton = uibutton(app.AddPolygonPrimitiveUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = '';
            % Show the figure after all components are created
            app.AddPolygonPrimitiveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddPolygon(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AddPolygonPrimitiveUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AddPolygonPrimitiveUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddPolygonPrimitiveUIFigure)
        end
    end
end