classdef AddRectangle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddRectangleSquarePrimitiveUIFigure  matlab.ui.Figure
        ApplyButton             matlab.ui.control.Button
        DoneButton              matlab.ui.control.Button
        RectangleSquarePanel    matlab.ui.container.Panel
        CornercoordinatesLabel  matlab.ui.control.Label
        CORNERCOORDS            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp; % Description
    end
    
    methods
        function reset(app)
            app.CORNERCOORDS.Value = '';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off
            Pos = app.AddRectangleSquarePrimitiveUIFigure.Position;
            app.AddRectangleSquarePrimitiveUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);                    
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.AddRectangleSquarePrimitiveUIFigure.Icon = appIcon;       
            if nargin == 2
                app.ParentApp=parentApp;
            end
            warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            cCoords = strcat('[',app.CORNERCOORDS.Value,']');
            [~,cCoords] = evalc(cCoords);
            try
                gm = saplab.addrect(app.ParentApp.MODEL,cCoords);
                msg = strcat('Generated Rectangle Primitive with LABEL (', num2str(gm), ')');
                app.ParentApp.updateMessage(msg);
            catch ME
                app.ParentApp.updateMessage(ME.message);
                return
            end
            app.ParentApp.updadateCurrGeomDropDownValue(gm);
            saplab.ui.updateLayersAnalysis(app.ParentApp)
            %reset
            app.reset();
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddRectangleSquarePrimitiveUIFigure = uifigure('Visible', 'off');
            app.AddRectangleSquarePrimitiveUIFigure.Name = 'Add Rectangle/Square Primitive';
            app.AddRectangleSquarePrimitiveUIFigure.Icon = 'Application Icon.png';
            app.RectangleSquarePanel = uipanel(app.AddRectangleSquarePrimitiveUIFigure);
            app.RectangleSquarePanel.Tooltip = {'FORMULA'};
            app.RectangleSquarePanel.Title = 'Rectangle/Square';
            app.CORNERCOORDS = uieditfield(app.RectangleSquarePanel, 'text');
            app.CORNERCOORDS.Tooltip = {'RECTANGLE DIM Xmin,Xmax,Ymin,Ymax'};
            app.CornercoordinatesLabel = uilabel(app.RectangleSquarePanel);
            app.CornercoordinatesLabel.Text = 'Corner coordinates ';
            app.DoneButton = uibutton(app.AddRectangleSquarePrimitiveUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ApplyButton = uibutton(app.AddRectangleSquarePrimitiveUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.AddRectangleSquarePrimitiveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AddRectangle(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddRectangleSquarePrimitiveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddRectangleSquarePrimitiveUIFigure)
        end
    end
end