classdef BodyLoads < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ApplyBodyForcesUIFigure         matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        DoneButton                      matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        RegionIDLabel_2                 matlab.ui.control.Label
        DownButton                      matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ThermalloadLabel                matlab.ui.control.Label
        SelfWeightLabel                 matlab.ui.control.Label
        SelectRegionButtonGroup         matlab.ui.container.ButtonGroup
        TemperatureLabel                matlab.ui.control.Label
        TEMPERATURE                     matlab.ui.control.EditField
        RegionIDLabel                   matlab.ui.control.Label
        REGIONID                        matlab.ui.control.EditField
        GravitationalAccelerationLabel  matlab.ui.control.Label
        GRAVITY                         matlab.ui.control.EditField
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
    end

    
    properties (Access = private)
        RegionType;
        RegionID;
        ParentApp; % Description
        NumRegions;
    end

    methods
        function initApp(app)
            app.RegionType = 'Face';
            if contains(app.ParentApp.MODEL.AnalysisType, 'frame')
                app.RegionType = 'Line';
                app.NumRegions = app.ParentApp.MODEL.Geometry.NumLines;
            else
                app.NumRegions = app.ParentApp.MODEL.Geometry.NumFaces;
            end
        end

        function reset(app)
            app.GRAVITY.Value = '';
            app.RegionID.Value = '';
        end

        function args = parserInputScheme(app)
             args = [];
             if ~isempty(app.GRAVITY.Value)
                 [~,g] = evalc(app.GRAVITY.Value);
                 args = {'GravitationalAcceleration', g};
             end
        
             if ~isempty(app.TEMPERATURE.Value)
                 [~,t] = evalc(app.TEMPERATURE.Value);
                 args = [args, {'Temperature', t}];
             end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            Pos = app.ApplyBodyForcesUIFigure.Position;
            app.ApplyBodyForcesUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);  
            upIcon = struct(matlab.ui.internal.toolstrip.Icon.UP_16).ImageSource;
            downIcon = struct(matlab.ui.internal.toolstrip.Icon.DOWN_16).ImageSource;

            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.ApplyBodyForcesUIFigure.Icon = appIcon;  
            app.UpButton.Icon = upIcon;
            app.DownButton.Icon = downIcon;
            if nargin == 2
                app.ParentApp = parentApp;
                app.initApp();
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
          try
              [~,app.RegionID] = evalc(app.REGIONID.Value);
              args = parserInputScheme(app);
              structuramodel = app.ParentApp.MODEL;
              for i=1:numel(app.RegionID)
                  saplab.applyBody(structuramodel, app.RegionType, app.RegionID(i), args{:});
                  app.ParentApp.updateMessage(['Body Load applied successfully to ' char(app.RegionType) ' region with ID (' num2str(app.RegionID(i)) ')']); 
              end
          catch ME
              app.ParentApp.updateMessage(ME.message); 
          end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ApplyBodyForcesUIFigure = uifigure('Visible', 'off');
            app.ApplyBodyForcesUIFigure.Name = 'Apply Body Forces';
            app.ApplyBodyForcesUIFigure.Icon = 'Application Icon.png';
            app.ButtonGroup_2 = uibuttongroup(app.ApplyBodyForcesUIFigure);
            app.SelectRegionButtonGroup = uibuttongroup(app.ApplyBodyForcesUIFigure);
            app.SelectRegionButtonGroup.Title = 'Select Region';
            app.GRAVITY = uieditfield(app.SelectRegionButtonGroup, 'text');
            app.GRAVITY.Tooltip = {''};
            app.GravitationalAccelerationLabel = uilabel(app.SelectRegionButtonGroup);
            app.GravitationalAccelerationLabel.Text = 'Gravitational Acceleration';
            app.REGIONID = uieditfield(app.SelectRegionButtonGroup, 'text');
            app.REGIONID.Tooltip = {''};
            app.RegionIDLabel = uilabel(app.SelectRegionButtonGroup);
            app.RegionIDLabel.Text = 'Region ID';
            app.TEMPERATURE = uieditfield(app.SelectRegionButtonGroup, 'text');
            app.TEMPERATURE.Tooltip = {''};
            app.TemperatureLabel = uilabel(app.SelectRegionButtonGroup);
            app.TemperatureLabel.Text = 'Temperature';
            app.SelfWeightLabel = uilabel(app.ApplyBodyForcesUIFigure);
            app.SelfWeightLabel.Text = 'Self Weight:';
            app.ThermalloadLabel = uilabel(app.ApplyBodyForcesUIFigure);
            app.ThermalloadLabel.Text = 'Thermal load:';
            app.Label = uilabel(app.ApplyBodyForcesUIFigure);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.ApplyBodyForcesUIFigure);
            app.Label_2.Text = '0';
            app.UpButton = uibutton(app.ApplyBodyForcesUIFigure, 'push');
            app.UpButton.Text = '';
            app.DownButton = uibutton(app.ApplyBodyForcesUIFigure, 'push');
            app.DownButton.Text = '';
            app.RegionIDLabel_2 = uilabel(app.ApplyBodyForcesUIFigure);
            app.RegionIDLabel_2.Text = 'RegionID:';
            app.Label_3 = uilabel(app.ApplyBodyForcesUIFigure);
            app.Label_3.Text = '0';
            app.Panel = uipanel(app.ApplyBodyForcesUIFigure);
            app.ApplyButton = uibutton(app.Panel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.Panel, 'push');
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.ApplyBodyForcesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BodyLoads(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ApplyBodyForcesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ApplyBodyForcesUIFigure)
        end
    end
end