classdef BoolOper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComposeGeometriesUIFigure       matlab.ui.Figure
        KeepinternaledgesOptionalLabel  matlab.ui.control.Label
        SetFormulaFLabel                matlab.ui.control.Label
        InputargumentsLabel             matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        ComposeGeometrieswithbooleanoperationPanel  matlab.ui.container.Panel
        KeepInternalEdgesCheckBox       matlab.ui.control.CheckBox
        GroupgeomentitiesLabel          matlab.ui.control.Label
        ORIntersectionLabel             matlab.ui.control.Label
        NOTDifferenceLabel              matlab.ui.control.Label
        ANDUnionLabel                   matlab.ui.control.Label
        FLabel                          matlab.ui.control.Label
        FORMULA                         matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp; % Description
    end
    
    methods
        function reset(app)
            app.FORMULA.Value = '';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
           warning off
           Pos = app.ComposeGeometriesUIFigure.Position;
           app.ComposeGeometriesUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);                             
           appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
           app.ComposeGeometriesUIFigure.Icon = appIcon;              
           if nargin == 2
                app.ParentApp=parentApp;
           end      
           warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            Formula = app.FORMULA.Value;
            KeepInternal = app.KeepInternalEdgesCheckBox.Value;
            if KeepInternal, KeepInternal = 'on'; else, KeepInternal = 'off'; end
            if isempty(Formula), return; end
            try
                gm = saplab.booloper(app.ParentApp.MODEL,Formula,"KeepInternalEdges",KeepInternal);
                Label = app.ParentApp.MODEL.Geometry.csgdb.tag{end};
                msg = strcat('Generated Composite Object with LABEL (', num2str(Label), ')');
                app.ParentApp.updateMessage(msg);
            catch ME
                app.ParentApp.updateMessage(ME.message);
                return
            end   
            app.ParentApp.updadateCurrGeomDropDownValue(gm);  
            saplab.ui.updateLayersAnalysis(app.ParentApp)
            %reset app
            app.reset();
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComposeGeometriesUIFigure = uifigure('Visible', 'off');
            app.ComposeGeometriesUIFigure.Name = 'Compose Geometries';
            app.ComposeGeometriesUIFigure.Icon = 'Application Icon.png';
            app.ComposeGeometrieswithbooleanoperationPanel = uipanel(app.ComposeGeometriesUIFigure);
            app.ComposeGeometrieswithbooleanoperationPanel.Title = 'Compose Geometries with boolean operation';
            app.FORMULA = uieditfield(app.ComposeGeometrieswithbooleanoperationPanel, 'text');
            app.FORMULA.Tooltip = {'FORMULA'};
            app.FLabel = uilabel(app.ComposeGeometrieswithbooleanoperationPanel);
            app.FLabel.Text = 'F';
            app.ANDUnionLabel = uilabel(app.ComposeGeometrieswithbooleanoperationPanel);
            app.ANDUnionLabel.Text = '[+] - AND (Union)';
            app.NOTDifferenceLabel = uilabel(app.ComposeGeometrieswithbooleanoperationPanel);
            app.NOTDifferenceLabel.Tooltip = {'FORMULA'};
            app.NOTDifferenceLabel.Text = '[-] - NOT (Difference)';
            app.ORIntersectionLabel = uilabel(app.ComposeGeometrieswithbooleanoperationPanel);
            app.ORIntersectionLabel.Text = '[*] - OR (Intersection)';
            app.GroupgeomentitiesLabel = uilabel(app.ComposeGeometrieswithbooleanoperationPanel);
            app.GroupgeomentitiesLabel.Text = '[()] - Group geom. entities';
            app.KeepInternalEdgesCheckBox = uicheckbox(app.ComposeGeometrieswithbooleanoperationPanel);
            app.KeepInternalEdgesCheckBox.Text = 'Keep Internal Edges';
            app.DoneButton = uibutton(app.ComposeGeometriesUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ApplyButton = uibutton(app.ComposeGeometriesUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.InputargumentsLabel = uilabel(app.ComposeGeometriesUIFigure);
            app.InputargumentsLabel.Text = 'Input arguments:';
            app.SetFormulaFLabel = uilabel(app.ComposeGeometriesUIFigure);
            app.SetFormulaFLabel.Text = '- Set Formula (F)';
            app.KeepinternaledgesOptionalLabel = uilabel(app.ComposeGeometriesUIFigure);
            app.KeepinternaledgesOptionalLabel.Text = '- Keep internal edges (Optional)';
            % Show the figure after all components are created
            app.ComposeGeometriesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BoolOper(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComposeGeometriesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComposeGeometriesUIFigure)
        end
    end
end