classdef BoundaryCondition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ApplyBoundaryConditionsUIFigure  matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        AtypeLabel                    matlab.ui.control.Label
        AnalysisTypeLabel             matlab.ui.control.Label
        RegionIDSpinner               matlab.ui.control.Spinner
        RegionIDSpinnerLabel          matlab.ui.control.Label
        BCTypeLabel                   matlab.ui.control.Label
        BoundaryConditionTypeLabel    matlab.ui.control.Label
        DoneButton                    matlab.ui.control.Button
        ApplyButton                   matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        ConstraintTab                 matlab.ui.container.Tab
        SelectdofstofixPanel          matlab.ui.container.Panel
        FixZRotationLabel             matlab.ui.control.Label
        ROTZCheckBox                  matlab.ui.control.CheckBox
        FixXRotationLabel             matlab.ui.control.Label
        FixYRotationLabel             matlab.ui.control.Label
        FixZdisplacementLabel         matlab.ui.control.Label
        FixYdisplacementLabel         matlab.ui.control.Label
        FixxdisplacementLabel         matlab.ui.control.Label
        ROTYCheckBox                  matlab.ui.control.CheckBox
        ROTXCheckBox                  matlab.ui.control.CheckBox
        UZCheckBox                    matlab.ui.control.CheckBox
        UYCheckBox                    matlab.ui.control.CheckBox
        UXCheckBox                    matlab.ui.control.CheckBox
        SelectBoundaryRegionTypeButtonGroup  matlab.ui.container.ButtonGroup
        EdgeButton                    matlab.ui.control.RadioButton
        VertexButton                  matlab.ui.control.RadioButton
        PointButton                   matlab.ui.control.RadioButton
        ForceTab                      matlab.ui.container.Tab
        ApplyboundaryforcesPanel      matlab.ui.container.Panel
        MomentaroundZglobalaxisLabel  matlab.ui.control.Label
        Mz                            matlab.ui.control.EditField
        MomentaroundYglobalaxisLabel  matlab.ui.control.Label
        My                            matlab.ui.control.EditField
        MomentaroundXglobalaxisLabel  matlab.ui.control.Label
        Mx                            matlab.ui.control.EditField
        ForcealongZglobalaxisLabel    matlab.ui.control.Label
        Fz                            matlab.ui.control.EditField
        ForcealongYglobalaxisLabel    matlab.ui.control.Label
        ForcealongXglobalaxisLabel    matlab.ui.control.Label
        ValueLabel                    matlab.ui.control.Label
        ParameterLabel                matlab.ui.control.Label
        Fy                            matlab.ui.control.EditField
        Fx                            matlab.ui.control.EditField
        DisplacementTab               matlab.ui.container.Tab
        ApplyboundarydisplacementsPanel  matlab.ui.container.Panel
        RotaionaroundZglobalaxisLabel_2  matlab.ui.control.Label
        Rz                            matlab.ui.control.EditField
        RotaionaroundYglobalaxisLabel_2  matlab.ui.control.Label
        Ry                            matlab.ui.control.EditField
        RotaionaroundXglobalaxisLabel_2  matlab.ui.control.Label
        Rx                            matlab.ui.control.EditField
        DisplacementalongZglobalaxisLabel  matlab.ui.control.Label
        Uz                            matlab.ui.control.EditField
        DisplacementalongYglobalaxisLabel  matlab.ui.control.Label
        DisplacementalongXglobalaxisLabel  matlab.ui.control.Label
        ValueLabel_6                  matlab.ui.control.Label
        ParameterLabel_6              matlab.ui.control.Label
        Uy                            matlab.ui.control.EditField
        Ux                            matlab.ui.control.EditField
        PressureTab                   matlab.ui.container.Tab
        AppypressureonboundaryPanel   matlab.ui.container.Panel
        TangentialPressureComponentLabel  matlab.ui.control.Label
        TangentialPressure            matlab.ui.control.EditField
        NormalPressureComponentLabel  matlab.ui.control.Label
        ValueLabel_5                  matlab.ui.control.Label
        ParameterLabel_5              matlab.ui.control.Label
        NormalPressure                matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp;
        RegionID;
        RegionType;
        AnalysisType = 'static-planeframe';
    end
    
    methods (Access = private)
        
        function init(app)
            if contains(app.AnalysisType,'frame')
               if contains(app.AnalysisType,'plane')
                    app.ROTXCheckBox.Enable = 'off';
                    app.ROTYCheckBox.Enable = 'off';
                    app.UZCheckBox.Enable = 'off';
                    app.Fz.Enable = 'off';
                    app.Mx.Enable = 'off';
                    app.My.Enable = 'off';
                    app.Uz.Enable = 'off';
                    app.Ry.Enable = 'off';
                    app.Rx.Enable = 'off';
                    app.RegionType = 'Point';
               end
               app.VertexButton.Enable = 'off';
               app.EdgeButton.Enable = 'off';
            else
                app.ROTXCheckBox.Enable = 'off';
                app.ROTYCheckBox.Enable = 'off';
                app.ROTZCheckBox.Enable = 'off';
                app.UZCheckBox.Enable = 'off';
                app.Fz.Enable = 'off';
                app.Mx.Enable = 'off';
                app.My.Enable = 'off';
                app.Mz.Enable = 'off';
                app.Uz.Enable = 'off';
                app.Ry.Enable = 'off';
                app.Rx.Enable = 'off';
                app.Rz.Enable = 'off';
                app.VertexButton.Value = true;
                app.PointButton.Enable = 'off';
                app.RegionType = 'Vertex';
            end
            app.AtypeLabel.Text = app.AnalysisType;
        end

        function [bc,tf] = setConstraint(app)
               tf = false;
               bc = translateBCtoString(app);
               app.RegionID = app.RegionIDSpinner.Value;
               regionType = app.RegionType;
               try 
                    saplab.setBoundaryCondition(app.ParentApp.MODEL,regionType,app.RegionID,'Constraint',bc);
               catch ME
                   app.ParentApp.updateMessage(ME.message);
                   tf = true;
                   return;
               end
        end
        

        function [rt,tf]  = setForce(app)
               tf = false;
               args = app.parseForceInputScheme();
               app.RegionID = app.RegionIDSpinner.Value;
               
               if contains(app.AnalysisType,'frame')
                   regionType = 'Point';
               else
                   regionType = 'Vertex';
               end
               try 
                    saplab.setBoundaryCondition(app.ParentApp.MODEL,regionType,app.RegionID,args{:});
                    rt = regionType;
               catch ME
                   app.ParentApp.updateMessage(ME.message);
                   tf = true;
                   return;
               end               
        end

        function [rt,tf] = setDisplacement(app)
               tf = false;
               args = app.parseDisplacementInputScheme();
               app.RegionID = app.RegionIDSpinner.Value;
               if contains(app.AnalysisType,'frame')
                   regionType = 'Point';
               else
                   regionType = 'Vertex';
               end
               try 
                    saplab.setBoundaryCondition(app.ParentApp.MODEL,regionType,app.RegionID,args{:});
                    rt = regionType;
               catch ME
                   app.ParentApp.updateMessage(ME.message);
                   tf = true;
                   return;
               end               
        end

        function [rt,tf] = setPressure(app)
               tf = false;
               app.RegionID = app.RegionIDSpinner.Value;
               regionType = 'Edge';
               pn = 0; pt = 0;
               if ~isempty(app.NormalPressure.Value)
                   [~,pn] = evalc(app.NormalPressure.Value);
               end
               if ~isempty(app.TangentialPressure.Value)
                   [~,pt] = evalc(app.TangetialPressure.Value);
               end
               p  = [pt,pn];
               try 
                    saplab.setBoundaryCondition(app.ParentApp.MODEL,regionType,app.RegionID,'Pressure',p);
                    rt = regionType;
               catch ME
                   app.ParentApp.updateMessage(ME.message);
                   tf = true;
                   return;
               end               
        end


    end

    methods (Access=private)
        function bc = translateBCtoString(app)

            switch app.AnalysisType
                case 'static-planeframe'
                    h = double([app.UXCheckBox.Value, app.UYCheckBox.Value, app.ROTZCheckBox.Value]);
                    if isequal(h,[0,0,0])
                        bc='free';
                    elseif isequal(h,[1,0,0])
                        bc='roller-y';
                    elseif isequal(h,[0,1,0])
                        bc='roller-x';
                    elseif isequal(h,[1,1,0])
                        bc='pinned';
                    elseif isequal(h,[1,1,1])
                        bc='fixed';
                    else
                        app.ParentApp.Message('Invalid constrinat definition');
                    end

                case 'static-spaceframe'

                     h = double([app.UXCheckBox.Value, app.UYCheckBox.Value, app.UZCheckBox.Value, ...
                                 app.ROTXCheckBox.Value, app.ROTYCheckBox.Value, app.ROTZCheckBox.Value]);
                    if isequal(h,[0,0,0,0,0,0])
                        bc='free';
                    elseif isequal(h,[1,0,0,0,0,0])
                        bc='roller-yz';
                    elseif isequal(h,[0,1,0,0,0,0])
                        bc='roller-xz';
                    elseif isequal(h,[0,0,1,0,0,0])
                        bc='roller-xy';
                    elseif isequal(h,[1,1,0,0,0,0])
                        bc='roller-z';
                    elseif isequal(h,[1,0,1,0,0,0])
                        bc='roller-y';
                    elseif isequal(h,[0,1,1,0,0,0])
                        bc='roller-x';
                    elseif isequal(h,[1,1,1,0,0,0]) || isequal(h,[1,1,1,1,0,0])
                        bc='pinned';
                    elseif isequal(h,[1,1,1,1,1,1])
                        bc='fixed';
                    else
                        app.ParentApp.Message('Invalid constrinat definition');
                    end     

                case {'static-planestress','static-planestrain'}

                   h = double([app.UXCheckBox.Value, app.UYCheckBox.Value]);      
                   if isequal(h,[1,1])
                       bc='fixed';
                   elseif isequal(h,[0,1])
                       bc='roller-x';
                   elseif isequal(h,[1,0])
                       bc='roller-y';
                   elseif isequal(h,[0,0])
                       bc='free';
                   end
            end
        end

        function args = parseForceInputScheme(app)
            args = [];
            if ~isempty(app.Fx.Value)
                [~,f] = evalc(app.Fx.Value);
                args = {'XForce', f};
            end
            if ~isempty(app.Fy.Value)
                [~,f] = evalc(app.Fy.Value);
                args = [args, {'YForce', f}];
            end    
            if ~isempty(app.Fz.Value)
                [~,f] = evalc(app.Fz.Value);
                args = [args, {'ZForce', f}];
            end     
            if ~isempty(app.Mx.Value)
                [~,m] = evalc(app.Mx.Value);
                args = [args, {'XMoment', m}];
            end     
            if ~isempty(app.My.Value)
                [~,m] = evalc(app.My.Value);
                args = [args, {'YMoment', m}];
            end           
            if ~isempty(app.Mz.Value)
                [~,m] = evalc(app.Mz.Value);
                args = [args, {'ZMoment', m}];
            end             
        end

        function args = parseDisplacementInputScheme(app)
            args = [];
            if ~isempty(app.Ux.Value)
                [~,f] = evalc(app.Ux.Value);
                args = {'XDisplacement', f};
            end
            if ~isempty(app.Uy.Value)
                [~,f] = evalc(app.Uy.Value);
                args = [args, {'YDisplacement', f}];
            end    
            if ~isempty(app.Uz.Value)
                [~,f] = evalc(app.Uz.Value);
                args = [args, {'ZDisplacement', f}];
            end     
            if ~isempty(app.Rx.Value)
                [~,m] = evalc(app.Rx.Value);
                args = [args, {'XRotation', m}];
            end     
            if ~isempty(app.Ry.Value)
                [~,m] = evalc(app.Ry.Value);
                args = [args, {'YRotation', m}];
            end           
            if ~isempty(app.Rz.Value)
                [~,m] = evalc(app.Rz.Value);
                args = [args, {'ZRotation', m}];
            end             
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, tab)
            warning off;
            Pos = app.ApplyBoundaryConditionsUIFigure.Position;
            app.ApplyBoundaryConditionsUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);  
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.ApplyBoundaryConditionsUIFigure.Icon = appIcon;      
            SelectBoundaryRegionTypeButtonGroupSelectionChanged(app)
            if nargin == 3
               app.ParentApp = parentApp;
               app.AnalysisType = parentApp.MODEL.AnalysisType;
               if strcmpi(tab,'constraint')
                    app.TabGroup.SelectedTab = app.ConstraintTab;
               elseif strcmpi(tab, 'force')
                   app.TabGroup.SelectedTab = app.ForceTab;
               elseif strcmpi(tab, 'displ')
                   app.TabGroup.SelectedTab = app.DisplacementTab;
               elseif strcmpi(tab, 'press')
                   app.TabGroup.SelectedTab = app.PressureTab;
               end
            end
            app.TabGroupSelectionChanged();
            app.init();
            warning on;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.ConstraintTab || selectedTab == app.DisplacementTab
                app.BCTypeLabel.Text = 'Dirichlet';
            else
                app.BCTypeLabel.Text = 'Neumann';
            end
            if contains(app.AnalysisType,'frame') && selectedTab == app.PressureTab
               msg = 'Pressure boundary loads not supported in frame analysis.';
               title = 'Invalid selection';
               selection = uiconfirm(app.ApplyBoundaryConditionsUIFigure,msg,title,'Options',{'Cancel'},'CancelOption',1,'Icon','error');
               switch selection
                   case 'Cancel'
                    app.TabGroup.SelectedTab = app.ConstraintTab;
               end
            end
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.RegionIDSpinner.Value == 0
                app.ParentApp.updateMessage('Region ID is invalid.');
                return
            end
            if app.TabGroup.SelectedTab == app.ConstraintTab
               %constraint function
               bc=app.setConstraint();
               msg = ['Constraint of type '''  char(bc) ''' set successfully to REGIONTYPE ' char(app.RegionType) ' with REGIONID ' num2str(app.RegionID)];
               app.ParentApp.updateMessage(msg)
            end
            if app.TabGroup.SelectedTab == app.ForceTab
              rt = app.setForce();
              msg = ['Boundary force applied successfully to REGIONTYPE ' char(rt) ' with REGIONID ' num2str(app.RegionID)];
              app.ParentApp.updateMessage(msg);
            end
            if app.TabGroup.SelectedTab == app.DisplacementTab
              rt = app.setDisplacement();
              msg = ['Boundary displacement applied successfully to REGIONTYPE ' char(rt) ' with REGIONID ' num2str(app.RegionID)];
              app.ParentApp.updateMessage(msg);
            end
            if app.TabGroup.SelectedTab == app.PressureTab
              rt = app.setDisplacement();
              msg = ['Boundary displacement applied successfully to REGIONTYPE ' char(rt) ' with REGIONID ' num2str(app.RegionID)];
              app.ParentApp.updateMessage(msg);
            end

            saplab.ui.updateLayersAnalysis(app.ParentApp);
            app.ParentApp.plot();
        end

        % Selection changed function: SelectBoundaryRegionTypeButtonGroup
        function SelectBoundaryRegionTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectBoundaryRegionTypeButtonGroup.SelectedObject;
            if selectedButton == app.PointButton
                app.RegionType = 'Point';
            elseif selectedButton == app.VertexButton
                app.RegionType = 'Vertex';
            else
                app.RegionType = 'Edge';
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ApplyBoundaryConditionsUIFigure = uifigure('Visible', 'off');
            app.ApplyBoundaryConditionsUIFigure.Name = 'Apply Boundary Conditions';
            app.TabGroup = uitabgroup(app.ApplyBoundaryConditionsUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ConstraintTab = uitab(app.TabGroup);
            app.ConstraintTab.Title = 'Constraint';
            app.SelectBoundaryRegionTypeButtonGroup = uibuttongroup(app.ConstraintTab);
            app.SelectBoundaryRegionTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectBoundaryRegionTypeButtonGroupSelectionChanged, true);
            app.SelectBoundaryRegionTypeButtonGroup.Title = 'Select Boundary Region Type';
            app.PointButton = uiradiobutton(app.SelectBoundaryRegionTypeButtonGroup);
            app.PointButton.Text = 'Point';
            app.PointButton.Value = true;
            app.VertexButton = uiradiobutton(app.SelectBoundaryRegionTypeButtonGroup);
            app.VertexButton.Text = 'Vertex';
            app.EdgeButton = uiradiobutton(app.SelectBoundaryRegionTypeButtonGroup);
            app.EdgeButton.Text = 'Edge';
            app.SelectdofstofixPanel = uipanel(app.ConstraintTab);
            app.SelectdofstofixPanel.Title = 'Select dofs to fix';
            app.UXCheckBox = uicheckbox(app.SelectdofstofixPanel);
            app.UXCheckBox.Text = ' UX';
            app.UYCheckBox = uicheckbox(app.SelectdofstofixPanel);
            app.UYCheckBox.Text = ' UY';
            app.UZCheckBox = uicheckbox(app.SelectdofstofixPanel);
            app.UZCheckBox.Text = ' UZ';
            app.ROTXCheckBox = uicheckbox(app.SelectdofstofixPanel);
            app.ROTXCheckBox.Text = ' ROTX';
            app.ROTYCheckBox = uicheckbox(app.SelectdofstofixPanel);
            app.ROTYCheckBox.Text = ' ROTY';
            app.FixxdisplacementLabel = uilabel(app.SelectdofstofixPanel);
            app.FixxdisplacementLabel.Text = 'Fix x displacement............................';
            app.FixYdisplacementLabel = uilabel(app.SelectdofstofixPanel);
            app.FixYdisplacementLabel.Text = 'Fix Y displacement...........................';
            app.FixZdisplacementLabel = uilabel(app.SelectdofstofixPanel);
            app.FixZdisplacementLabel.Text = 'Fix Z displacement...........................';
            app.FixYRotationLabel = uilabel(app.SelectdofstofixPanel);
            app.FixYRotationLabel.Text = 'Fix Y Rotation...................................';
            app.FixXRotationLabel = uilabel(app.SelectdofstofixPanel);
            app.FixXRotationLabel.Text = 'Fix X Rotation...................................';
            app.ROTZCheckBox = uicheckbox(app.SelectdofstofixPanel);
            app.ROTZCheckBox.Text = ' ROTZ';
            app.FixZRotationLabel = uilabel(app.SelectdofstofixPanel);
            app.FixZRotationLabel.Text = 'Fix Z Rotation...................................';
            app.ForceTab = uitab(app.TabGroup);
            app.ForceTab.Title = 'Force';
            app.ApplyboundaryforcesPanel = uipanel(app.ForceTab);
            app.ApplyboundaryforcesPanel.Title = 'Apply boundary forces';
            app.Fx = uieditfield(app.ApplyboundaryforcesPanel, 'text');
            app.Fx.Tooltip = {''};
            app.Fy = uieditfield(app.ApplyboundaryforcesPanel, 'text');
            app.Fy.Tooltip = {''};
            app.ParameterLabel = uilabel(app.ApplyboundaryforcesPanel);
            app.ParameterLabel.Text = 'Parameter';
            app.ValueLabel = uilabel(app.ApplyboundaryforcesPanel);
            app.ValueLabel.Text = 'Value';
            app.ForcealongXglobalaxisLabel = uilabel(app.ApplyboundaryforcesPanel);
            app.ForcealongXglobalaxisLabel.Text = 'Force along X-global axis.................';
            app.ForcealongYglobalaxisLabel = uilabel(app.ApplyboundaryforcesPanel);
            app.ForcealongYglobalaxisLabel.Text = 'Force along Y-global axis.................';
            app.Fz = uieditfield(app.ApplyboundaryforcesPanel, 'text');
            app.Fz.Tooltip = {''};
            app.ForcealongZglobalaxisLabel = uilabel(app.ApplyboundaryforcesPanel);
            app.ForcealongZglobalaxisLabel.Text = 'Force along Z-global axis.................';
            app.Mx = uieditfield(app.ApplyboundaryforcesPanel, 'text');
            app.Mx.Tooltip = {''};
            app.MomentaroundXglobalaxisLabel = uilabel(app.ApplyboundaryforcesPanel);
            app.MomentaroundXglobalaxisLabel.Text = 'Moment around X-global axis...........';
            app.My = uieditfield(app.ApplyboundaryforcesPanel, 'text');
            app.My.Tooltip = {''};
            app.MomentaroundYglobalaxisLabel = uilabel(app.ApplyboundaryforcesPanel);
            app.MomentaroundYglobalaxisLabel.Text = 'Moment around Y-global axis............';
            app.Mz = uieditfield(app.ApplyboundaryforcesPanel, 'text');
            app.Mz.Tooltip = {''};
            app.MomentaroundZglobalaxisLabel = uilabel(app.ApplyboundaryforcesPanel);
            app.MomentaroundZglobalaxisLabel.Text = 'Moment around Z-global axis...........';
            app.DisplacementTab = uitab(app.TabGroup);
            app.DisplacementTab.Title = 'Displacement';
            app.ApplyboundarydisplacementsPanel = uipanel(app.DisplacementTab);
            app.ApplyboundarydisplacementsPanel.Title = 'Apply boundary displacements';
            app.Ux = uieditfield(app.ApplyboundarydisplacementsPanel, 'text');
            app.Ux.Tooltip = {''};
            app.Uy = uieditfield(app.ApplyboundarydisplacementsPanel, 'text');
            app.Uy.Tooltip = {''};
            app.ParameterLabel_6 = uilabel(app.ApplyboundarydisplacementsPanel);
            app.ParameterLabel_6.Text = 'Parameter';
            app.ValueLabel_6 = uilabel(app.ApplyboundarydisplacementsPanel);
            app.ValueLabel_6.Text = 'Value';
            app.DisplacementalongXglobalaxisLabel = uilabel(app.ApplyboundarydisplacementsPanel);
            app.DisplacementalongXglobalaxisLabel.Text = 'Displacement along X-global axis..........................';
            app.DisplacementalongYglobalaxisLabel = uilabel(app.ApplyboundarydisplacementsPanel);
            app.DisplacementalongYglobalaxisLabel.Text = 'Displacement along Y-global axis..........................';
            app.Uz = uieditfield(app.ApplyboundarydisplacementsPanel, 'text');
            app.Uz.Tooltip = {''};
            app.DisplacementalongZglobalaxisLabel = uilabel(app.ApplyboundarydisplacementsPanel);
            app.DisplacementalongZglobalaxisLabel.Text = 'Displacement along Z-global axis..........................';
            app.Rx = uieditfield(app.ApplyboundarydisplacementsPanel, 'text');
            app.Rx.Tooltip = {''};
            app.RotaionaroundXglobalaxisLabel_2 = uilabel(app.ApplyboundarydisplacementsPanel);
            app.RotaionaroundXglobalaxisLabel_2.Text = 'Rotaion around X-global axis.................................';
            app.Ry = uieditfield(app.ApplyboundarydisplacementsPanel, 'text');
            app.Ry.Tooltip = {''};
            app.RotaionaroundYglobalaxisLabel_2 = uilabel(app.ApplyboundarydisplacementsPanel);
            app.RotaionaroundYglobalaxisLabel_2.Text = 'Rotaion around Y-global axis..................................';
            app.Rz = uieditfield(app.ApplyboundarydisplacementsPanel, 'text');
            app.Rz.Tooltip = {''};
            app.RotaionaroundZglobalaxisLabel_2 = uilabel(app.ApplyboundarydisplacementsPanel);
            app.RotaionaroundZglobalaxisLabel_2.Text = 'Rotaion around Z-global axis..................................';
            app.PressureTab = uitab(app.TabGroup);
            app.PressureTab.Title = 'Pressure';
            app.AppypressureonboundaryPanel = uipanel(app.PressureTab);
            app.AppypressureonboundaryPanel.Title = 'Appy pressure on boundary';
            app.NormalPressure = uieditfield(app.AppypressureonboundaryPanel, 'text');
            app.NormalPressure.Tooltip = {''};
            app.ParameterLabel_5 = uilabel(app.AppypressureonboundaryPanel);
            app.ParameterLabel_5.Text = 'Parameter';
            app.ValueLabel_5 = uilabel(app.AppypressureonboundaryPanel);
            app.ValueLabel_5.Text = 'Value';
            app.NormalPressureComponentLabel = uilabel(app.AppypressureonboundaryPanel);
            app.NormalPressureComponentLabel.Text = 'Normal Pressure Component..............................................';
            app.TangentialPressure = uieditfield(app.AppypressureonboundaryPanel, 'text');
            app.TangentialPressure.Tooltip = {''};
            app.TangentialPressureComponentLabel = uilabel(app.AppypressureonboundaryPanel);
            app.TangentialPressureComponentLabel.Text = 'Tangential Pressure Component.........................................';
            app.ApplyButton = uibutton(app.ApplyBoundaryConditionsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.ApplyBoundaryConditionsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.Panel = uipanel(app.ApplyBoundaryConditionsUIFigure);
            app.BoundaryConditionTypeLabel = uilabel(app.Panel);
            app.BoundaryConditionTypeLabel.Text = 'Boundary Condition Type:';
            app.BCTypeLabel = uilabel(app.Panel);
            app.BCTypeLabel.Text = 'BCType';
            app.RegionIDSpinnerLabel = uilabel(app.Panel);
            app.RegionIDSpinnerLabel.Text = 'Region ID';
            app.RegionIDSpinner = uispinner(app.Panel);
            app.AnalysisTypeLabel = uilabel(app.Panel);
            app.AnalysisTypeLabel.Text = 'Analysis Type:';
            app.AtypeLabel = uilabel(app.Panel);
            app.AtypeLabel.Text = 'Atype';
            % Show the figure after all components are created
            app.ApplyBoundaryConditionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BoundaryCondition(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ApplyBoundaryConditionsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ApplyBoundaryConditionsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ApplyBoundaryConditionsUIFigure)
        end
    end
end