classdef Create < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateNewModelUIFigure       matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        ChooseAnalysisButton         matlab.ui.control.Button
        Tree                         matlab.ui.container.Tree
        BeamElementsAnalysisNode     matlab.ui.container.TreeNode
        PlaneframeBEAM132Node        matlab.ui.container.TreeNode
        SpaceframeBEAM162Node        matlab.ui.container.TreeNode
        PlaneElementsAnalysisNode    matlab.ui.container.TreeNode
        PlanestressTRI223PSNode      matlab.ui.container.TreeNode
        PlanestrainTRI223PNNode      matlab.ui.container.TreeNode
        CoupledElementsAnalysisNode  matlab.ui.container.TreeNode
        FRPPlanestressTRI223PSTRUSS122Node  matlab.ui.container.TreeNode
        CancelButton                 matlab.ui.control.Button
        OKButton                     matlab.ui.control.Button
        GeneralDataPanel             matlab.ui.container.Panel
        AnalysisTypeLabel            matlab.ui.control.Label
        AnalysisTypeField            matlab.ui.control.EditField
        UnitsDropDown                matlab.ui.control.DropDown
        ModelUnitsLabel              matlab.ui.control.Label
        ProjectTitleLabel            matlab.ui.control.Label
        ProjectTitleEditField        matlab.ui.control.EditField
        FileNameLabel                matlab.ui.control.Label
        FileNameEditField            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp;
        isCombined = false;
        AnalysisType = 'static-planeframe';
    end
    
    methods (Access = public)
        function selectAnalysisAttributes(app)
           if app.Tree.SelectedNodes == app.PlaneframeBEAM132Node
               app.AnalysisType = 'static-planeframe';
           elseif app.Tree.SelectedNodes == app.SpaceframeBEAM162Node
               app.AnalysisType = 'static-spaceframe';
           elseif app.Tree.SelectedNodes == app.PlanestressTRI223PSNode
               app.AnalysisType = 'static-planestress';        
           elseif app.Tree.SelectedNodes == app.PlanestrainTRI223PNNode
               app.AnalysisType = 'static-planestrain';      
           elseif app.Tree.SelectedNodes == app.FRPPlanestressTRI223PSTRUSS122Node
               app.AnalysisType = 'coupled-static-planestress';
               app.isCombined = true;
           end
           app.AnalysisTypeField.Value = app.AnalysisType;
        end

        function name = get_Name(app)
            name = app.FileNameEditField.Value;
        end

        function ptitle = get_ProjectTitle(app)
            ptitle = app.ProjectTitleEditField.Value;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off;
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.CreateNewModelUIFigure.Icon = appIcon;
            Pos = app.CreateNewModelUIFigure.Position;
            app.CreateNewModelUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);   
            mlapptools.setStyle(app.Tree,'border','2px solid rgb(255,255,255)');
            mlapptools.setStyle(app.Tree,'border-top','2px solid rgb(150,150,150)');
            mlapptools.setStyle(app.Tree,'border-left','2px solid rgb(150,150,150)');
            if nargin == 2
            app.ParentApp = parentApp;
            end
            warning on;
            expand(app.Tree,'all')
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.selectAnalysisAttributes();
            model = saplab.create("Attributes",app.AnalysisType);
            name  = app.get_Name();
            ptitle = app.get_ProjectTitle();

            app.ParentApp.createSAP(model,name,ptitle,app.isCombined,app.UnitsDropDown.Value)
            app.ParentApp.updateMessage('New model created successfully!');
            
            delete(app);
        end

        % Close request function: CreateNewModelUIFigure
        function CreateNewModelUIFigureCloseRequest(app, event)
            app.delete();
        end

        % Button pushed function: ChooseAnalysisButton
        function ChooseAnalysisButtonPushed(app, event)
            if app.CreateNewModelUIFigure.Position ~= 580
            app.CreateNewModelUIFigure.Position(3) = 580;
            Pos = app.CreateNewModelUIFigure.Position;
            app.CreateNewModelUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);
            else
            app.CreateNewModelUIFigure.Position(3) = 305;
            Pos = app.CreateNewModelUIFigure.Position;
            app.CreateNewModelUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);  

            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.selectAnalysisAttributes(); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateNewModelUIFigure = uifigure('Visible', 'off');
            app.CreateNewModelUIFigure.Name = 'Create New Model';
            app.CreateNewModelUIFigure.Icon = 'Application Icon.png';
            app.CreateNewModelUIFigure.CloseRequestFcn = createCallbackFcn(app, @CreateNewModelUIFigureCloseRequest, true);
            app.GeneralDataPanel = uipanel(app.CreateNewModelUIFigure);
            app.GeneralDataPanel.Title = 'General Data';
            app.FileNameEditField = uieditfield(app.GeneralDataPanel, 'text');
            app.FileNameEditField.Value = 'Untitled';
            app.FileNameLabel = uilabel(app.GeneralDataPanel);
            app.FileNameLabel.Text = 'File Name:';
            app.ProjectTitleEditField = uieditfield(app.GeneralDataPanel, 'text');
            app.ProjectTitleEditField.Value = 'My Project';
            app.ProjectTitleLabel = uilabel(app.GeneralDataPanel);
            app.ProjectTitleLabel.Text = 'Project Title:';
            app.ModelUnitsLabel = uilabel(app.GeneralDataPanel);
            app.ModelUnitsLabel.Text = 'Model Units:';
            app.UnitsDropDown = uidropdown(app.GeneralDataPanel);
            app.UnitsDropDown.Items = {'kN/m/°C', 'N/m/°C', 'kN/mm/°C', 'N/mm/°C'};
            app.UnitsDropDown.Value = 'kN/m/°C';
            app.AnalysisTypeField = uieditfield(app.GeneralDataPanel, 'text');
            app.AnalysisTypeField.Editable = 'off';
            app.AnalysisTypeField.Value = 'static-planeframe';
            app.AnalysisTypeLabel = uilabel(app.GeneralDataPanel);
            app.AnalysisTypeLabel.Text = 'Analysis Type:';
            app.OKButton = uibutton(app.CreateNewModelUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.CreateNewModelUIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.Tree = uitree(app.CreateNewModelUIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.BeamElementsAnalysisNode = uitreenode(app.Tree);
            app.BeamElementsAnalysisNode.Text = 'Beam Elements Analysis';
            app.PlaneframeBEAM132Node = uitreenode(app.BeamElementsAnalysisNode);
            app.PlaneframeBEAM132Node.Icon = 'forceAndMoments.png';
            app.PlaneframeBEAM132Node.Text = 'Plane-frame (BEAM132)';
            app.SpaceframeBEAM162Node = uitreenode(app.BeamElementsAnalysisNode);
            app.SpaceframeBEAM162Node.Icon = 'forceAndMoments.png';
            app.SpaceframeBEAM162Node.Text = 'Space-frame (BEAM162)';
            app.PlaneElementsAnalysisNode = uitreenode(app.Tree);
            app.PlaneElementsAnalysisNode.Text = 'Plane Elements Analysis';
            app.PlanestressTRI223PSNode = uitreenode(app.PlaneElementsAnalysisNode);
            app.PlanestressTRI223PSNode.Icon = 'plottype-contourf_60.png';
            app.PlanestressTRI223PSNode.Text = 'Plane-stress (TRI223PS)';
            app.PlanestrainTRI223PNNode = uitreenode(app.PlaneElementsAnalysisNode);
            app.PlanestrainTRI223PNNode.Icon = 'plottype-contourf_60.png';
            app.PlanestrainTRI223PNNode.Text = 'Plane-strain (TRI223PN)';
            app.CoupledElementsAnalysisNode = uitreenode(app.Tree);
            app.CoupledElementsAnalysisNode.Text = 'Coupled Elements Analysis';
            app.FRPPlanestressTRI223PSTRUSS122Node = uitreenode(app.CoupledElementsAnalysisNode);
            app.FRPPlanestressTRI223PSTRUSS122Node.Icon = 'plottype-contourf_60.png';
            app.FRPPlanestressTRI223PSTRUSS122Node.Text = 'FRP Plane-stress (TRI223PS+TRUSS122)';
            app.Panel = uipanel(app.CreateNewModelUIFigure);
            app.ChooseAnalysisButton = uibutton(app.Panel, 'push');
            app.ChooseAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseAnalysisButtonPushed, true);
            app.ChooseAnalysisButton.Text = 'Choose Analysis >>';
            % Show the figure after all components are created
            app.CreateNewModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Create(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CreateNewModelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CreateNewModelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateNewModelUIFigure)
        end
    end
end