classdef Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        GeometryMenu                    matlab.ui.container.Menu
        AddPointMenu                    matlab.ui.container.Menu
        AddLineMenu                     matlab.ui.container.Menu
        ConnectMenu                     matlab.ui.container.Menu
        TrimLineMenu                    matlab.ui.container.Menu
        TrimLineAtLocationMenu          matlab.ui.container.Menu
        AddCircleMenu                   matlab.ui.container.Menu
        AddRectangleMenu                matlab.ui.container.Menu
        AddEllipseMenu                  matlab.ui.container.Menu
        AddPolygonMenu                  matlab.ui.container.Menu
        TrimEdgeMenu                    matlab.ui.container.Menu
        BooleanOperationMenu            matlab.ui.container.Menu
        ImportDefaultGeometryMenu       matlab.ui.container.Menu
        StructuralMenu                  matlab.ui.container.Menu
        MaterialPropertiesMenu          matlab.ui.container.Menu
        SectionPropertiesMenu           matlab.ui.container.Menu
        ReinforcementMenu_2             matlab.ui.container.Menu
        InterfaceMenu                   matlab.ui.container.Menu
        LoadingMenu                     matlab.ui.container.Menu
        BodyMenu                        matlab.ui.container.Menu
        DistributedMenu                 matlab.ui.container.Menu
        BoundariesMenu                  matlab.ui.container.Menu
        ConstraintMenu                  matlab.ui.container.Menu
        ForceMenu                       matlab.ui.container.Menu
        DisplacementMenu                matlab.ui.container.Menu
        PressureMenu                    matlab.ui.container.Menu
        ReleaseMenu                     matlab.ui.container.Menu
        MeshMenu_2                      matlab.ui.container.Menu
        MeshoptionsMenu                 matlab.ui.container.Menu
        DisplayMeshMenu                 matlab.ui.container.Menu
        CanvasMenu                      matlab.ui.container.Menu
        InteractivityMenu               matlab.ui.container.Menu
        LabelsMenu                      matlab.ui.container.Menu
        PointsMenu                      matlab.ui.container.Menu
        LinesMenu                       matlab.ui.container.Menu
        VerticesMenu                    matlab.ui.container.Menu
        EdgesMenu                       matlab.ui.container.Menu
        FacesMenu                       matlab.ui.container.Menu
        ReinforcementMenu               matlab.ui.container.Menu
        SolveMenu                       matlab.ui.container.Menu
        ElasticLinearSolverMenu         matlab.ui.container.Menu
        NonLinearSolverMenu             matlab.ui.container.Menu
        ResultsMenu                     matlab.ui.container.Menu
        DeformedShapeMenu_2             matlab.ui.container.Menu
        StressMenu                      matlab.ui.container.Menu
        BeamElementsMenu                matlab.ui.container.Menu
        AxialForcesNxMenu               matlab.ui.container.Menu
        ShearForcesFyMenu               matlab.ui.container.Menu
        ShearForcesFzMenu               matlab.ui.container.Menu
        TorqueMomentMxMenu              matlab.ui.container.Menu
        BendingMomentMyMenu             matlab.ui.container.Menu
        BendingMomentMzMenu             matlab.ui.container.Menu
        PlaneElementsMenu               matlab.ui.container.Menu
        StressSXXMenu                   matlab.ui.container.Menu
        StressSYYMenu                   matlab.ui.container.Menu
        StressSXYMenu                   matlab.ui.container.Menu
        VonMisesStressS11Menu           matlab.ui.container.Menu
        VonMisesStressS22Menu           matlab.ui.container.Menu
        VonMisesStressS12Menu           matlab.ui.container.Menu
        StrainMenu                      matlab.ui.container.Menu
        PlaneElementsMenu_2             matlab.ui.container.Menu
        StrainEXXMenu                   matlab.ui.container.Menu
        StrainEYYMenu                   matlab.ui.container.Menu
        StrainEXYMenu                   matlab.ui.container.Menu
        ScaleFactorMenu                 matlab.ui.container.Menu
        DisplacementsMenu               matlab.ui.container.Menu
        FieldMenu                       matlab.ui.container.Menu
        DisplacementUXMenu              matlab.ui.container.Menu
        DisplacementUYMenu              matlab.ui.container.Menu
        DisplacementUZMenu              matlab.ui.container.Menu
        FEResultsMenu                   matlab.ui.container.Menu
        StiffnessMatrixMenu             matlab.ui.container.Menu
        ForcesVectorMenu                matlab.ui.container.Menu
        EnforcedDisplacementVectorMenu  matlab.ui.container.Menu
        BoundaryConditionsMatrixMenu    matlab.ui.container.Menu
        BoundaryConditionsVectorMenu    matlab.ui.container.Menu
        FESolutionMenu                  matlab.ui.container.Menu
        MainGridLayout                  matlab.ui.container.GridLayout
        Tree                            matlab.ui.container.Tree
        MessagePanel                    matlab.ui.container.Panel
        MessageGridLayout               matlab.ui.container.GridLayout
        Message                         matlab.ui.container.Tree
        Node                            matlab.ui.container.TreeNode
        Node_2                          matlab.ui.container.TreeNode
        ProgramVersion100Node           matlab.ui.container.TreeNode
        DateNode                        matlab.ui.container.TreeNode
        AuthorFIDUCIAGabrieleIDs265128Node  matlab.ui.container.TreeNode
        Node_3                          matlab.ui.container.TreeNode
        MessagesNode                    matlab.ui.container.TreeNode
        NONELabel                       matlab.ui.control.Label
        SelectedObjectLabel             matlab.ui.control.Label
        ProgramMessageLabel             matlab.ui.control.Label
        CurrGeomDropDown                matlab.ui.control.DropDown
        ClearMessagesButton             matlab.ui.control.Button
        CanvasPanel                     matlab.ui.container.Panel
        CanvasGrid                      matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        BottomBar                       matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        UnitsTextLabel                  matlab.ui.control.Label
        UnitsLabel                      matlab.ui.control.Label
        AmplitudeValue                  matlab.ui.control.Label
        AmplitudeLabel                  matlab.ui.control.Label
        Ypos                            matlab.ui.control.Label
        NALabel                         matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        Xpos                            matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        Z_ShortCut                      matlab.ui.control.Button
        Y_ShortCut                      matlab.ui.control.Button
        X_ShortCut                      matlab.ui.control.Button
        Rotate_ShortCut                 matlab.ui.control.Button
        ZoomOut_ShortCut                matlab.ui.control.Button
        ZoomIn_ShortCut                 matlab.ui.control.Button
        AnalysisTypeLabel               matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        DeleteMenu                      matlab.ui.container.Menu
        ViewPropertiesMenu              matlab.ui.container.Menu
    end

    
    properties (Access = public)
        MODEL; % A property which stores the structural model. 
        PREFERENCES; % A property which stores the model preferences
        CHILDRENAPP; % A property which stores children apps.
        GRAPHICS;
        ACTIONID;       % A property which stores the number of actions
        NUMACTIONS = 2; % A property which stores the number of actions
        MATERIALOBJ;
        ISCOMBINED = false;
    end
    
    %General methods
    methods (Access=public)

        function createSAP(app,Model,ModelName,pTitle,isCombined,unitsValue)

            %Create a new SAP Model
            app.PREFERENCES.NAME = ModelName;
            app.PREFERENCES.PTITLE = pTitle;
            app.MODEL = Model;
           
            saplab.ui.GraphicOptions(app);
            app.UIFigure.GraphicsSmoothing='off';
            %set canvas visible
            app.CanvasPanel.Visible = 'on';
            app.Tree.Enable = 'on';
            
            app.ISCOMBINED = isCombined;
            switch app.MODEL.AnalysisType
                case 'static-planeframe'
                app.AnalysisTypeLabel.Text = 'Static-Planeframe - BEAM132';
                case 'static-spaceframe'
                app.AnalysisTypeLabel.Text = 'Static-Spaceframe - BEAM162';   
                case {'static-planestress'}
                if ~ app.ISCOMBINED
                app.AnalysisTypeLabel.Text = 'Static-Planestress - TRI223';   
                else
                app.AnalysisTypeLabel.Text = '(Coupled) Static-Planestress - TRI223 + TRUSS122';  
                end
                case {'static-planestrain'}
                app.AnalysisTypeLabel.Text = 'Static-Planestrain - TRI223';   

            end
            app.PREFERENCES.ANALYSIS = app.AnalysisTypeLabel.Text;
            app.UnitsTextLabel.Text = unitsValue;
            
            %set canvas default properties
            saplab.ui.updateLayersAnalysis(app);

            title(app.UIAxes,pTitle);
            app.UIFigure.Name = [app.UIFigure.Name, ' - ', app.PREFERENCES.NAME];

            uialert(app.UIFigure,'Model created successfully!','Message','Icon','success');
        end

        function openSAP(app,m)
            
            %set the model
            app.MODEL = m;
  
            %set canvas visible
            app.CanvasPanel.Visible = 'on';

            saplab.ui.GraphicOptions(app);
            app.UIFigure.GraphicsSmoothing='off';

            %plot the geometry
            if contains(app.MODEL.AnalysisType,'frame')

                app.plot();
            else
                if isempty(app.MODEL.Geometry)
                    %do nothing
                else
                    if isempty(app.MODEL.Geometry.geom)
                        app.updadateCurrGeomDropDownValue();
                        app.CurrGeomDropDown();
                    else
                        app.ParentApp.CurrGeomDropDown.Items = {app.MODEL.Geometry.geomTag};
                        app.ParentApp.CurrGeomDropDown.Value = app.MODEL.Geometry.geomTag;
                        app.plot2('Imported');
                    end
                end
            end

            %update the tree
            saplab.ui.updateLayersAnalysis(app);
        end

        function updateMessage(app, msg, setStyle)
            if nargin == 2
            uitreenode(app.MessagesNode,'Text', msg);
            scroll(app.Message,"bottom");
            else
            n = uitreenode(app.MessagesNode,'Text', msg);
            addStyle(app.Message,setStyle,"node",n);
            scroll(app.Message,"bottom");            
            end
            expand(app.MessagesNode)
        end


        function initCallbacks(app,avoid)

            if isempty(app.MODEL)
                style = uistyle("FontColor",'r');
                app.updateMessage('No structural model found.',style); return        
            end
            if avoid == 2
                if contains(app.MODEL.AnalysisType,{'stress','strain'})
                    style = uistyle("FontColor",'r');
                    app.updateMessage('Functionality not supported for Plane-stress/Plane-strain analysis',style); return             
                end
            elseif avoid == 1
                if contains(app.MODEL.AnalysisType,{'frame'})
                    style = uistyle("FontColor",'r');
                    app.updateMessage('Functionality not supported Frame analysis',style); return             
                end
            end
            if ~isempty(app.MODEL.Geometry)
                if isprop(app.MODEL.Geometry, 'NumFaces')
                    if isempty(app.MODEL.Geometry.NumFaces) || app.MODEL.Geometry.NumFaces == 0
                        style = uistyle("FontColor",'r');
                        app.updateMessage('Model does not have geometry.',style);
                        return;
                    end
                else
                    if isempty(app.MODEL.Geometry.NumLines) || app.MODEL.Geometry.NumLines == 0
                        style = uistyle("FontColor",'r');
                        app.updateMessage('Model does not have geometry.',style);
                        return
                    end
                end
            end
   

        end

    end

    methods (Static)
        function Data = getData(model,axis)
            Data.Model = model;
            Data.Canvas = axis;
        end        
    end

    methods
        function plot(app, varargin)

            if isempty(varargin)
               app.GraphicHandles = saplab.gplot(app.MODEL,'Parent',app.UIAxes);
            else
               if ~strcmpi(app.CurrGeomDropDown.Value,'All')
               app.GraphicHandles = saplab.gplot(app.MODEL,'Parent',app.UIAxes, 'Tag', varargin{1});
               else
               saplab.gplot(app.MODEL,'Parent',app.UIAxes, 'Tag', varargin{1}); return;
               end
            end
            app.GraphicHandles.PointLabels(app.displayPointLabels);
            app.GraphicHandles.LineLabels(app.displayLineLabels);
            app.GraphicHandles.VertexLabels(app.displayVertexLabels);
            app.GraphicHandles.EdgeLabels(app.displayEdgeLabels);
            app.GraphicHandles.FaceLabels(app.displayFaceLabels);
            if contains(app.MODEL.AnalysisType,'plane')
                view(app.UIAxes,2);
            end
        end
    end

    properties
        GraphicHandles;
    end


    %Geometry 1D methods
    methods 

        function set.displayPointLabels(app, TF)
            validatestring(TF,["on","off"]);
            app.displayPointLabels = TF;
            app.plot();
        end

        function set.displayLineLabels(app, TF)
            validatestring(TF,["on","off"]);
            app.displayLineLabels = TF;
            app.plot();
        end

        function set.displayEdgeLabels(app, TF)
            validatestring(TF,["on","off"]);
            app.displayEdgeLabels = TF;
            CurrGeomDropDownValueChanged(app);
        end

        function set.displayVertexLabels(app, TF)
            validatestring(TF,["on","off"]);
            app.displayVertexLabels = TF;
            CurrGeomDropDownValueChanged(app);
        end

        function set.displayFaceLabels(app, TF)
            validatestring(TF,["on","off"]);
            app.displayFaceLabels = TF;
            CurrGeomDropDownValueChanged(app);
        end

        function set.displayFRPLabels(app, TF)
            validatestring(TF,["on","off"]);
            app.displayFRPLabels = TF;
            CurrGeomDropDownValueChanged(app);
        end

    end

    methods 
        function plot2(app,gm,pltAllOpt)
            app.currentGeometryDisplay = gm;
            if nargin == 2
                if strcmp(app.currentGeometryDisplay,'Imported')
                saplab.ui.plot(app.MODEL,'Canvas', app.UIAxes, 'EdgeLabels', app.displayEdgeLabels,'VertexLabels',app.displayVertexLabels,'FaceLabels',app.displayFaceLabels,'ReinforcementLabels',app.displayFRPLabels,'PlotBoundaryForces',app.plotBoundaryForces,'PlotThermalLoads',app.plotThermalLoads, ...
                    'Stress',app.plotStress,'PlotDeformedShape', app.plotDeformedShape,'ScaleFactor', app.scaleFactorValue, 'Strain', app.plotStrain,'PlotDisplacements',app.plotDisplacements,'Field',app.displacementField);
                else
                saplab.ui.plot(gm,'Canvas', app.UIAxes, 'EdgeLabels', app.displayEdgeLabels,'VertexLabels',app.displayVertexLabels,'FaceLabels',app.displayFaceLabels,'ReinforcementLabels',app.displayFRPLabels);    
                return
                end
            end
            if nargin == 3
                saplab.ui.plot(gm,'Canvas', app.UIAxes, 'EdgeLabels', app.displayEdgeLabels,'VertexLabels',app.displayVertexLabels ,'FaceLabels',app.displayFaceLabels,'ReinforcementLabels',app.displayFRPLabels,'PlotAll',pltAllOpt);               
            end 
        end

        function updadateCurrGeomDropDownValue(app,gm)
            if isempty(app.MODEL), return; end
            if contains(app.MODEL.AnalysisType, 'frame'), return; end
            if isempty(app.MODEL.Geometry), return ; end
            app.CurrGeomDropDown.Items = [{'ALL'};app.MODEL.Geometry.csgdb.tag];
            app.CurrGeomDropDown.Value = gm;
            app.plot(gm);
%             CurrGeomDropDownValueChanged(app);
       
        end
    end

    properties 
        displayPointLabels = 'off';
        displayLineLabels  = 'off';
        displayEdgeLabels  = 'off';
        displayVertexLabels = 'off';
        displayFaceLabels = 'off';
        displayFRPLabels  = 'off';
        plotDeformedShape = 'off';
        plotDisplacements='off';
        plotDistributedLoads = 'off';
        displacementField = 'UX';
        forceandmomentFields = '';
        currentGeometryDisplay;
        canvasInteractivity = 'on';
        plotBoundaryForces = 'on';
        showMesh = 'off';
        scaleFactorValue = [];
        plotThermalLoads = 'off';
        plotStress = '';
        plotStrain = '';
    end

    methods
        function setMatObj(app,matobj)
            app.MATERIALOBJ = matobj;
        end
    end

    properties
        ANIMATIONSTATE = false;
        ISSAVED = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, model)
            warning off
            addpath("icons\");
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.UIFigure.Icon = appIcon;
            %define application start up function. This function is
            %executed each time the application is opening.
            %customize borders of lateral panel
            saplab.ui.custom.customToolBarButton(app.ZoomIn_ShortCut)
            saplab.ui.custom.customToolBarButton(app.ZoomOut_ShortCut);
            saplab.ui.custom.customToolBarButton(app.Rotate_ShortCut);
            saplab.ui.custom.customToolBarButton(app.X_ShortCut);
            saplab.ui.custom.customToolBarButton(app.Y_ShortCut);
            saplab.ui.custom.customToolBarButton(app.Z_ShortCut);

            %customize borders of message area
            mlapptools.setStyle(app.MessageGridLayout,'border-top','1px solid rgb(100,100,100)');
            mlapptools.setStyle(app.Message,'border','none');
            mlapptools.setStyle(app.Message,'box-shadow','none');
            %customize down bar components 
            mlapptools.setStyle(app.Tree,'border','1px solid rgb(100,100,100)');

            %cutomize clear button
            saplab.ui.custom.customButton(app.ClearMessagesButton);

            %custom Current Geometry DropDown Value
            mlapptools.setStyle(app.CurrGeomDropDown,'border-radius','0px');
            
            %set date
            app.DateNode.Text = strcat(app.DateNode.Text, string( datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z')));

            if nargin == 1
                uialert(app.UIFigure,'Welcome in SAPLAB Application','Welcome Message','Icon','info')
            end

            if nargin > 1
                if isa(model,'sap.FEModel')
                   %open new project 
                    openSAP(app,model);
                   return
                end

                if isstring(model) || ischar(model)
                    tf = saplab.filechk(model);
                    if ~tf
                        error('The specified file does not contain a SAPLAB model or is invalid.');
                    end
                    model = saplab.open(model);
                    openSAP(app,model);
                end
            end
            warning off
        end

        % Callback function
        function GeometryButtonPushed(app, event)
           if contains(app.MODEL.AnalysisType,'frame')
               app.CHILDRENAPP = saplab.ui.Geometry1D(app);
           else
               app.CHILDRENAPP = saplab.ui.Geometry2D(app);
           end
        end

        % Menu selected function: PointsMenu
        function PointsMenuSelected(app, event)
            switch app.displayPointLabels
                case 'on'
                    app.displayPointLabels='off';
                case 'off'
                    app.displayPointLabels='on';
            end
        end

        % Callback function
        function UndoButtonPushed(app, event)
            app.ACTIONID = app.ACTIONID-1;
            delete(app.UIAxes.Children);
            if app.ACTIONID == 2
                app.Undo.Enable = 'off';
                return;
            end
            ID = app.ACTIONID;
            getID = app.Message.Children(ID);
            Data = getID.NodeData;
            app.MODEL  = Data.Model;
            app.MODEL.Geometry
            app.plot();
        end

        % Menu selected function: LinesMenu
        function LinesMenuSelected(app, event)
            switch app.displayLineLabels
                case 'on'
                    app.displayLineLabels='off';
                case 'off'
                    app.displayLineLabels='on';
            end            
        end

        % Menu selected function: EdgesMenu
        function EdgesMenuSelected(app, event)
            switch app.displayEdgeLabels
                case 'on'
                    app.displayEdgeLabels='off';
                case 'off'
                    app.displayEdgeLabels='on';
            end              
        end

        % Value changed function: CurrGeomDropDown
        function CurrGeomDropDownValueChanged(app, event)
            Value = app.CurrGeomDropDown.Value;
            app.plot(Value)
            hold(app.UIAxes,'off')
        end

        % Menu selected function: VerticesMenu
        function VerticesMenuSelected(app, event)
            switch app.displayVertexLabels
                case 'on'
                    app.displayVertexLabels='off';
                case 'off'
                    app.displayVertexLabels='on';
            end              
        end

        % Callback function
        function MaterialButtonPushed(app, event)
            if isempty(app.MODEL)
                style = uistyle("FontColor",'r');
                app.updateMessage('No structural model found.',style);
                return
            end
            if isempty(app.MODEL.Geometry)
                style = uistyle("FontColor",'r');
                app.updateMessage('Model does not have geometry.',style);
                return
            end
            if ~isempty(app.MODEL.Geometry)
                if isprop(app.MODEL.Geometry, 'NumFaces')
                    if isempty(app.MODEL.Geometry.NumFaces) || app.MODEL.Geometry.NumFaces == 0
                        style = uistyle("FontColor",'r');
                        app.updateMessage('Unable to set/add material properties because no primitive/composite object has been set as default geometry. Import a geometry to the model.',style);
                        return;
                    end
                else
                    if isempty(app.MODEL.Geometry.NumLines) || app.MODEL.Geometry.NumLines == 0
                        style = uistyle("FontColor",'r');
                        app.updateMessage('Unable to set/add material properties because model does not have geometry.',style);
                        return
                    end
                end
            end
            app.CHILDRENAPP = saplab.ui.MaterialProperties(app);
        end

        % Menu selected function: FacesMenu
        function FacesMenuSelected(app, event)
            switch app.displayFaceLabels
                case 'on'
                    app.displayFaceLabels='off';
                case 'off'
                    app.displayFaceLabels='on';
            end             
        end

        % Menu selected function: NewMenu
        function NewClicked(app, event)
            if isempty(app.MODEL)
            updateMessage(app, 'Initialize new model...')
            app.CHILDRENAPP = saplab.ui.Create(app);    
            else
                if ~app.ISSAVED
                    selection = uiconfirm(app.UIFigure,'Want to save current project?','Save project','Icon','warning'); 
                    switch selection
                        case 'OK'
                        app.SaveMenuSelected();
                        app.MODEL = [];
                        delete(app.Tree.Children);
                        delete(app.UIAxes.Children);
                        app.CHILDRENAPP = saplab.ui.Create(app);  
                        otherwise
                        app.MODEL = [];
                        delete(app.Tree.Children);
                        delete(app.UIAxes.Children);       
                        app.CHILDRENAPP = saplab.ui.Create(app);  
                    end
                else
                app.MODEL = [];
                delete(app.Tree.Children);
                delete(app.UIAxes.Children);       
                app.CHILDRENAPP = saplab.ui.Create(app);  
                end
            end
        end

        % Menu selected function: ReinforcementMenu
        function ReinforcementMenuSelected(app, event)
            switch app.displayFRPLabels
                case 'on'
                    app.displayFRPLabels='off';
                case 'off'
                    app.displayFRPLabels='on';
            end            
        end

        % Callback function
        function InterfaceButtonPushed(app, event)
            if isempty(app.MODEL)
                style = uistyle("FontColor",'r');
                app.updateMessage('No structural model found.',style); return        
            end
            if contains(app.MODEL.AnalysisType,'frame')
                style = uistyle("FontColor",'r');
                app.updateMessage('Frame analysis does not support Interface',style); return             
            end
            if ~isempty(app.MODEL.Geometry)
                if isprop(app.MODEL.Geometry, 'NumFaces')
                    if isempty(app.MODEL.Geometry.NumFaces) || app.MODEL.Geometry.NumFaces == 0
                        style = uistyle("FontColor",'r');
                        app.updateMessage('Unable to set/add material properties because no primitive/composite object has been set as default geometry. Import a geometry to the model.',style);
                        return;
                    end
                else
                    if isempty(app.MODEL.Geometry.NumLines) || app.MODEL.Geometry.NumLines == 0
                        style = uistyle("FontColor",'r');
                        app.updateMessage('Unable to set/add material properties because model does not have geometry.',style);
                        return
                    end
                end
            end
            
            app.CHILDRENAPP = saplab.ui.Interface(app);
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if isempty(app.MODEL) || strcmpi(app.canvasInteractivity,'off')
                app.NONELabel.Text = 'NONE';
                return
            else
            [p2d_mouse,selectedItem] = saplab.ui.canvasRayCasting(app.MODEL,app.UIAxes,app.MODEL.Graphics.LinkedCanvasPoints,app.MODEL.Graphics.CanvasChildrenTags);
            app.Xpos.Text = num2str(p2d_mouse(1));
            app.Ypos.Text = num2str(p2d_mouse(2));
            app.NONELabel.Text = selectedItem;
            end
        end

        % Callback function
        function MeshMenuSelected(app, event)
            if ~contains(app.MODEL.AnalysisType,'frame')
                if strcmpi(app.showMesh,'off')
                if isempty(app.MODEL.Mesh)
                    return;
                end
                app.showMesh='on';
                t = app.MODEL.Mesh.Elements;
                disp(t)
                %extract triangles
                triFind = find(t(3,:)==0);
                if ~isempty(triFind)
                t = t(~triFind);
                end
                
                maxp = max(max([t(1,:), max(t(2,:)) max(t(3,:))]));
                p = app.MODEL.Mesh.Nodes;
                p = p(:,1:maxp);

                fig = gcf;
                fig.Visible = 'off';
                h = pdemesh(p,t,"EdgeColor",'k');
                copyobj(h,app.UIAxes);
                else
                app.showMesh='off';
                app.plot2(app.currentGeometryDisplay)
                end
            else

            end
        end

        % Callback function
        function AnalyzeClicked(app, event)
            app.CHILDRENAPP = saplab.ui.Solve(app);
        end

        % Menu selected function: AddPointMenu
        function AddPointMenuSelected(app, event)
            if isempty(app.MODEL)
                return;
            end
            if contains(app.MODEL.AnalysisType,'frame')
                app.CHILDRENAPP = saplab.ui.AddPoint(app);
            else
                app.updateMessage('"Add Point" functionality can only be used in 1D elements analysis');
            end
        end

        % Menu selected function: AddLineMenu
        function AddLineMenuSelected(app, event)
            
            if contains(app.MODEL.AnalysisType,'frame')
                gm = app.MODEL.Geometry;
                if isempty(gm) || gm.NumPoints == 1
                app.updateMessage('The model''s geometry is empty or insufficient number of points.', uistyle('FontColor',[1 0 0]));
                end
                app.CHILDRENAPP = saplab.ui.AddLine(app);
            else
                app.updateMessage('"Add Line" functionality can only be used in 1D elements analysis', uistyle('FontColor',[1 0 0]));
            end            
        end

        % Menu selected function: ConnectMenu
        function ConnectMenuSelected(app, event)
            initCallbacks(app,2);
            if contains(app.MODEL.AnalysisType,'frame')
                app.CHILDRENAPP = saplab.ui.Connect(app);
            else
                app.updateMessage('"Connect" functionality can only be used in 1D elements analysis', uistyle('FontColor',[1 .5 .25]));
            end            
        end

        % Menu selected function: TrimLineMenu
        function TrimLineMenuSelected(app, event)
            initCallbacks(app,2);
            if contains(app.MODEL.AnalysisType,'frame')
                app.CHILDRENAPP = saplab.ui.TrimLine(app);
            else
                app.updateMessage('"Trim Line" functionality can only be used in 1D elements analysis', uistyle('FontColor',[1 .5 .25]));
            end            
        end

        % Menu selected function: TrimLineAtLocationMenu
        function TrimLineAtLocationMenuSelected(app, event)
            initCallbacks(app,2);
            if contains(app.MODEL.AnalysisType,'frame')
                app.CHILDRENAPP = saplab.ui.TrimLineAtLocation(app);
            else
                app.updateMessage('"Trim Line At Location" functionality can only be used in 1D elements analysis', uistyle('FontColor',[1 .5 .25]));
            end            
        end

        % Menu selected function: AddCircleMenu
        function AddCircleMenuSelected(app, event)
            if ~contains(app.MODEL.AnalysisType,'frame')
                app.CHILDRENAPP = saplab.ui.AddCircle(app);
            else
                app.updateMessage('"Add Circle" functionality can only be used in 2D elements analysis', uistyle('FontColor',[1 .5 .25]));
            end            
        end

        % Menu selected function: AddRectangleMenu
        function AddRectangleMenuSelected(app, event)
            if ~contains(app.MODEL.AnalysisType,'frame')
                app.CHILDRENAPP = saplab.ui.AddRectangle(app);
            else
                app.updateMessage('"Add Rectangle" functionality can only be used in 2D elements analysis', uistyle('FontColor',[1 .5 .25]));
            end             
        end

        % Menu selected function: AddEllipseMenu
        function AddEllipseMenuSelected(app, event)
            if ~contains(app.MODEL.AnalysisType,'frame')
                app.CHILDRENAPP = saplab.ui.AddEllipse(app);
            else
                app.updateMessage('"Add Ellipse" functionality can only be used in 2D elements analysis', uistyle('FontColor',[1 .5 .25]));
            end             
        end

        % Menu selected function: AddPolygonMenu
        function AddPolygonMenuSelected(app, event)
            if ~contains(app.MODEL.AnalysisType,'frame')
                app.CHILDRENAPP = saplab.ui.AddPolygon(app);
            else
                app.updateMessage('"Add Polygon" functionality can only be used in 2D elements analysis', uistyle('FontColor',[1 .5 .25]));
            end             
        end

        % Menu selected function: TrimEdgeMenu
        function TrimEdgeMenuSelected(app, event)

            if ~contains(app.MODEL.AnalysisType,'frame')
                app.CHILDRENAPP = saplab.ui.TrimEdge(app);
            else
                app.updateMessage('"Trim Edge" functionality can only be used in 2D elements analysis', uistyle('FontColor',[1 .5 .25]));
            end             
        end

        % Menu selected function: BooleanOperationMenu
        function BooleanOperationMenuSelected(app, event)

            if ~contains(app.MODEL.AnalysisType,'frame')
                app.CHILDRENAPP = saplab.ui.BoolOper(app);
            else
                app.updateMessage('"Boolean Operation" functionality can only be used with Constructive Solid Geometries', uistyle('FontColor',[1 .5 .25]));
            end             
        end

        % Menu selected function: ImportDefaultGeometryMenu
        function ImportDefaultGeometryMenuSelected(app, event)
            if ~contains(app.MODEL.AnalysisType,'frame')
                app.CHILDRENAPP = saplab.ui.Import(app);
            else
                app.updateMessage('"Import Geometry" functionality can only be used in 2D elements analysis', uistyle('FontColor',[1 .5 .25]));
            end             
        end

        % Menu selected function: InterfaceMenu
        function InterfaceMenuSelected(app, event)
            app.initCallbacks(1);
            if ~app.ISCOMBINED
                updateMessage(app,'Functionality supported only with combined elements.');
                return
            end
            app.CHILDRENAPP = saplab.ui.Interface(app);            
        end

        % Menu selected function: MaterialPropertiesMenu
        function MaterialPropertiesMenuSelected(app, event)
            app.initCallbacks(0);
            app.CHILDRENAPP = saplab.ui.MaterialProperties(app);            
        end

        % Menu selected function: SectionPropertiesMenu
        function SectionPropertiesMenuSelected(app, event)
            app.initCallbacks(2);
            app.CHILDRENAPP = saplab.ui.SectionProperties(app);            
        end

        % Menu selected function: ReinforcementMenu_2
        function ReinforcementMenu_2Selected(app, event)
            app.initCallbacks(1);
            if ~app.ISCOMBINED
                updateMessage(app,'Functionality supported only with combined elements.');
                return
            end
            app.CHILDRENAPP = saplab.ui.Reinforcement(app);            
        end

        % Menu selected function: DistributedMenu
        function DistributedMenuSelected(app, event)
            initCallbacks(app,2);
            app.CHILDRENAPP = saplab.ui.DistributedLoad(app);                  
        end

        % Menu selected function: BodyMenu
        function BodyMenuSelected(app, event)
            initCallbacks(app,0);
            app.CHILDRENAPP = saplab.ui.BodyLoads(app);             
        end

        % Callback function
        function ThermalLoadsMenuSelected(app, event)
            initCallbacks(app,0);
            app.CHILDRENAPP = saplab.ui.ThermalLoad(app);                 
        end

        % Menu selected function: ConstraintMenu
        function ConstraintMenuSelected(app, event)
            if isempty(app.MODEL)
                return;
            end
            if isempty(app.MODEL.Geometry)
                return;
            end
            app.CHILDRENAPP = saplab.ui.BoundaryCondition(app, 'Constraint');                 
        end

        % Menu selected function: ForceMenu
        function ForceMenuSelected(app, event)
            if isempty(app.MODEL)
                return;
            end
            if isempty(app.MODEL.Geometry)
                return;
            end
            app.CHILDRENAPP = saplab.ui.BoundaryCondition(app, 'force');                 
        end

        % Menu selected function: DisplacementMenu
        function DisplacementMenuSelected(app, event)
            if isempty(app.MODEL)
                return;
            end
            if isempty(app.MODEL.Geometry)
                return;
            end
            app.CHILDRENAPP = saplab.ui.BoundaryCondition(app, 'displ');               
        end

        % Menu selected function: PressureMenu
        function PressureMenuSelected(app, event)
            initCallbacks(app,1);
            app.CHILDRENAPP = saplab.ui.BoundPressure(app);                 
        end

        % Menu selected function: ReleaseMenu
        function ReleaseMenuSelected(app, event)
            initCallbacks(app,2);
            app.CHILDRENAPP = saplab.ui.Release(app);                 
        end

        % Callback function
        function ToolbarMenuSelected(app, event)
            
            if strcmpi(app.Toolbar.Visible,'off')
            app.Toolbar.Visible = 'on';
            app.ToolbarMenu.Checked = true;
            else
            app.Toolbar.Visible = 'off';
            app.ToolbarMenu.Checked = false;
            end
        end

        % Menu selected function: InteractivityMenu
        function InteractivityMenuSelected(app, event)
            if strcmpi(app.canvasInteractivity,'off')
                app.canvasInteractivity = 'on';
                app.InteractivityMenu.Checked = true;
            else
                app.canvasInteractivity = 'off';
                app.InteractivityMenu.Checked = false;
            end
        end

        % Callback function
        function DistributedLoadsMenu_2Selected(app, event)
            if ~contains(app.MODEL.AnalysisType, 'frame')
                app.updateMessage('Analysis do not support distributed loads.', uistyle('FontColor','r'));
                return
            end
            try
            if strcmpi(app.plotDistributedLoads,'off')
                app.plotDistributedLoads = 'on';
            else
                app.plotDistributedLoads = 'off';
            end
            catch ME
                updateMessage(app,ME.message, uistyle('FontColor','r'))
            end

        end

        % Callback function
        function BoundaryForcesMenuSelected(app, event)
            try
            if strcmpi(app.plotBoundaryForces,'off')
                app.plotBoundaryForces = 'on';
            else
                app.plotBoundaryForces = 'off';
            end         
            catch ME
                updateMessage(app,ME.message, uistyle('FontColor','r'))
            end
        end

        % Callback function
        function DElementMeshMenuSelected(app, event)

            
            if contains(app.MODEL.AnalysisType,'frame')
                if strcmpi(app.showMesh,'off')
                    app.canvasInteractivity = 'off';
                    app.InteractivityMenu.Checked = false;

                    if isempty(app.MODEL.Mesh)
                        app.canvasInteractivity = 'on';
                        app.InteractivityMenu.Checked = true;
                        return;
                    end
                    app.showMesh='on';
                    app.DElementMeshMenu.Checked = true;
                    saplab.mplot(app.MODEL,'Parent',app.UIAxes);
                else
                    app.showMesh='off';
                    app.canvasInteractivity = 'on';
                    app.InteractivityMenu.Checked = true;
                    app.plot()
                end
            else
                app.canvasInteractivity = 'on';
                app.InteractivityMenu.Checked = true;

                app.DElementMeshMenu.Checked = false;
                updateMessage(app,'1D elements not found.',uistyle('FontColor','r'));
            end 


        end

        % Callback function
        function DElementMeshMenu_2Selected(app, event)
            if contains(app.MODEL.AnalysisType,'frame')
                if strcmpi(app.showMesh,'off')
                    app.canvasInteractivity = 'off';
                    app.InteractivityMenu.Checked = false;

                    if isempty(app.MODEL.Mesh)
                        app.canvasInteractivity = 'on';
                        app.InteractivityMenu.Checked = true;
                        return;
                    end
                    app.showMesh='on';
                    app.DElementMeshMenu.Checked = true;
                    saplab.mplot(app.MODEL,'Parent',app.UIAxes);
                else
                    app.showMesh='off';
                    app.canvasInteractivity = 'on';
                    app.InteractivityMenu.Checked = true;
                    app.plot()
                end
            else
                app.canvasInteractivity = 'on';
                app.InteractivityMenu.Checked = true;

                app.DElementMeshMenu.Checked = false;
                updateMessage(app,'1D elements not found.',uistyle('FontColor','r'));
            end             
        end

        % Menu selected function: MeshoptionsMenu
        function MeshoptionsMenuSelected(app, event)
            if contains(app.MODEL.AnalysisType,'frame')
                saplab.ui.Mesh1(app);
            else
                saplab.ui.Mesh2(app);
            end
        end

        % Callback function
        function ThermalLoadsMenu_2Selected(app, event)
            try
            if strcmpi(app.plotThermalLoads,'off')
                app.plotThermalLoads = 'on';
                app.ThermalLoadsMenu_2.Checked = true;
            else
                app.plotThermalLoads = 'off';
                app.ThermalLoadsMenu_2.Checked = false;
            end         
            catch ME
                updateMessage(app,ME.message, uistyle('FontColor','r'))
            end            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isempty(app.MODEL)
                selection = uiconfirm(app.UIFigure,'Want to close application?','Confirm Close',...
                                        'Icon','warning');
                switch selection
                    case 'OK'
                        app.delete();
                    otherwise
                        return
                end
            else
                selection = uiconfirm(app.UIFigure,'Want to close application?','Confirm Close',...
                                        'Icon','warning'); 
                switch selection
                    case 'OK'
                        if ~app.ISSAVED
                            selection2 = uiconfirm(app.UIFigure,'Want to save project?','Confirm Close',...
                                                    'Icon','warning');  
                            
                            switch selection2
                                case 'OK'
                                    %save function
                                    app.SaveMenuSelected();
                                    app.delete();
                                otherwise
                                    app.delete();
                            end
                        else
                            app.delete();
                        end
                    otherwise
                        app.delete();
                end

            end
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if isempty(app.MODEL)
                updateMessage(app,'No model found.', uistyle('FontColor','r'));
                return
            end
            name = app.PREFERENCES.NAME;
            saplab.save(app.MODEL,'FileName', name);
            updateMessage(app,['File ', char(name),' saved successfully! See in MATLAB Current Folder panel.']);
            uialert(app.UIFigure,'File saved successfully!','Message','Icon','success');
        end

        % Menu selected function: ElasticLinearSolverMenu
        function ElasticLinearSolverMenuSelected(app, event)
            app.canvasInteractivity = 'off'; 
            try
             tic
             R = saplab.elinSolver(app.MODEL); %#ok
             k = toc;
             catch ME
                 app.updateMessage(ME.message); return
             end
             app.CHILDRENAPP=saplab.ui.ResultsPlot(app.MODEL,k);
             updateMessage(app,'Model solved successfully!',uistyle("FontColor",[0 .5 0]));
             updateMessage(app,['Elapsed time: ', num2str(k)],uistyle("FontColor",[0 .5 0]));
        end

        % Menu selected function: AxialForcesNxMenu
        function AxialForcesNxMenuSelected(app, event)
            if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            ForMom = app.MODEL.Results.ForcesAndMoments;
            F = ForMom.Nx;
            saplab.rplot(app.MODEL,'Diagram',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end
        end

        % Menu selected function: ShearForcesFyMenu
        function ShearForcesFyMenuSelected(app, event)
            if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            ForMom = app.MODEL.Results.ForcesAndMoments;
            F = ForMom.Ty;
            saplab.rplot(app.MODEL,'Diagram',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end
        end

        % Menu selected function: ShearForcesFzMenu
        function ShearForcesFzMenuSelected(app, event)
            if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            ForMom = app.MODEL.Results.ForcesAndMoments;
            F = ForMom.Tz;
            saplab.rplot(app.MODEL,'Diagram',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end
        end

        % Menu selected function: TorqueMomentMxMenu
        function TorqueMomentMxMenuSelected(app, event)
            if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            ForMom = app.MODEL.Results.ForcesAndMoments;
            F = ForMom.Mx;
            saplab.rplot(app.MODEL,'Diagram',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end
        end

        % Menu selected function: BendingMomentMyMenu
        function BendingMomentMyMenuSelected(app, event)
            if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            ForMom = app.MODEL.Results.ForcesAndMoments;
            F = ForMom.My;
            saplab.rplot(app.MODEL,'Diagram',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end
        end

        % Menu selected function: BendingMomentMzMenu
        function BendingMomentMzMenuSelected(app, event)
            if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            ForMom = app.MODEL.Results.ForcesAndMoments;
            F = ForMom.Mz;
            saplab.rplot(app.MODEL,'Diagram',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end
        end

        % Callback function
        function StartAnimationMenuSelected(app, event)
            if isempty(app.MODEL)
                updateMessage(app,'No model found.', uistyle('FontColor','r')); return;
            end

            if isempty(app.MODEL.Results)
                updateMessage(app,'Model has not been solved.', uistyle('FontColor','r')); return
            end

            if ~contains(app.MODEL.AnalysisType,'frame')
                updateMessage(app,'Animations are only allowed in frame analysis.');
                return;
            end

            app.plotDeformedShape = 'on';
            app.plotDisplacements = 'off';
            

            maxS = 1/(max(abs(app.MODEL.U)));
            scaleFactor = 0:maxS/10:maxS;
            if app.ANIMATIONSTATE 
                app.ANIMATIONSTATE = false;
                app.StartAnimationMenu.Text = 'Start Animation';
                app.StartAnimationMenu.ForegroundColor = [0.39,0.83,0.07];

            else
                app.ANIMATIONSTATE = true;
                app.StartAnimationMenu.Text = 'Stop Animation';
                app.StartAnimationMenu.ForegroundColor = 'r';
            end
            i = 1;
            ascend = true;
            xlim=app.UIAxes.XLim;
            ylim=app.UIAxes.YLim;
            zlim=app.UIAxes.ZLim;


            while app.ANIMATIONSTATE

                if contains(app.MODEL.AnalysisType,'frame')
                    app.plot(scaleFactor(i),xlim,ylim,zlim);
                    pause(0.1)
                end

                if ascend 
                    i=i+1;
                else
                    i=i-1;
                end

                if i == numel(scaleFactor)
                    ascend = false;
                end
                if i == 1
                    ascend = true;
                end
                
                title(app.UIAxes,['Plot: DEFORMED SHAPE - AMPLITUDE: ', num2str(scaleFactor(i))]);    

                if ~app.ANIMATIONSTATE
                    break;
                end
            end            
        end

        % Menu selected function: StressSXXMenu
        function StressSXXMenuSelected(app, event)
            if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            NodStress = app.MODEL.Results.NodalStress;
            F = NodStress.sxx;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end
        end

        % Menu selected function: ScaleFactorMenu
        function ScaleFactorMenuSelected(app, event)
            prompt = {'Enter scale factor value:'};
            dlgtitle = 'Input SF';
            dims = [1 35];
            definput = {'1'};
            answer = inputdlg(prompt,dlgtitle,dims,definput)  ;
            app.scaleFactorValue = str2double(answer{1});
            app.AmplitudeValue.Text = num2str(app.scaleFactorValue);
        end

        % Menu selected function: StressSYYMenu
        function StressSYYMenuSelected(app, event)
            if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            NodStress = app.MODEL.Results.NodalStress;
            F = NodStress.syy;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end        
        end

        % Menu selected function: StressSXYMenu
        function StressSXYMenuSelected(app, event)
            if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            NodStress = app.MODEL.Results.NodalStress;
            F = NodStress.sxy;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end           
        end

        % Menu selected function: VonMisesStressS11Menu
        function VonMisesStressS11MenuSelected(app, event)
             if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            VonMisesStress = app.MODEL.Results.VonMisesStress;
            F = VonMisesStress.s11;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end              
        end

        % Menu selected function: VonMisesStressS22Menu
        function VonMisesStressS22MenuSelected(app, event)
             if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            VonMisesStress = app.MODEL.Results.VonMisesStress;
            F = VonMisesStress.s22;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end        
        end

        % Menu selected function: VonMisesStressS12Menu
        function VonMisesStressS12MenuSelected(app, event)
             if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            VonMisesStress = app.MODEL.Results.VonMisesStress;
            F = VonMisesStress.s12;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end                  
        end

        % Menu selected function: StrainEXXMenu
        function StrainEXXMenuSelected(app, event)
             if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            NodalStrain = app.MODEL.Results.NodalStrain;
            F = NodalStrain.exx;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end                 
        end

        % Menu selected function: DisplacementUXMenu
        function DisplacementUXMenuSelected(app, event)
             if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            NodalSolution = app.MODEL.Results.NodalSolution;
            F = NodalSolution.ux;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end     
        end

        % Callback function
        function PlotMenuSelected(app, event)
            if ~isempty(app.MODEL.Results)
                if contains(app.MODEL.AnalysisType,'frame')
                    app.plotDeformedShape = 'on';
                    app.plotDisplacements = 'off';      
                    app.plot();
                else
                    app.plotDeformedShape = 'on';
                    app.plotDisplacements = 'off';  
                    app.forceandmomentFields = '';
                    app.plotStress = '';
                    app.plot2('Imported')
                end
            end    
            title(app.UIAxes,['Plot: DEFORMED SHAPE - AMPLITUDE: ' app.AmplitudeValue.Text]); 
            updateMessage(app,'Plotting Deformed Shape.')  
        end

        % Menu selected function: StrainEYYMenu
        function StrainEYYMenuSelected(app, event)
             if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            NodalStrain = app.MODEL.Results.NodalStrain;
            F = NodalStrain.eyy;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end 
        end

        % Menu selected function: StrainEXYMenu
        function StrainEXYMenuSelected(app, event)
             if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            NodalStrain = app.MODEL.Results.NodalStrain;
            F = NodalStrain.exy;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end           
        end

        % Menu selected function: DisplacementUYMenu
        function DisplacementUYMenuSelected(app, event)
             if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            NodalSolution = app.MODEL.Results.NodalSolution;
            F = NodalSolution.uy;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end            
        end

        % Menu selected function: DisplacementUZMenu
        function DisplacementUZMenuSelected(app, event)
             if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            try
            NodalSolution = app.MODEL.Results.NodalSolution;
            F = NodalSolution.uz;
            saplab.rplot(app.MODEL,'XYData',F,'Parent',app.UIAxes);
            catch ME
                app.updateMessage(ME.message);
            end          
        end

        % Menu selected function: StiffnessMatrixMenu
        function StiffnessMatrixMenuSelected(app, event)
            if isempty(app.MODEL)
                updateMessage(app,'No model found.', uistyle('FontColor','r')); return;
            end            
            
            if isempty(app.MODEL.Results)
               updateMessage(app,'Model has not been solved!',uistyle('FontColor','r'));
            end      
            app.CHILDRENAPP = saplab.ui.FEResults(app.MODEL.K, 'Stiffness Matrix');
        end

        % Menu selected function: ForcesVectorMenu
        function ForcesVectorMenuSelected(app, event)
            if isempty(app.MODEL)
                updateMessage(app,'No model found.', uistyle('FontColor','r')); return;
            end            
            
            if isempty(app.MODEL.Results)
               updateMessage(app,'Model has not been solved!',uistyle('FontColor','r'));
            end      
            app.CHILDRENAPP = saplab.ui.FEResults(app.MODEL.F, 'Forces Vector');          
        end

        % Menu selected function: EnforcedDisplacementVectorMenu
        function EnforcedDisplacementVectorMenuSelected(app, event)
            if isempty(app.MODEL)
                updateMessage(app,'No model found.', uistyle('FontColor','r')); return;
            end            
            
            if isempty(app.MODEL.Results)
               updateMessage(app,'Model has not been solved!',uistyle('FontColor','r'));
            end      
            app.CHILDRENAPP = saplab.ui.FEResults(app.MODEL.Ud, 'Enforced Displacements Vector');                     
        end

        % Menu selected function: BoundaryConditionsMatrixMenu
        function BoundaryConditionsMatrixMenuSelected(app, event)
            if isempty(app.MODEL)
                updateMessage(app,'No model found.', uistyle('FontColor','r')); return;
            end            
            
            if isempty(app.MODEL.Results)
               updateMessage(app,'Model has not been solved!',uistyle('FontColor','r'));
            end      
            app.CHILDRENAPP = saplab.ui.FEResults(app.MODEL.H,'Boundary Conditions Matrix');                 
        end

        % Menu selected function: BoundaryConditionsVectorMenu
        function BoundaryConditionsVectorMenuSelected(app, event)
            if isempty(app.MODEL)
                updateMessage(app,'No model found.', uistyle('FontColor','r')); return;
            end            
            
            if isempty(app.MODEL.Results)
               updateMessage(app,'Model has not been solved!',uistyle('FontColor','r'));
            end      
            app.CHILDRENAPP = saplab.ui.FEResults(app.MODEL.R,'Boundary Conditions Vector');             
        end

        % Menu selected function: FESolutionMenu
        function FESolutionMenuSelected(app, event)
            if isempty(app.MODEL)
                updateMessage(app,'No model found.', uistyle('FontColor','r')); return;
            end            
            
            if isempty(app.MODEL.Results)
               updateMessage(app,'Model has not been solved!',uistyle('FontColor','r'));
            end      
            app.CHILDRENAPP = saplab.ui.FEResults(app.MODEL.U,'FE Solution');              
        end

        % Menu selected function: NonLinearSolverMenu
        function NonLinearSolverMenuSelected(app, event)
            app.canvasInteractivity = 'off';  
            try
             tic
             R = saplab.nlinSolver(app.MODEL); %#ok
             k = toc;
             catch ME
                 app.updateMessage(ME.message); return
             end
             app.CHILDRENAPP=saplab.ui.ResultsPlot(app.MODEL,k);
             updateMessage(app,'Model solved successfully!',uistyle("FontColor",[0 .5 0]));
             updateMessage(app,['Elapsed time: ', num2str(k)],uistyle("FontColor",[0 .5 0]));            
        end

        % Menu selected function: DeformedShapeMenu_2
        function DeformedShapeMenu_2Selected(app, event)
            if isempty(app.MODEL)
                app.updateMessage('No model found.'); return;
            end
            tt = app.MODEL.Results
            if isempty(app.MODEL.Results)
                app.updateMessage('Model has not been solved'); return;
            end
            saplab.rplot(app.MODEL,'Deformation','Parent',app.UIAxes);
        end

        % Menu selected function: DisplayMeshMenu
        function DisplayMeshMenuSelected(app, event)
                if strcmpi(app.showMesh,'off')
                    app.canvasInteractivity = 'off';
                    app.InteractivityMenu.Checked = false;

                    if isempty(app.MODEL.Mesh)
                        app.canvasInteractivity = 'on';
                        app.InteractivityMenu.Checked = true;
                        return;
                    end
                    app.showMesh='on';
                    saplab.mplot(app.MODEL,'Parent',app.UIAxes);
                    hold(app.UIAXes,'on')
                    app.plot()
                else
                    app.showMesh='off';
                    app.canvasInteractivity = 'on';
                    app.InteractivityMenu.Checked = true;
                    app.plot()
                end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SAPLAB - Structural Analysis Program Laboratory';
            app.UIFigure.Icon = 'Application Icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = ' File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewClicked, true);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New              ';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Accelerator = 'Q';
            app.ExitMenu.Text = 'Exit';
            app.GeometryMenu = uimenu(app.UIFigure);
            app.GeometryMenu.Text = 'Geometry';
            app.AddPointMenu = uimenu(app.GeometryMenu);
            app.AddPointMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPointMenuSelected, true);
            app.AddPointMenu.Text = 'Add Point';
            app.AddLineMenu = uimenu(app.GeometryMenu);
            app.AddLineMenu.MenuSelectedFcn = createCallbackFcn(app, @AddLineMenuSelected, true);
            app.AddLineMenu.Text = 'Add Line';
            app.ConnectMenu = uimenu(app.GeometryMenu);
            app.ConnectMenu.MenuSelectedFcn = createCallbackFcn(app, @ConnectMenuSelected, true);
            app.ConnectMenu.Text = 'Connect';
            app.TrimLineMenu = uimenu(app.GeometryMenu);
            app.TrimLineMenu.MenuSelectedFcn = createCallbackFcn(app, @TrimLineMenuSelected, true);
            app.TrimLineMenu.Text = 'Trim Line';
            app.TrimLineAtLocationMenu = uimenu(app.GeometryMenu);
            app.TrimLineAtLocationMenu.MenuSelectedFcn = createCallbackFcn(app, @TrimLineAtLocationMenuSelected, true);
            app.TrimLineAtLocationMenu.Text = 'Trim Line At Location                ';
            app.AddCircleMenu = uimenu(app.GeometryMenu);
            app.AddCircleMenu.MenuSelectedFcn = createCallbackFcn(app, @AddCircleMenuSelected, true);
            app.AddCircleMenu.Separator = 'on';
            app.AddCircleMenu.Text = 'Add Circle';
            app.AddRectangleMenu = uimenu(app.GeometryMenu);
            app.AddRectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @AddRectangleMenuSelected, true);
            app.AddRectangleMenu.Text = 'Add Rectangle';
            app.AddEllipseMenu = uimenu(app.GeometryMenu);
            app.AddEllipseMenu.MenuSelectedFcn = createCallbackFcn(app, @AddEllipseMenuSelected, true);
            app.AddEllipseMenu.Text = 'Add Ellipse';
            app.AddPolygonMenu = uimenu(app.GeometryMenu);
            app.AddPolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPolygonMenuSelected, true);
            app.AddPolygonMenu.Text = 'Add Polygon';
            app.TrimEdgeMenu = uimenu(app.GeometryMenu);
            app.TrimEdgeMenu.MenuSelectedFcn = createCallbackFcn(app, @TrimEdgeMenuSelected, true);
            app.TrimEdgeMenu.Text = 'Trim Edge';
            app.BooleanOperationMenu = uimenu(app.GeometryMenu);
            app.BooleanOperationMenu.MenuSelectedFcn = createCallbackFcn(app, @BooleanOperationMenuSelected, true);
            app.BooleanOperationMenu.Separator = 'on';
            app.BooleanOperationMenu.Text = 'Boolean Operation';
            app.ImportDefaultGeometryMenu = uimenu(app.GeometryMenu);
            app.ImportDefaultGeometryMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportDefaultGeometryMenuSelected, true);
            app.ImportDefaultGeometryMenu.Text = 'Import Default Geometry';
            app.StructuralMenu = uimenu(app.UIFigure);
            app.StructuralMenu.Text = 'Structural';
            app.MaterialPropertiesMenu = uimenu(app.StructuralMenu);
            app.MaterialPropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @MaterialPropertiesMenuSelected, true);
            app.MaterialPropertiesMenu.Accelerator = 'm';
            app.MaterialPropertiesMenu.Text = 'Material Properties       ';
            app.SectionPropertiesMenu = uimenu(app.StructuralMenu);
            app.SectionPropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @SectionPropertiesMenuSelected, true);
            app.SectionPropertiesMenu.Text = 'Section Properties';
            app.ReinforcementMenu_2 = uimenu(app.StructuralMenu);
            app.ReinforcementMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ReinforcementMenu_2Selected, true);
            app.ReinforcementMenu_2.Separator = 'on';
            app.ReinforcementMenu_2.Text = 'Reinforcement';
            app.InterfaceMenu = uimenu(app.StructuralMenu);
            app.InterfaceMenu.MenuSelectedFcn = createCallbackFcn(app, @InterfaceMenuSelected, true);
            app.InterfaceMenu.Accelerator = 'i';
            app.InterfaceMenu.Text = 'Interface';
            app.LoadingMenu = uimenu(app.UIFigure);
            app.LoadingMenu.Text = 'Loading';
            app.BodyMenu = uimenu(app.LoadingMenu);
            app.BodyMenu.MenuSelectedFcn = createCallbackFcn(app, @BodyMenuSelected, true);
            app.BodyMenu.Accelerator = 'B';
            app.BodyMenu.Text = 'Body';
            app.DistributedMenu = uimenu(app.LoadingMenu);
            app.DistributedMenu.MenuSelectedFcn = createCallbackFcn(app, @DistributedMenuSelected, true);
            app.DistributedMenu.Accelerator = 'D';
            app.DistributedMenu.Text = 'Distributed';
            app.BoundariesMenu = uimenu(app.UIFigure);
            app.BoundariesMenu.Text = 'Boundaries';
            app.ConstraintMenu = uimenu(app.BoundariesMenu);
            app.ConstraintMenu.MenuSelectedFcn = createCallbackFcn(app, @ConstraintMenuSelected, true);
            app.ConstraintMenu.Accelerator = 'C';
            app.ConstraintMenu.Text = 'Constraint                 ';
            app.ForceMenu = uimenu(app.BoundariesMenu);
            app.ForceMenu.MenuSelectedFcn = createCallbackFcn(app, @ForceMenuSelected, true);
            app.ForceMenu.Text = 'Force';
            app.DisplacementMenu = uimenu(app.BoundariesMenu);
            app.DisplacementMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplacementMenuSelected, true);
            app.DisplacementMenu.Accelerator = 'u';
            app.DisplacementMenu.Text = 'Displacement';
            app.PressureMenu = uimenu(app.BoundariesMenu);
            app.PressureMenu.MenuSelectedFcn = createCallbackFcn(app, @PressureMenuSelected, true);
            app.PressureMenu.Text = 'Pressure';
            app.ReleaseMenu = uimenu(app.BoundariesMenu);
            app.ReleaseMenu.MenuSelectedFcn = createCallbackFcn(app, @ReleaseMenuSelected, true);
            app.ReleaseMenu.Separator = 'on';
            app.ReleaseMenu.Accelerator = 'R';
            app.ReleaseMenu.Text = 'Release';
            app.MeshMenu_2 = uimenu(app.UIFigure);
            app.MeshMenu_2.Text = 'Mesh';
            app.MeshoptionsMenu = uimenu(app.MeshMenu_2);
            app.MeshoptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @MeshoptionsMenuSelected, true);
            app.MeshoptionsMenu.Text = 'Mesh options';
            app.DisplayMeshMenu = uimenu(app.MeshMenu_2);
            app.DisplayMeshMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplayMeshMenuSelected, true);
            app.DisplayMeshMenu.Text = 'Display Mesh              ';
            app.CanvasMenu = uimenu(app.UIFigure);
            app.CanvasMenu.Text = 'Canvas';
            app.InteractivityMenu = uimenu(app.CanvasMenu);
            app.InteractivityMenu.MenuSelectedFcn = createCallbackFcn(app, @InteractivityMenuSelected, true);
            app.InteractivityMenu.Checked = 'on';
            app.InteractivityMenu.Text = 'Interactivity';
            app.LabelsMenu = uimenu(app.CanvasMenu);
            app.LabelsMenu.Text = 'Labels            ';
            app.PointsMenu = uimenu(app.LabelsMenu);
            app.PointsMenu.MenuSelectedFcn = createCallbackFcn(app, @PointsMenuSelected, true);
            app.PointsMenu.Accelerator = 'P';
            app.PointsMenu.Text = 'Points                ';
            app.LinesMenu = uimenu(app.LabelsMenu);
            app.LinesMenu.MenuSelectedFcn = createCallbackFcn(app, @LinesMenuSelected, true);
            app.LinesMenu.Accelerator = 'L';
            app.LinesMenu.Text = 'Lines';
            app.VerticesMenu = uimenu(app.LabelsMenu);
            app.VerticesMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticesMenuSelected, true);
            app.VerticesMenu.Separator = 'on';
            app.VerticesMenu.Accelerator = 'V';
            app.VerticesMenu.Text = 'Vertices';
            app.EdgesMenu = uimenu(app.LabelsMenu);
            app.EdgesMenu.MenuSelectedFcn = createCallbackFcn(app, @EdgesMenuSelected, true);
            app.EdgesMenu.Accelerator = 'E';
            app.EdgesMenu.Text = 'Edges';
            app.FacesMenu = uimenu(app.LabelsMenu);
            app.FacesMenu.MenuSelectedFcn = createCallbackFcn(app, @FacesMenuSelected, true);
            app.FacesMenu.Accelerator = 'F';
            app.FacesMenu.Text = 'Faces';
            app.ReinforcementMenu = uimenu(app.LabelsMenu);
            app.ReinforcementMenu.MenuSelectedFcn = createCallbackFcn(app, @ReinforcementMenuSelected, true);
            app.ReinforcementMenu.Separator = 'on';
            app.ReinforcementMenu.Accelerator = 'R';
            app.ReinforcementMenu.Text = 'Reinforcement           ';
            app.SolveMenu = uimenu(app.UIFigure);
            app.SolveMenu.Text = 'Solve';
            app.ElasticLinearSolverMenu = uimenu(app.SolveMenu);
            app.ElasticLinearSolverMenu.MenuSelectedFcn = createCallbackFcn(app, @ElasticLinearSolverMenuSelected, true);
            app.ElasticLinearSolverMenu.Text = 'Elastic Linear Solver';
            app.NonLinearSolverMenu = uimenu(app.SolveMenu);
            app.NonLinearSolverMenu.MenuSelectedFcn = createCallbackFcn(app, @NonLinearSolverMenuSelected, true);
            app.NonLinearSolverMenu.Text = 'Non Linear Solver';
            app.ResultsMenu = uimenu(app.UIFigure);
            app.ResultsMenu.Text = 'Results';
            app.DeformedShapeMenu_2 = uimenu(app.ResultsMenu);
            app.DeformedShapeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DeformedShapeMenu_2Selected, true);
            app.DeformedShapeMenu_2.Text = 'Deformed Shape';
            app.StressMenu = uimenu(app.ResultsMenu);
            app.StressMenu.Text = 'Stress           ';
            app.BeamElementsMenu = uimenu(app.StressMenu);
            app.BeamElementsMenu.Text = 'Beam Elements            ';
            app.AxialForcesNxMenu = uimenu(app.BeamElementsMenu);
            app.AxialForcesNxMenu.MenuSelectedFcn = createCallbackFcn(app, @AxialForcesNxMenuSelected, true);
            app.AxialForcesNxMenu.Text = 'Axial - Forces (Nx)';
            app.ShearForcesFyMenu = uimenu(app.BeamElementsMenu);
            app.ShearForcesFyMenu.MenuSelectedFcn = createCallbackFcn(app, @ShearForcesFyMenuSelected, true);
            app.ShearForcesFyMenu.Separator = 'on';
            app.ShearForcesFyMenu.Text = 'Shear - Forces (Fy)';
            app.ShearForcesFzMenu = uimenu(app.BeamElementsMenu);
            app.ShearForcesFzMenu.MenuSelectedFcn = createCallbackFcn(app, @ShearForcesFzMenuSelected, true);
            app.ShearForcesFzMenu.Text = 'Shear - Forces (Fz)';
            app.TorqueMomentMxMenu = uimenu(app.BeamElementsMenu);
            app.TorqueMomentMxMenu.MenuSelectedFcn = createCallbackFcn(app, @TorqueMomentMxMenuSelected, true);
            app.TorqueMomentMxMenu.Separator = 'on';
            app.TorqueMomentMxMenu.Text = 'Torque - Moment (Mx)                 ';
            app.BendingMomentMyMenu = uimenu(app.BeamElementsMenu);
            app.BendingMomentMyMenu.MenuSelectedFcn = createCallbackFcn(app, @BendingMomentMyMenuSelected, true);
            app.BendingMomentMyMenu.Separator = 'on';
            app.BendingMomentMyMenu.Text = 'Bending - Moment (My)';
            app.BendingMomentMzMenu = uimenu(app.BeamElementsMenu);
            app.BendingMomentMzMenu.MenuSelectedFcn = createCallbackFcn(app, @BendingMomentMzMenuSelected, true);
            app.BendingMomentMzMenu.Text = 'Bending - Moment (Mz)';
            app.PlaneElementsMenu = uimenu(app.StressMenu);
            app.PlaneElementsMenu.Text = 'Plane Elements';
            app.StressSXXMenu = uimenu(app.PlaneElementsMenu);
            app.StressSXXMenu.MenuSelectedFcn = createCallbackFcn(app, @StressSXXMenuSelected, true);
            app.StressSXXMenu.Text = 'Stress SXX               ';
            app.StressSYYMenu = uimenu(app.PlaneElementsMenu);
            app.StressSYYMenu.MenuSelectedFcn = createCallbackFcn(app, @StressSYYMenuSelected, true);
            app.StressSYYMenu.Text = 'Stress SYY';
            app.StressSXYMenu = uimenu(app.PlaneElementsMenu);
            app.StressSXYMenu.MenuSelectedFcn = createCallbackFcn(app, @StressSXYMenuSelected, true);
            app.StressSXYMenu.Text = 'Stress SXY';
            app.VonMisesStressS11Menu = uimenu(app.PlaneElementsMenu);
            app.VonMisesStressS11Menu.MenuSelectedFcn = createCallbackFcn(app, @VonMisesStressS11MenuSelected, true);
            app.VonMisesStressS11Menu.Separator = 'on';
            app.VonMisesStressS11Menu.Text = 'Von Mises Stress S11                  ';
            app.VonMisesStressS22Menu = uimenu(app.PlaneElementsMenu);
            app.VonMisesStressS22Menu.MenuSelectedFcn = createCallbackFcn(app, @VonMisesStressS22MenuSelected, true);
            app.VonMisesStressS22Menu.Text = 'Von Mises Stress S22';
            app.VonMisesStressS12Menu = uimenu(app.PlaneElementsMenu);
            app.VonMisesStressS12Menu.MenuSelectedFcn = createCallbackFcn(app, @VonMisesStressS12MenuSelected, true);
            app.VonMisesStressS12Menu.Text = 'Von Mises Stress S12';
            app.StrainMenu = uimenu(app.ResultsMenu);
            app.StrainMenu.Text = 'Strain';
            app.PlaneElementsMenu_2 = uimenu(app.StrainMenu);
            app.PlaneElementsMenu_2.Separator = 'on';
            app.PlaneElementsMenu_2.Text = 'Plane Elements';
            app.StrainEXXMenu = uimenu(app.PlaneElementsMenu_2);
            app.StrainEXXMenu.MenuSelectedFcn = createCallbackFcn(app, @StrainEXXMenuSelected, true);
            app.StrainEXXMenu.Text = 'Strain EXX              ';
            app.StrainEYYMenu = uimenu(app.PlaneElementsMenu_2);
            app.StrainEYYMenu.MenuSelectedFcn = createCallbackFcn(app, @StrainEYYMenuSelected, true);
            app.StrainEYYMenu.Text = 'Strain EYY';
            app.StrainEXYMenu = uimenu(app.PlaneElementsMenu_2);
            app.StrainEXYMenu.MenuSelectedFcn = createCallbackFcn(app, @StrainEXYMenuSelected, true);
            app.StrainEXYMenu.Text = 'Strain EXY';
            app.ScaleFactorMenu = uimenu(app.ResultsMenu);
            app.ScaleFactorMenu.MenuSelectedFcn = createCallbackFcn(app, @ScaleFactorMenuSelected, true);
            app.ScaleFactorMenu.Text = 'Scale Factor             ';
            app.DisplacementsMenu = uimenu(app.ResultsMenu);
            app.DisplacementsMenu.Separator = 'on';
            app.DisplacementsMenu.Text = 'Displacements              ';
            app.FieldMenu = uimenu(app.DisplacementsMenu);
            app.FieldMenu.Separator = 'on';
            app.FieldMenu.Text = 'Field';
            app.DisplacementUXMenu = uimenu(app.FieldMenu);
            app.DisplacementUXMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplacementUXMenuSelected, true);
            app.DisplacementUXMenu.Text = 'Displacement UX                ';
            app.DisplacementUYMenu = uimenu(app.FieldMenu);
            app.DisplacementUYMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplacementUYMenuSelected, true);
            app.DisplacementUYMenu.Text = 'Displacement UY';
            app.DisplacementUZMenu = uimenu(app.FieldMenu);
            app.DisplacementUZMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplacementUZMenuSelected, true);
            app.DisplacementUZMenu.Text = 'Displacement UZ';
            app.FEResultsMenu = uimenu(app.ResultsMenu);
            app.FEResultsMenu.Separator = 'on';
            app.FEResultsMenu.Text = 'FE Results';
            app.StiffnessMatrixMenu = uimenu(app.FEResultsMenu);
            app.StiffnessMatrixMenu.MenuSelectedFcn = createCallbackFcn(app, @StiffnessMatrixMenuSelected, true);
            app.StiffnessMatrixMenu.Accelerator = 'K';
            app.StiffnessMatrixMenu.Text = 'Stiffness Matrix ';
            app.ForcesVectorMenu = uimenu(app.FEResultsMenu);
            app.ForcesVectorMenu.MenuSelectedFcn = createCallbackFcn(app, @ForcesVectorMenuSelected, true);
            app.ForcesVectorMenu.Text = 'Forces Vector';
            app.EnforcedDisplacementVectorMenu = uimenu(app.FEResultsMenu);
            app.EnforcedDisplacementVectorMenu.MenuSelectedFcn = createCallbackFcn(app, @EnforcedDisplacementVectorMenuSelected, true);
            app.EnforcedDisplacementVectorMenu.Text = 'Enforced Displacement Vector';
            app.BoundaryConditionsMatrixMenu = uimenu(app.FEResultsMenu);
            app.BoundaryConditionsMatrixMenu.MenuSelectedFcn = createCallbackFcn(app, @BoundaryConditionsMatrixMenuSelected, true);
            app.BoundaryConditionsMatrixMenu.Text = 'Boundary Conditions Matrix';
            app.BoundaryConditionsVectorMenu = uimenu(app.FEResultsMenu);
            app.BoundaryConditionsVectorMenu.MenuSelectedFcn = createCallbackFcn(app, @BoundaryConditionsVectorMenuSelected, true);
            app.BoundaryConditionsVectorMenu.Text = 'Boundary Conditions Vector';
            app.FESolutionMenu = uimenu(app.FEResultsMenu);
            app.FESolutionMenu.MenuSelectedFcn = createCallbackFcn(app, @FESolutionMenuSelected, true);
            app.FESolutionMenu.Text = 'FE Solution';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.BottomBar = uipanel(app.MainGridLayout);
            app.GridLayout = uigridlayout(app.BottomBar);
            app.AnalysisTypeLabel = uilabel(app.GridLayout);
            app.AnalysisTypeLabel.Text = 'AnalysisType';
            app.ZoomIn_ShortCut = uibutton(app.GridLayout, 'push');
            app.ZoomIn_ShortCut.Icon = 'ZOOMIN.gif';
            app.ZoomIn_ShortCut.Text = '';
            app.ZoomOut_ShortCut = uibutton(app.GridLayout, 'push');
            app.ZoomOut_ShortCut.Icon = 'ZOOMOUT.gif';
            app.ZoomOut_ShortCut.Text = '';
            app.Rotate_ShortCut = uibutton(app.GridLayout, 'push');
            app.Rotate_ShortCut.Icon = 'rotate01.gif';
            app.Rotate_ShortCut.Text = '';
            app.X_ShortCut = uibutton(app.GridLayout, 'push');
            app.X_ShortCut.Icon = 'ASSEX.png';
            app.X_ShortCut.Text = '';
            app.Y_ShortCut = uibutton(app.GridLayout, 'push');
            app.Y_ShortCut.Icon = 'ASSEY.png';
            app.Y_ShortCut.Text = '';
            app.Z_ShortCut = uibutton(app.GridLayout, 'push');
            app.Z_ShortCut.Icon = 'ASSEZ.png';
            app.Z_ShortCut.Text = '';
            app.XLabel = uilabel(app.GridLayout);
            app.XLabel.Text = 'X';
            app.Xpos = uilabel(app.GridLayout);
            app.Xpos.Text = '';
            app.YLabel = uilabel(app.GridLayout);
            app.YLabel.Text = {'Y'; ''};
            app.ZLabel = uilabel(app.GridLayout);
            app.ZLabel.Text = 'Z';
            app.NALabel = uilabel(app.GridLayout);
            app.NALabel.Text = 'N/A';
            app.Ypos = uilabel(app.GridLayout);
            app.Ypos.Text = '';
            app.AmplitudeLabel = uilabel(app.GridLayout);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.AmplitudeValue = uilabel(app.GridLayout);
            app.AmplitudeValue.Text = 'Auto';
            app.UnitsLabel = uilabel(app.GridLayout);
            app.UnitsLabel.Text = 'Units';
            app.UnitsTextLabel = uilabel(app.GridLayout);
            app.UnitsTextLabel.Text = {'UnitsText'; ''};
            app.CanvasPanel = uipanel(app.MainGridLayout);
            app.CanvasPanel.Visible = 'off';
            app.CanvasGrid = uigridlayout(app.CanvasPanel);
            app.UIAxes = uiaxes(app.CanvasGrid);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MessagePanel = uipanel(app.MainGridLayout);
            app.MessageGridLayout = uigridlayout(app.MessagePanel);
            app.ClearMessagesButton = uibutton(app.MessageGridLayout, 'push');
            app.ClearMessagesButton.Text = 'Clear Messages';
            app.CurrGeomDropDown = uidropdown(app.MessageGridLayout);
            app.CurrGeomDropDown.Items = {'ALL'};
            app.CurrGeomDropDown.ValueChangedFcn = createCallbackFcn(app, @CurrGeomDropDownValueChanged, true);
            app.CurrGeomDropDown.Value = 'ALL';
            app.ProgramMessageLabel = uilabel(app.MessageGridLayout);
            app.ProgramMessageLabel.Text = 'Program Message';
            app.SelectedObjectLabel = uilabel(app.MessageGridLayout);
            app.SelectedObjectLabel.Text = 'Selected Object';
            app.NONELabel = uilabel(app.MessageGridLayout);
            app.NONELabel.Text = 'NONE';
            app.Message = uitree(app.MessageGridLayout);
            app.Node = uitreenode(app.Message);
            app.Node.Text = 'Welcome to SAPLAB Application - Structural Analysis Program Laboratory';
            app.Node_2 = uitreenode(app.Message);
            app.Node_2.Text = '---------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.ProgramVersion100Node = uitreenode(app.Message);
            app.ProgramVersion100Node.Text = 'Program Version: 1.0.0';
            app.DateNode = uitreenode(app.Message);
            app.DateNode.Text = 'Date:';
            app.AuthorFIDUCIAGabrieleIDs265128Node = uitreenode(app.Message);
            app.AuthorFIDUCIAGabrieleIDs265128Node.Text = 'Author: FIDUCIA Gabriele - ID: s265128';
            app.Node_3 = uitreenode(app.Message);
            app.Node_3.Text = '---------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.MessagesNode = uitreenode(app.Message);
            app.MessagesNode.Text = 'Messages';
            app.Tree = uitree(app.MainGridLayout);
            app.Tree.Enable = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.Text = 'Delete';
            app.ViewPropertiesMenu = uimenu(app.ContextMenu);
            app.ViewPropertiesMenu.Text = 'View Properties';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dashboard(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end