classdef DistributedLoad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ApplyDistributedLoadsAndMomentsUIFigure  matlab.ui.Figure
        DistributedLoadsPanel           matlab.ui.container.Panel
        ValueLabel_2                    matlab.ui.control.Label
        DistributedMomentsLabel         matlab.ui.control.Label
        MomentappliedaroundZLRSAxisLabel  matlab.ui.control.Label
        MomentappliedaroundYLRSAxisLabel  matlab.ui.control.Label
        MomentappliedaroundXLRSAxisLabel  matlab.ui.control.Label
        ForceappliedalongZLRSAxisLabel  matlab.ui.control.Label
        ForceappliedalongYLRSAxisLabel  matlab.ui.control.Label
        ForceappliedalongXLRSAxisLabel  matlab.ui.control.Label
        Mz                              matlab.ui.control.EditField
        My                              matlab.ui.control.EditField
        Mx                              matlab.ui.control.EditField
        ValueLabel                      matlab.ui.control.Label
        DistributedForcesLabel          matlab.ui.control.Label
        Fz                              matlab.ui.control.EditField
        Fy                              matlab.ui.control.EditField
        Fx                              matlab.ui.control.EditField
        RegionIDLabel_3                 matlab.ui.control.Label
        REGIONID                        matlab.ui.control.EditField
        DoneButton_2                    matlab.ui.control.Button
        ApplyButton_2                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        AnalysisType = 'static-planeframe';
        RegionID;
        ParentApp; % Description
        LoadTag; 
        NumLines;
    end
    
    methods
        function initApp(app)

            switch app.AnalysisType
                case 'static-planeframe'
                    app.Fz.Enable = 'off';
                    app.Mx.Enable = 'off';
                    app.My.Enable = 'off';
            end

%             app.NumLines = app.ParentApp.MODEL.Geometry.NumLines;
        end

        function args = parseInputScheme(app)
            args = [];
            if ~isempty(app.Fx.Value)
                [~,f] = evalc(app.Fx.Value);
                args = {'XForce', f};
            end
            if ~isempty(app.Fy.Value)
                [~,f] = evalc(app.Fy.Value);
                args = [args, {'YForce', f}];
            end    
            if ~isempty(app.Fz.Value)
                [~,f] = evalc(app.Fz.Value);
                args = [args, {'ZForce', f}];
            end     
            if ~isempty(app.Mx.Value)
                [~,m] = evalc(app.Mx.Value);
                args = [args, {'XMoment', m}];
            end     
            if ~isempty(app.My.Value)
                [~,m] = evalc(app.My.Value);
                args = [args, {'YMoment', m}];
            end           
            if ~isempty(app.Mz.Value)
                [~,m] = evalc(app.Mz.Value);
                args = [args, {'ZMoment', m}];
            end             
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off
            Pos = app.ApplyDistributedLoadsAndMomentsUIFigure.Position;
            app.ApplyDistributedLoadsAndMomentsUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos); 
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.ApplyDistributedLoadsAndMomentsUIFigure.Icon = appIcon;                  
            
            if nargin == 2
                app.ParentApp = parentApp;
                app.AnalysisType = parentApp.MODEL.AnalysisType;
            end
            app.initApp();
            warning on
        end

        % Button pushed function: ApplyButton_2
        function ApplyButtonPushed(app, event)
           args = app.parseInputScheme();

           if isempty(args)
               return;
           end

            if isempty(app.REGIONID.Value)
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage('Invalid Region ID', style);
                return
            end

            [~,app.RegionID] = evalc(app.REGIONID.Value);

            if or(any(~isrounded(app.RegionID)), any(app.RegionID<=0))
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage('Region ID must be a rounded and positive value', style);
                return
            end

            structuralModel = app.ParentApp.MODEL;

            try
                for i=1:numel(app.RegionID)
                   saplab.applyDistributed(structuralModel,'Line',app.RegionID(i),args{:});
                   app.ParentApp.updateMessage(['Distributed Load/Moment applied successfully to line region with ID (' num2str(app.RegionID(i)) ')']); 
                end
                saplab.ui.updateLayersAnalysis(app.ParentApp);
            catch ME

                app.ParentApp.updateMessage(ME.message);                

            end


        end

        % Button pushed function: DoneButton_2
        function DoneButton_2Pushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ApplyDistributedLoadsAndMomentsUIFigure = uifigure('Visible', 'off');
            app.ApplyDistributedLoadsAndMomentsUIFigure.Name = 'Apply Distributed Loads And Moments';
            app.ApplyDistributedLoadsAndMomentsUIFigure.Icon = 'Application Icon.png';
            app.ApplyButton_2 = uibutton(app.ApplyDistributedLoadsAndMomentsUIFigure, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.DoneButton_2 = uibutton(app.ApplyDistributedLoadsAndMomentsUIFigure, 'push');
            app.DoneButton_2.ButtonPushedFcn = createCallbackFcn(app, @DoneButton_2Pushed, true);
            app.DoneButton_2.Text = 'Done';
            app.REGIONID = uieditfield(app.ApplyDistributedLoadsAndMomentsUIFigure, 'text');
            app.REGIONID.Tooltip = {''};
            app.RegionIDLabel_3 = uilabel(app.ApplyDistributedLoadsAndMomentsUIFigure);
            app.RegionIDLabel_3.Text = 'Region ID';
            app.DistributedLoadsPanel = uipanel(app.ApplyDistributedLoadsAndMomentsUIFigure);
            app.DistributedLoadsPanel.Title = 'Distributed Loads';
            app.Fx = uieditfield(app.DistributedLoadsPanel, 'text');
            app.Fx.Tooltip = {''};
            app.Fy = uieditfield(app.DistributedLoadsPanel, 'text');
            app.Fy.Tooltip = {''};
            app.Fz = uieditfield(app.DistributedLoadsPanel, 'text');
            app.Fz.Tooltip = {''};
            app.DistributedForcesLabel = uilabel(app.DistributedLoadsPanel);
            app.DistributedForcesLabel.Text = 'Distributed Forces';
            app.ValueLabel = uilabel(app.DistributedLoadsPanel);
            app.ValueLabel.Text = 'Value';
            app.Mx = uieditfield(app.DistributedLoadsPanel, 'text');
            app.Mx.Tooltip = {''};
            app.My = uieditfield(app.DistributedLoadsPanel, 'text');
            app.My.Tooltip = {''};
            app.Mz = uieditfield(app.DistributedLoadsPanel, 'text');
            app.Mz.Tooltip = {''};
            app.ForceappliedalongXLRSAxisLabel = uilabel(app.DistributedLoadsPanel);
            app.ForceappliedalongXLRSAxisLabel.Text = 'Force applied along X LRS Axis..........................................';
            app.ForceappliedalongYLRSAxisLabel = uilabel(app.DistributedLoadsPanel);
            app.ForceappliedalongYLRSAxisLabel.Text = 'Force applied along Y LRS Axis.........................................';
            app.ForceappliedalongZLRSAxisLabel = uilabel(app.DistributedLoadsPanel);
            app.ForceappliedalongZLRSAxisLabel.Text = 'Force applied along Z LRS Axis.........................................';
            app.MomentappliedaroundXLRSAxisLabel = uilabel(app.DistributedLoadsPanel);
            app.MomentappliedaroundXLRSAxisLabel.Text = 'Moment applied around X LRS Axis..................................';
            app.MomentappliedaroundYLRSAxisLabel = uilabel(app.DistributedLoadsPanel);
            app.MomentappliedaroundYLRSAxisLabel.Text = 'Moment applied around Y LRS Axis..................................';
            app.MomentappliedaroundZLRSAxisLabel = uilabel(app.DistributedLoadsPanel);
            app.MomentappliedaroundZLRSAxisLabel.Text = 'Moment applied around Z LRS Axis..................................';
            app.DistributedMomentsLabel = uilabel(app.DistributedLoadsPanel);
            app.DistributedMomentsLabel.Text = 'Distributed Moments';
            app.ValueLabel_2 = uilabel(app.DistributedLoadsPanel);
            app.ValueLabel_2.Text = 'Value';
            % Show the figure after all components are created
            app.ApplyDistributedLoadsAndMomentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DistributedLoad(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ApplyDistributedLoadsAndMomentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ApplyDistributedLoadsAndMomentsUIFigure)
        end
    end
end