classdef ForceAndMoment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FORCESANDMOMENTSUIFigure  matlab.ui.Figure
        PlotLabel                 matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        MZBendingMomentButton     matlab.ui.control.Button
        MYBendingMomentButton     matlab.ui.control.Button
        NXAxialForcesButton       matlab.ui.control.Button
        MXTorqueMomentButton      matlab.ui.control.Button
        FZShearForcesButton       matlab.ui.control.Button
        FYShearForcesButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        ParentApp Tesla.Dashboard; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            Pos = app.FORCESANDMOMENTSUIFigure.Position;
            app.FORCESANDMOMENTSUIFigure.Position = Tesla.internal.getNiceDialogLocation(Pos);             
            if (nargin == 2)
                app.ParentApp = parentApp;
            end
        end

        % Button pushed function: NXAxialForcesButton
        function NXAxialForcesButtonPushed(app, event)
            if ~contains(app.ParentApp.MODEL.AnalysisType, 'frame')
                updateMessage(app,[char(app.ParentApp.MODEL.AnalysisType), ' does not support Axial Forces Plotting.']);
                return;
            end
            app.ParentApp.PLOTDEFSHAPE = 'off';
            app.ParentApp.PLOTDISPLACEMENTS='off';
            app.ParentApp.FORCEANDMOMENT = 'NX';
            app.ParentApp.plot();
            title(app.ParentApp.UIAxes,'Plot: AXIAL FORCE NX','FontName','Monospac821 BT');
        end

        % Button pushed function: FYShearForcesButton
        function FYShearForcesButtonPushed(app, event)
            if ~contains(app.ParentApp.MODEL.AnalysisType, 'frame')
                updateMessage(app,[char(app.ParentApp.MODEL.AnalysisType), ' does not support Shear Forces Plotting.']);
                return;
            end
            app.ParentApp.PLOTDEFSHAPE = 'off';
            app.ParentApp.PLOTDISPLACEMENTS='off';
            app.ParentApp.FORCEANDMOMENT = 'FY';
            app.ParentApp.plot();
            title(app.ParentApp.UIAxes,'Plot: SHEAR FORCE FY','FontName','Monospac821 BT');            
        end

        % Button pushed function: FZShearForcesButton
        function FZShearForcesButtonPushed(app, event)
            if ~contains(app.ParentApp.MODEL.AnalysisType, 'frame')
                updateMessage(app,[char(app.ParentApp.MODEL.AnalysisType), ' does not support Shear Forces Plotting.']);
                return;
            end
            app.ParentApp.PLOTDEFSHAPE = 'off';
            app.ParentApp.PLOTDISPLACEMENTS='off';
            app.ParentApp.FORCEANDMOMENT = 'FZ';
            app.ParentApp.plot();
            title(app.ParentApp.UIAxes,'Plot: SHEAR FORCE FZ','FontName','Monospac821 BT');                        
        end

        % Button pushed function: MYBendingMomentButton
        function MYBendingMomentButtonPushed(app, event)
            if ~contains(app.ParentApp.MODEL.AnalysisType, 'frame')
                updateMessage(app,[char(app.ParentApp.MODEL.AnalysisType), ' does not support Shear Forces Plotting.']);
                return;
            end
            app.ParentApp.PLOTDEFSHAPE = 'off';
            app.ParentApp.PLOTDISPLACEMENTS='off';
            app.ParentApp.FORCEANDMOMENT = 'MY';
            app.ParentApp.plot();
            title(app.ParentApp.UIAxes,'Plot: BENDING MOMENT MY','FontName','Monospac821 BT');            
            
        end

        % Button pushed function: MZBendingMomentButton
        function MZBendingMomentButtonPushed(app, event)
            if ~contains(app.ParentApp.MODEL.AnalysisType, 'frame')
                updateMessage(app,[char(app.ParentApp.MODEL.AnalysisType), ' does not support Shear Forces Plotting.']);
                return;
            end
            app.ParentApp.PLOTDEFSHAPE = 'off';
            app.ParentApp.PLOTDISPLACEMENTS='off';
            app.ParentApp.FORCEANDMOMENT = 'MZ';
            app.ParentApp.plot();
            title(app.ParentApp.UIAxes,'Plot: BENDING MOMENT MZ','FontName','Monospac821 BT');            
            
        end

        % Button pushed function: MXTorqueMomentButton
        function MXTorqueMomentButtonPushed(app, event)
            if ~contains(app.ParentApp.MODEL.AnalysisType, 'frame')
                updateMessage(app,[char(app.ParentApp.MODEL.AnalysisType), ' does not support Shear Forces Plotting.']);
                return;
            end
            app.ParentApp.PLOTDEFSHAPE = 'off';
            app.ParentApp.PLOTDISPLACEMENTS='off';
            app.ParentApp.FORCEANDMOMENT = 'MX';
            app.ParentApp.plot();
            title(app.ParentApp.UIAxes,'Plot: TORQUE MOMENT MX','FontName','Monospac821 BT');            
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FORCESANDMOMENTSUIFigure = uifigure('Visible', 'off');
            app.FORCESANDMOMENTSUIFigure.Name = 'FORCES AND MOMENTS';
            app.FORCESANDMOMENTSUIFigure.Icon = 'Application Icon.png';
            app.Panel = uipanel(app.FORCESANDMOMENTSUIFigure);
            app.FYShearForcesButton = uibutton(app.Panel, 'push');
            app.FYShearForcesButton.ButtonPushedFcn = createCallbackFcn(app, @FYShearForcesButtonPushed, true);
            app.FYShearForcesButton.Text = 'FY - Shear Forces';
            app.FZShearForcesButton = uibutton(app.Panel, 'push');
            app.FZShearForcesButton.ButtonPushedFcn = createCallbackFcn(app, @FZShearForcesButtonPushed, true);
            app.FZShearForcesButton.Text = 'FZ - Shear Forces';
            app.MXTorqueMomentButton = uibutton(app.Panel, 'push');
            app.MXTorqueMomentButton.ButtonPushedFcn = createCallbackFcn(app, @MXTorqueMomentButtonPushed, true);
            app.MXTorqueMomentButton.Text = 'MX - Torque Moment';
            app.NXAxialForcesButton = uibutton(app.Panel, 'push');
            app.NXAxialForcesButton.ButtonPushedFcn = createCallbackFcn(app, @NXAxialForcesButtonPushed, true);
            app.NXAxialForcesButton.Text = 'NX - Axial Forces';
            app.MYBendingMomentButton = uibutton(app.Panel, 'push');
            app.MYBendingMomentButton.ButtonPushedFcn = createCallbackFcn(app, @MYBendingMomentButtonPushed, true);
            app.MYBendingMomentButton.Text = 'MY - Bending Moment';
            app.MZBendingMomentButton = uibutton(app.Panel, 'push');
            app.MZBendingMomentButton.ButtonPushedFcn = createCallbackFcn(app, @MZBendingMomentButtonPushed, true);
            app.MZBendingMomentButton.Text = 'MZ - Bending Moment';
            app.PlotLabel = uilabel(app.FORCESANDMOMENTSUIFigure);
            app.PlotLabel.Text = 'Plot';
            % Show the figure after all components are created
            app.FORCESANDMOMENTSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForceAndMoment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FORCESANDMOMENTSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FORCESANDMOMENTSUIFigure)
        end
    end
end