classdef Import < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportdefaultgeometryUIFigure  matlab.ui.Figure
        GeometryTagLabel               matlab.ui.control.Label
        TAG                            matlab.ui.control.EditField
        ImportGeometryToTheModelPanel  matlab.ui.container.Panel
        ImportButton                   matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
        ListSelectorTwoPane            wt.ListSelectorTwoPane
    end

    
    properties (Access = private)
        ParentApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off
            app.ListSelectorTwoPane.Items=[];
            app.ListSelectorTwoPane.UserButtons.Enable = 'on';
            app.ListSelectorTwoPane.AllowDuplicates = 'off';
            Pos = app.ImportdefaultgeometryUIFigure.Position;
            app.ImportdefaultgeometryUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.ImportdefaultgeometryUIFigure.Icon = appIcon;   

            if nargin == 2
                app.ParentApp=parentApp;
                app.ListSelectorTwoPane.Items= app.ParentApp.MODEL.Geometry.csgdb.tag;
            end     
            warning on
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            tag = app.ListSelectorTwoPane.HighlightedValue;
            try
                app.ParentApp.MODEL.Geometry = tag;
            catch ME
                if ~isempty(ME.message)
                    app.ParentApp.updateMessage(ME.message,uistyle('FontColor','r'));
                    return;
                end
            end
            msg = strcat('Geometry with tag (', num2str(tag), ') imported successfully!');
            app.ParentApp.updateMessage(msg,uistyle('FontColor',[0,.5,0]));
            msg = strcat('Warning: Other geometries will be not selectable in "Display Geometry" drop down menu from this point on ');
            app.ParentApp.updateMessage(msg,uistyle('FontColor',[1,.5,.25]));
            
            
            app.ParentApp.CurrGeomDropDown.Items = {char(tag)};
            app.ParentApp.CurrGeomDropDown.Value = tag;
            app.ParentApp.plot()
            saplab.ui.updateLayersAnalysis(app.ParentApp)
            app.delete()
        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Callback function: ListSelectorTwoPane
        function ListSelectorTwoPaneButtonPushed(app, event)
            
        end

        % Callback function: ListSelectorTwoPane
        function ListSelectorTwoPaneValueChanged(app, event)
            value = app.ListSelectorTwoPane.Value;
            
            if size(value,2) >= 1
                app.ListSelectorTwoPane.UserButtons.Enable = 'off';
            else
                app.ListSelectorTwoPane.UserButtons.Enable = 'on';
            end
        end

        % Callback function: ListSelectorTwoPane
        function ListSelectorTwoPaneHighlightedValueChanged(app, event)

        end

        % Callback function
        function ButtonPushed2(app, event)
    
        end

        % Callback function: ListSelectorTwoPane
        function ListSelectorTwoPaneButtonDown(app, event)
           
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            t = app.ListSelectorTwoPane.HighlightedValue;
            app.ImportButton.Text = strcat('Import', " ", t);  
            app.ImportButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportdefaultgeometryUIFigure = uifigure('Visible', 'off');
            app.ImportdefaultgeometryUIFigure.Name = 'Import default geometry';
            app.ImportdefaultgeometryUIFigure.Icon = 'Application Icon.png';
            app.ImportGeometryToTheModelPanel = uipanel(app.ImportdefaultgeometryUIFigure);
            app.ImportGeometryToTheModelPanel.Title = 'Import Geometry To The Model';
            app.ListSelectorTwoPane = wt.ListSelectorTwoPane(app.ImportGeometryToTheModelPanel);
            app.ListSelectorTwoPane.ButtonPushedFcn = createCallbackFcn(app, @ListSelectorTwoPaneButtonPushed, true);
            app.ListSelectorTwoPane.ValueChangedFcn = createCallbackFcn(app, @ListSelectorTwoPaneValueChanged, true);
            app.ListSelectorTwoPane.HighlightedValueChangedFcn = createCallbackFcn(app, @ListSelectorTwoPaneHighlightedValueChanged, true);
            app.ListSelectorTwoPane.ButtonDownFcn = createCallbackFcn(app, @ListSelectorTwoPaneButtonDown, true);
            app.SelectButton = uibutton(app.ImportGeometryToTheModelPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ImportButton = uibutton(app.ImportGeometryToTheModelPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Enable = 'off';
            app.ImportButton.Text = 'Import';
            app.TAG = uieditfield(app.ImportdefaultgeometryUIFigure, 'text');
            app.TAG.Tooltip = {'Geometry TAG'};
            app.GeometryTagLabel = uilabel(app.ImportdefaultgeometryUIFigure);
            app.GeometryTagLabel.Text = 'Geometry Tag';
            % Show the figure after all components are created
            app.ImportdefaultgeometryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Import(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportdefaultgeometryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportdefaultgeometryUIFigure)
        end
    end
end