classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateSpringInterfaceUIFigure   matlab.ui.Figure
        ApplyButton                     matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        InterfacePropertiesPanel        matlab.ui.container.Panel
        HorizontalStiffnessLabel        matlab.ui.control.Label
        KN                              matlab.ui.control.EditField
        VerticalStiffnessLabel          matlab.ui.control.Label
        KT                              matlab.ui.control.EditField
        EdgeIDLabel                     matlab.ui.control.Label
        EDGEID                          matlab.ui.control.EditField
        DefineNonLinearPropertiesPanel  matlab.ui.container.Panel
        DefineModelButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        ParentApp; 
        MaterialModel = [];
        ChildrenApp;
    end

    methods
        function get_Model(app,M)
            app.MaterialModel = M;
            app.KT.Value = string(M.E);
            app.ParentApp.updateMessage('Material model created successfully.',uistyle("FontColor",[0,.5,0]))
        end

        function reset(app)
            app.EDGEID.Value = '';
            app.KT.Value = '';
            app.KV.Value = '';
            app.MaterialModel = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off
            Pos = app.CreateSpringInterfaceUIFigure.Position;
            app.CreateSpringInterfaceUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);    
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.CreateSpringInterfaceUIFigure.Icon = appIcon;    
            
            if nargin==2
                app.ParentApp = parentApp;
            end
            warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if isempty(app.MaterialModel)
                if isempty(app.EDGEID.Value)
                    msg = 'No edge selected.';
                    app.ParentApp.updateMessage(msg,uistyle("FontColor",'r')); return                    
                end
                [~,EdgeID] = evalc(app.EDGEID.Value);
                findRei = findStructuralReinforcement(app.ParentApp.MODEL.StructuralReinforcement,'Edge',EdgeID);
                if isempty(findRei)
                    msg = ['Edge with ID (' num2str(EdgeID) ') does not have FRP reinforcement'];
                    app.ParentApp.updateMessage(msg,uistyle("FontColor",'r')); return
                end
                if isempty(app.KT.Value)
                    msg = 'Tangential stiffness value is incorrect.';
                    app.ParentApp.updateMessage(msg,uistyle("FontColor",'r')); return                    
                end
                if isempty(app.KN.Value)
                    msg = 'Tangential stiffness value is incorrect.';
                    app.ParentApp.updateMessage(msg,uistyle("FontColor",'r')); return                    
                end

                [~,kn]=evalc(app.KN.Value);
                [~,kt]=evalc(app.KT.Value);

                try
                saplab.structuralFEI(app.ParentApp.MODEL,"Edge",EdgeID,"HorizontalStiffness",kt);
                saplab.structuralFEI(app.ParentApp.MODEL,"Edge",EdgeID,"VerticalStiffness",kn);
                msg = ['Interface added successfully at edge with ID (' num2str(EdgeID) ')'];
                app.ParentApp.updateMessage(msg,uistyle("FontColor",'k')); return 
                catch ME
                    app.ParentApp.updateMessage(ME.message,uistyle("FontColor",'r')); return   
                end

            else
                if isempty(app.EDGEID.Value)
                    msg = 'No edge selected.';
                    app.ParentApp.updateMessage(msg,uistyle("FontColor",'r')); return                    
                end
                [~,EdgeID] = evalc(app.EDGEID.Value);
                findRei = findStructuralReinforcement(app.ParentApp.MODEL.StructuralReinforcement,'Edge',EdgeID);
                if isempty(findRei)
                    msg = ['Edge with ID (' num2str(EdgeID) ') does not have FRP reinforcement'];
                    app.ParentApp.updateMessage(msg,uistyle("FontColor",'r')); return
                end
                if isempty(app.KT.Value)
                    msg = 'Tangential stiffness value is incorrect.';
                    app.ParentApp.updateMessage(msg,uistyle("FontColor",'r')); return                    
                end
                if isempty(app.KN.Value)
                    msg = 'Tangential stiffness value is incorrect.';
                    app.ParentApp.updateMessage(msg,uistyle("FontColor",'r')); return                    
                end

                [~,kn]=evalc(app.KN.Value);
                try
                structuralFEI(app.ParentApp.MODEL,"Edge",EdgeID,"Horizontalspring",app.MaterialModel.Model);
                structuralFEI(app.ParentApp.MODEL,"Edge",EdgeID,"Verticalspring",kn);
                msg = ['Non linear interface added successfully at edge with ID (' num2str(EdgeID) ')'];
                app.ParentApp.updateMessage(msg,uistyle("FontColor",'k')); 
                catch ME
                    app.ParentApp.updateMessage(ME.message,uistyle("FontColor",'r')); return   
                end
            end

            app.reset();
        end

        % Button pushed function: DefineModelButton
        function DefineModelButtonPushed(app, event)
            app.ChildrenApp = saplab.ui.MaterialModel(app);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateSpringInterfaceUIFigure = uifigure('Visible', 'off');
            app.CreateSpringInterfaceUIFigure.Name = 'Create Spring Interface';
            app.CreateSpringInterfaceUIFigure.Icon = 'Application Icon.png';
            app.DefineNonLinearPropertiesPanel = uipanel(app.CreateSpringInterfaceUIFigure);
            app.DefineNonLinearPropertiesPanel.Title = 'Define Non Linear Properties';
            app.DefineModelButton = uibutton(app.DefineNonLinearPropertiesPanel, 'push');
            app.DefineModelButton.ButtonPushedFcn = createCallbackFcn(app, @DefineModelButtonPushed, true);
            app.DefineModelButton.Icon = 'material01.png';
            app.DefineModelButton.Tooltip = {'Define Material Model. Material Model can only be applied to Horizontal Spring (Tangential Stiffness)'};
            app.DefineModelButton.Text = 'Define Model';
            app.InterfacePropertiesPanel = uipanel(app.CreateSpringInterfaceUIFigure);
            app.InterfacePropertiesPanel.Title = 'Interface Properties';
            app.EDGEID = uieditfield(app.InterfacePropertiesPanel, 'text');
            app.EDGEID.Tooltip = {'EDGE ID'};
            app.EdgeIDLabel = uilabel(app.InterfacePropertiesPanel);
            app.EdgeIDLabel.Text = 'Edge ID.........................................................';
            app.KT = uieditfield(app.InterfacePropertiesPanel, 'text');
            app.KT.Tooltip = {'TANGENTIAL STIFFNESS'};
            app.VerticalStiffnessLabel = uilabel(app.InterfacePropertiesPanel);
            app.VerticalStiffnessLabel.Text = 'Vertical Stiffness............................................';
            app.KN = uieditfield(app.InterfacePropertiesPanel, 'text');
            app.KN.Tooltip = {'NORMAL STIFFNESS'};
            app.HorizontalStiffnessLabel = uilabel(app.InterfacePropertiesPanel);
            app.HorizontalStiffnessLabel.Text = 'Horizontal Stiffness........................................';
            app.DoneButton = uibutton(app.CreateSpringInterfaceUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ApplyButton = uibutton(app.CreateSpringInterfaceUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.CreateSpringInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateSpringInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateSpringInterfaceUIFigure)
        end
    end
end