classdef MaterialModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DefinenonlinearmaterialpropertiesUIFigure  matlab.ui.Figure
        PlotButton                  matlab.ui.control.Button
        Panel_5                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        Panel_4                     matlab.ui.container.Panel
        flipcurveonnegativesideCheckBox  matlab.ui.control.CheckBox
        HelpButton                  matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        SetForceDisplacementfunctionLabel  matlab.ui.control.Label
        Panel_3                     matlab.ui.container.Panel
        FunctionintervaluLabel      matlab.ui.control.Label
        INTERVAL                    matlab.ui.control.EditField
        FunctionforcedataFuLabel    matlab.ui.control.Label
        FUNCTION                    matlab.ui.control.EditField
        SetForceDisplacementcurvepointsLabel  matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        DisplacementdataLabel       matlab.ui.control.Label
        DISPLACEMENT                matlab.ui.control.EditField
        ForcedataLabel              matlab.ui.control.Label
        FORCE                       matlab.ui.control.EditField
        GenerateMaterialModelLabel  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        MinimumtangentmodulusLabel  matlab.ui.control.Label
        MaximumtangentmodulusLabel  matlab.ui.control.Label
        KMIN                        matlab.ui.control.EditField
        MODULUSOFELASTICITY         matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp;
        MatModel; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            Pos = app.DefinenonlinearmaterialpropertiesUIFigure.Position;
            app.DefinenonlinearmaterialpropertiesUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);  
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.DefinenonlinearmaterialpropertiesUIFigure.Icon = appIcon;               
         
            
            if nargin == 2
                app.ParentApp = parentApp;
            end            
        end

        % Callback function
        function AddButtonPushed(app, event)
            app.UITable.Data = [app.UITable.Data; "",""];
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            if ~isempty(app.FORCE.Value) && ~isempty(app.DISPLACEMENT.Value)
                [~,F] = evalc(strcat('[',string(app.FORCE.Value),']'));
                [~,u] = evalc(strcat('[',string(app.DISPLACEMENT.Value),']'));
            elseif ~isempty(app.FUNCTION.Value) && ~isempty(app.INTERVAL.Value)
                [~,Func] = evalc(app.FUNCTION.Value);
                [~,interval] = evalc(strcat('[',string(app.INTERVAL.Value),']'));
                F = Func(interval);
                u = interval;
            else
                return;
            end
            
            if app.flipcurveonnegativesideCheckBox.Value
                uflip = -flip(u);
                Fflip = -flip(F);
                uflip(end) = [];
                Fflip(end) = [];
                u = [uflip, u];
                F = [Fflip, F];
            end

            plot(app.UIAxes,u,F,"Color",[0 0 1],'LineStyle','-','MarkerFaceColor',[0 0 1])

            matModel = saplab.defineMatModel('Force',F,'u',u);
            k = matModel.evalkateachstate(u,F);
            app.MODULUSOFELASTICITY.Value = string(max(k));
            app.KMIN.Value = string(min(k));

            app.MatModel.Model = matModel;
            app.MatModel.E = max(k);
            
        end

        % Value changed function: flipcurveonnegativesideCheckBox
        function flipcurveonnegativesideCheckBoxValueChanged(app, event)

        end

        % Callback function
        function SliderValueChanged(app, event)
 
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.ParentApp.get_Model(app.MatModel);
            app.delete();
        end

        % Callback function
        function HelpButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DefinenonlinearmaterialpropertiesUIFigure = uifigure('Visible', 'off');
            app.DefinenonlinearmaterialpropertiesUIFigure.Name = 'Define non linear material properties';
            app.DefinenonlinearmaterialpropertiesUIFigure.Icon = 'Application Icon.png';
            app.Panel = uipanel(app.DefinenonlinearmaterialpropertiesUIFigure);
            app.MODULUSOFELASTICITY = uieditfield(app.Panel, 'text');
            app.MODULUSOFELASTICITY.Editable = 'off';
            app.MODULUSOFELASTICITY.Tooltip = {'EDGE ID'};
            app.KMIN = uieditfield(app.Panel, 'text');
            app.KMIN.Editable = 'off';
            app.KMIN.Tooltip = {'EDGE ID'};
            app.MaximumtangentmodulusLabel = uilabel(app.Panel);
            app.MaximumtangentmodulusLabel.Text = 'Maximum tangent modulus.......................................';
            app.MinimumtangentmodulusLabel = uilabel(app.Panel);
            app.MinimumtangentmodulusLabel.Text = 'Minimum tangent modulus........................................';
            app.GenerateMaterialModelLabel = uilabel(app.DefinenonlinearmaterialpropertiesUIFigure);
            app.GenerateMaterialModelLabel.Text = 'Generate Material Model';
            app.Panel_2 = uipanel(app.DefinenonlinearmaterialpropertiesUIFigure);
            app.FORCE = uieditfield(app.Panel_2, 'text');
            app.FORCE.Tooltip = {'EDGE ID'};
            app.ForcedataLabel = uilabel(app.Panel_2);
            app.ForcedataLabel.Text = 'Force data ..........................................';
            app.DISPLACEMENT = uieditfield(app.Panel_2, 'text');
            app.DISPLACEMENT.Tooltip = {'EDGE ID'};
            app.DisplacementdataLabel = uilabel(app.Panel_2);
            app.DisplacementdataLabel.Text = 'Displacement data.............................';
            app.SetForceDisplacementcurvepointsLabel = uilabel(app.DefinenonlinearmaterialpropertiesUIFigure);
            app.SetForceDisplacementcurvepointsLabel.Text = 'Set Force-Displacement curve points';
            app.Panel_3 = uipanel(app.DefinenonlinearmaterialpropertiesUIFigure);
            app.FUNCTION = uieditfield(app.Panel_3, 'text');
            app.FUNCTION.Tooltip = {''};
            app.FunctionforcedataFuLabel = uilabel(app.Panel_3);
            app.FunctionforcedataFuLabel.Text = 'Function force data F(u)...................';
            app.INTERVAL = uieditfield(app.Panel_3, 'text');
            app.INTERVAL.Tooltip = {'EDGE ID'};
            app.FunctionintervaluLabel = uilabel(app.Panel_3);
            app.FunctionintervaluLabel.Text = 'Function interval u........................................................';
            app.SetForceDisplacementfunctionLabel = uilabel(app.DefinenonlinearmaterialpropertiesUIFigure);
            app.SetForceDisplacementfunctionLabel.Text = 'Set Force-Displacement function';
            app.ApplyButton = uibutton(app.DefinenonlinearmaterialpropertiesUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.HelpButton = uibutton(app.DefinenonlinearmaterialpropertiesUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.Panel_4 = uipanel(app.DefinenonlinearmaterialpropertiesUIFigure);
            app.flipcurveonnegativesideCheckBox = uicheckbox(app.Panel_4);
            app.flipcurveonnegativesideCheckBox.ValueChangedFcn = createCallbackFcn(app, @flipcurveonnegativesideCheckBoxValueChanged, true);
            app.flipcurveonnegativesideCheckBox.Text = 'flip curve on negative side';
            app.Panel_5 = uipanel(app.DefinenonlinearmaterialpropertiesUIFigure);
            app.UIAxes = uiaxes(app.Panel_5);
            xlabel(app.UIAxes, 'Displacement')
            ylabel(app.UIAxes, 'Force')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.DefinenonlinearmaterialpropertiesUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = 'material01.png';
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.DefinenonlinearmaterialpropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaterialModel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DefinenonlinearmaterialpropertiesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DefinenonlinearmaterialpropertiesUIFigure)
        end
    end
end