classdef MaterialProperties < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StructuralMaterialPropertiesUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        MATERIALPROPERTIESLabel     matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        DoneButton                  matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        PropertiesPanel             matlab.ui.container.Panel
        RegionIDLabel               matlab.ui.control.Label
        REGIONID                    matlab.ui.control.EditField
        InterfacemechanicalpropertiesNormalStiffnessKNLabel_3  matlab.ui.control.Label
        InterfacemechanicalpropertiesNormalStiffnessKNLabel_2  matlab.ui.control.Label
        PoissonsRatioOptionalLabel  matlab.ui.control.Label
        ModulusofElasticityLabel    matlab.ui.control.Label
        DescriptionLabel            matlab.ui.control.Label
        ValueLabel                  matlab.ui.control.Label
        CoefficientLabel            matlab.ui.control.Label
        CTE                         matlab.ui.control.EditField
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        MD                          matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
        NU                          matlab.ui.control.EditField
        ELabel                      matlab.ui.control.Label
        E                           matlab.ui.control.EditField
        SelectRegionButtonGroup     matlab.ui.container.ButtonGroup
        TypeofregionLabel           matlab.ui.control.Label
        ReinforcementButton         matlab.ui.control.RadioButton
        FaceButton                  matlab.ui.control.RadioButton
        StraightLineButton          matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        ParentApp; % Description
        RegionType;
        RegionID;
    end

    methods
        function set.RegionID(app, val)
            app.RegionID = val;
        end
        function reset(app)
            app.REGIONID.Value = '';
            app.E.Value = '';
            app.NU.Value = '';
            app.MD.Value = '';
            app.CTE.Value = '';
        end

        function [args, tf] = parseInputCoefficients(app)

            tf = false;

            if ~isempty(app.E.Value)
                [~,YMval] = evalc(app.E.Value);
                args = {'YoungsModulus', YMval};
            else
                app.ParentApp.updateMessage('Invalid definition of Modulus of elasticity.');
                tf = true; return;
            end
            if ~isempty(app.NU.Value)
                [~,PRval] = evalc(app.NU.Value);
                args = [args, {'PoissonsRatio', PRval}];
            end
            if ~isempty(app.MD.Value)
                [~,MDval] = evalc(app.MD.Value);
                args = [args, {'MassDensity', MDval}];
            end       
            if ~isempty(app.CTE.Value)
                [~,CTEval] = evalc(app.CTE.Value);
                args = [args, {'CTE', CTEval}];
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off
            Pos = app.StructuralMaterialPropertiesUIFigure.Position;
            app.StructuralMaterialPropertiesUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);  
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.StructuralMaterialPropertiesUIFigure.Icon = appIcon;                  
            if nargin == 2
                app.ParentApp=parentApp;
                if contains(app.ParentApp.MODEL.AnalysisType,'frame')
                    app.SelectRegionButtonGroup.SelectedObject = app.StraightLineButton;
                else
                    app.SelectRegionButtonGroup.SelectedObject = app.FaceButton;
                end

            end         
            warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            app.SelectRegionButtonGroupSelectionChanged();

            [args, tf] = parseInputCoefficients(app);
            if tf
                return;
            end


            if isempty(app.REGIONID.Value)
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage('Invalid Region ID', style);
                return
            end

            [~,app.RegionID] = evalc(app.REGIONID.Value);

            if or(any(~isrounded(app.RegionID)), any(app.RegionID<=0))
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage('Region ID must be a rounded and positive value', style);
                return
            end
            
            structuralModel = app.ParentApp.MODEL;
            try
                for i=1:app.RegionID
                   saplab.setMaterialProperties(structuralModel,app.RegionType,app.RegionID(i),args{:}) 
                end
            catch 

            end

            msg = ['Material properties set successfully to REGIONTYPE (' app.RegionType ') with REGIONID (' num2str(app.RegionID) ')'];
            app.ParentApp.updateMessage(msg);
            saplab.ui.updateLayersAnalysis(app.ParentApp)
            app.reset();
            

        end

        % Selection changed function: SelectRegionButtonGroup
        function SelectRegionButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectRegionButtonGroup.SelectedObject;
            if selectedButton == app.StraightLineButton
                app.RegionType = 'Line';
            elseif selectedButton == app.FaceButton
                app.RegionType = 'Face';
            elseif selectedButton == app.ReinforcementButton
                app.RegionType = 'Reinforcement';
            end
            
        end

        % Callback function
        function DefineModelButtonPushed(app, event)
            if contains(app.ParentApp.MODEL.AnalysisType,'frame')
                msg = 'Frame analysis does not support material model.';
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage(msg,style);
                return
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete()
        end

        % Callback function
        function ExploreDataButtonPushed(app, event)
            if app.GridLayout.RowHeight{5} == 0.1
                app.GridLayout.RowHeight{5} = 170;
                app.StructuralMaterialPropertiesUIFigure.Position(4) = app.StructuralMaterialPropertiesUIFigure.Position(4)+170;
            Pos = app.StructuralMaterialPropertiesUIFigure.Position;
            app.StructuralMaterialPropertiesUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);            
            
            else
                app.GridLayout.RowHeight{5} = 0.1;
                app.StructuralMaterialPropertiesUIFigure.Position(4) = app.StructuralMaterialPropertiesUIFigure.Position(4)-170;
                Pos = app.StructuralMaterialPropertiesUIFigure.Position;
                app.StructuralMaterialPropertiesUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos); 

            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StructuralMaterialPropertiesUIFigure = uifigure('Visible', 'off');
            app.StructuralMaterialPropertiesUIFigure.Name = 'Structural Material Properties';
            app.StructuralMaterialPropertiesUIFigure.Icon = 'Application Icon.png';
            app.GridLayout = uigridlayout(app.StructuralMaterialPropertiesUIFigure);
            app.SelectRegionButtonGroup = uibuttongroup(app.GridLayout);
            app.SelectRegionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectRegionButtonGroupSelectionChanged, true);
            app.SelectRegionButtonGroup.Title = 'Select Region ';
            app.StraightLineButton = uiradiobutton(app.SelectRegionButtonGroup);
            app.StraightLineButton.Tooltip = {'Analyze frames in XY plane'};
            app.StraightLineButton.Text = 'Straight Line';
            app.StraightLineButton.Value = true;
            app.FaceButton = uiradiobutton(app.SelectRegionButtonGroup);
            app.FaceButton.Tooltip = {'Analyize frame in XYZ space'};
            app.FaceButton.Text = 'Face';
            app.ReinforcementButton = uiradiobutton(app.SelectRegionButtonGroup);
            app.ReinforcementButton.Tooltip = {'Analyze plane stress elements in XY plane '};
            app.ReinforcementButton.Text = 'Reinforcement';
            app.TypeofregionLabel = uilabel(app.SelectRegionButtonGroup);
            app.TypeofregionLabel.Text = 'Type of region:';
            app.PropertiesPanel = uipanel(app.GridLayout);
            app.PropertiesPanel.Title = 'Properties';
            app.E = uieditfield(app.PropertiesPanel, 'text');
            app.E.Tooltip = {''};
            app.ELabel = uilabel(app.PropertiesPanel);
            app.ELabel.Text = 'E';
            app.NU = uieditfield(app.PropertiesPanel, 'text');
            app.NU.Tooltip = {'MUST BE IN RANGE 0; +0.5'};
            app.Label = uilabel(app.PropertiesPanel);
            app.Label.Text = 'ν';
            app.MD = uieditfield(app.PropertiesPanel, 'text');
            app.MD.Tooltip = {'MUST BE IN RANGE 0; +Inf'};
            app.Label_2 = uilabel(app.PropertiesPanel);
            app.Label_2.Text = 'γ';
            app.Label_3 = uilabel(app.PropertiesPanel);
            app.Label_3.Text = 'α';
            app.CTE = uieditfield(app.PropertiesPanel, 'text');
            app.CTE.Tooltip = {'MUST BE POSITIVE'};
            app.CoefficientLabel = uilabel(app.PropertiesPanel);
            app.CoefficientLabel.Text = 'Coefficient';
            app.ValueLabel = uilabel(app.PropertiesPanel);
            app.ValueLabel.Text = 'Value';
            app.DescriptionLabel = uilabel(app.PropertiesPanel);
            app.DescriptionLabel.Text = 'Description';
            app.ModulusofElasticityLabel = uilabel(app.PropertiesPanel);
            app.ModulusofElasticityLabel.Text = 'Modulus of Elasticity';
            app.PoissonsRatioOptionalLabel = uilabel(app.PropertiesPanel);
            app.PoissonsRatioOptionalLabel.Text = 'Poisson''s Ratio (Optional)';
            app.InterfacemechanicalpropertiesNormalStiffnessKNLabel_2 = uilabel(app.PropertiesPanel);
            app.InterfacemechanicalpropertiesNormalStiffnessKNLabel_2.Text = 'Mass Density (Optional)';
            app.InterfacemechanicalpropertiesNormalStiffnessKNLabel_3 = uilabel(app.PropertiesPanel);
            app.InterfacemechanicalpropertiesNormalStiffnessKNLabel_3.Text = 'Coefficient of Thermal Expansion (Optional)';
            app.REGIONID = uieditfield(app.PropertiesPanel, 'text');
            app.REGIONID.Tooltip = {''};
            app.RegionIDLabel = uilabel(app.PropertiesPanel);
            app.RegionIDLabel.Text = 'Region ID';
            app.Panel = uipanel(app.GridLayout);
            app.ApplyButton = uibutton(app.Panel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.Panel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.MATERIALPROPERTIESLabel = uilabel(app.GridLayout);
            app.MATERIALPROPERTIESLabel.Text = 'MATERIAL PROPERTIES';
            % Show the figure after all components are created
            app.StructuralMaterialPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaterialProperties(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StructuralMaterialPropertiesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StructuralMaterialPropertiesUIFigure)
        end
    end
end