classdef Mesh1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GenerateMeshForLineRegionsUIFigure  matlab.ui.Figure
        Label                      matlab.ui.control.Label
        IfhisscalarthenthesizeisappliedtoeachlineregioninthemodelLabel  matlab.ui.control.Label
        FiniteElementSizehLabel    matlab.ui.control.Label
        HelpButton                 matlab.ui.control.Button
        DoneButton                 matlab.ui.control.Button
        ApplyButton                matlab.ui.control.Button
        MeshParametersButtonGroup  matlab.ui.container.ButtonGroup
        ElementSizeLabel           matlab.ui.control.Label
        h                          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
           warning off
           Pos = app.GenerateMeshForLineRegionsUIFigure.Position;
           app.GenerateMeshForLineRegionsUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);                
           appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
           app.GenerateMeshForLineRegionsUIFigure.Icon = appIcon;                                         
           if   nargin == 2
                app.ParentApp = parentApp;
           end
           warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            try
                [~,H0] = evalc(app.h.Value);
                
                isvalidh = true;

                if ~isnumeric(H0)
                   isvalidh=false;
                end

                if isscalar(H0)
                    H0 = repmat(H0,1,app.ParentApp.MODEL.Geometry.NumLines);
                end

                if ~isscalar(H0)
                    if isnumeric(H0) && (~isvector(H0) || numel(H0) ~= app.ParentApp.MODEL.Geometry.NumLines || any(H0<=0))
                        isvalidh=false;
                    end
                end

                if ~isvalidh
                    app.ParentApp.updateMessage('Invalid finite element size', uistyle('FontColor','r'));
                    return;
                end

                nl = app.ParentApp.MODEL.Geometry.NumLines;
                saplab.mesh(app.ParentApp.MODEL.Geometry,'Line',1:nl,'ElementSize',H0);

                msh = app.ParentApp.MODEL.Mesh;
                MSG = ['Mesh generated successfully! Number of nodes: ', num2str(size(msh.Nodes,2)), ' Number of elements: ', num2str(size(msh.Elements,2))];
                app.ParentApp.updateMessage(MSG,uistyle('FontColor',[0 .5 0]));

                
            catch ME
                app.ParentApp.updateMessage(ME.message,uistyle('FontColor','r'))
            end

            saplab.ui.updateLayersAnalysis(app.ParentApp);
            app.delete();
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GenerateMeshForLineRegionsUIFigure = uifigure('Visible', 'off');
            app.GenerateMeshForLineRegionsUIFigure.Name = 'Generate Mesh For Line Regions';
            app.GenerateMeshForLineRegionsUIFigure.Icon = 'Application Icon.png';
            app.MeshParametersButtonGroup = uibuttongroup(app.GenerateMeshForLineRegionsUIFigure);
            app.MeshParametersButtonGroup.Title = 'Mesh Parameters';
            app.h = uieditfield(app.MeshParametersButtonGroup, 'text');
            app.h.Tooltip = {'POINT/VERTEX ID'};
            app.ElementSizeLabel = uilabel(app.MeshParametersButtonGroup);
            app.ElementSizeLabel.Text = 'Element Size...............................................................';
            app.ApplyButton = uibutton(app.GenerateMeshForLineRegionsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.GenerateMeshForLineRegionsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.HelpButton = uibutton(app.GenerateMeshForLineRegionsUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.FiniteElementSizehLabel = uilabel(app.GenerateMeshForLineRegionsUIFigure);
            app.FiniteElementSizehLabel.Text = '- Finite Element Size (h)';
            app.IfhisscalarthenthesizeisappliedtoeachlineregioninthemodelLabel = uilabel(app.GenerateMeshForLineRegionsUIFigure);
            app.IfhisscalarthenthesizeisappliedtoeachlineregioninthemodelLabel.Text = 'If h is scalar then the size is applied  to each line region in the model';
            app.Label = uilabel(app.GenerateMeshForLineRegionsUIFigure);
            app.Label.Text = 'If h is a vector then each element in the vector represents the size of a specific line region. The number of the element in the vector must be equal to the number of lines in the model';
            % Show the figure after all components are created
            app.GenerateMeshForLineRegionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mesh1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GenerateMeshForLineRegionsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.GenerateMeshForLineRegionsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GenerateMeshForLineRegionsUIFigure)
        end
    end
end