classdef Mesh2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneratetriangularmeshUIFigure  matlab.ui.Figure
        DoneButton                      matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        MeshrefinementButtonGroup       matlab.ui.container.ButtonGroup
        RefineelementsinvertexregionsHvertexOptionalLabel_2  matlab.ui.control.Label
        RefineelementsinedgeregionsHedgeOptionalLabel_2  matlab.ui.control.Label
        RefineelementsinfaceregionsHfaceOptionalLabel_2  matlab.ui.control.Label
        Hvertex                         matlab.ui.control.EditField
        Hedge                           matlab.ui.control.EditField
        Hface                           matlab.ui.control.EditField
        MeshDefaultParametersButtonGroup  matlab.ui.container.ButtonGroup
        MaximumelementedgelengthHmaxLabel  matlab.ui.control.Label
        MinimumelementedgelengthHminLabel  matlab.ui.control.Label
        Hmax                            matlab.ui.control.EditField
        Hmin                            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp % Description
    end
    
    methods
        function args = getArgs(app)
            
            args = [];
            if ~isempty(app.Hmin.Value)
                [~,HminVal] = evalc(app.Hmin.Value);
                args = [args {'Hmin', HminVal}];
            end
            if ~isempty(app.Hmax.Value)
                [~,HmaxVal] = evalc(app.Hmax.Value);
                args = [args {'Hmax', HmaxVal}];
            end
            
            if ~isempty(app.Hface.Value)
                [~,HfaceVal] = evalc(app.Hface.Value);
                args = [args {'Hface', HfaceVal}];
            end
            if ~isempty(app.Hedge.Value)
                [~,HedgeVal] = evalc(app.Hedge.Value);
                args = [args {'Hedge', HedgeVal}];
            end
            if ~isempty(app.Hvertex.Value)
                [~,HvertVal] = evalc(app.Hvertex.Value);
                args = [args {'Hvertex', HvertVal}];
            end

        end

        function reset(app)
            app.Hedge.Value = '';
            app.Hface.Value = '';
            app.Hvertex.Value = '';
            app.Hmin.Value = '';
            app.Hmax.Value = '';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            Pos = app.GeneratetriangularmeshUIFigure.Position;
            app.GeneratetriangularmeshUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);  
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.GeneratetriangularmeshUIFigure.Icon = appIcon;                 

            if nargin == 2
                app.ParentApp = parentApp;
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            try
                args = app.getArgs();
                args{:}
                if isempty(args)
                    saplab.mesh(app.ParentApp.MODEL.Geometry);
                else
                    saplab.mesh(app.ParentApp.MODEL.Geometry,args{:});
                end
                app.reset();
                msh = app.ParentApp.MODEL.Mesh;
                MSG = ['Mesh generated successfully! Number of nodes: ', num2str(size(msh.Nodes,2)), ' Number of elements: ', num2str(size(msh.Elements,2))];
                app.ParentApp.updateMessage(MSG,uistyle('FontColor',[0 .5 0]));
            catch ME
                app.ParentApp.updateMessage(ME.message,uistyle('FontColor','r'))
            end

            saplab.ui.updateLayersAnalysis(app.ParentApp);

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeneratetriangularmeshUIFigure = uifigure('Visible', 'off');
            app.GeneratetriangularmeshUIFigure.Name = 'Generate triangular mesh';
            app.GeneratetriangularmeshUIFigure.Icon = 'Application Icon.png';
            app.MeshDefaultParametersButtonGroup = uibuttongroup(app.GeneratetriangularmeshUIFigure);
            app.MeshDefaultParametersButtonGroup.Tooltip = {''};
            app.MeshDefaultParametersButtonGroup.Title = 'Mesh Default Parameters';
            app.Hmin = uieditfield(app.MeshDefaultParametersButtonGroup, 'text');
            app.Hmin.Tooltip = {''};
            app.Hmax = uieditfield(app.MeshDefaultParametersButtonGroup, 'text');
            app.Hmax.Tooltip = {''};
            app.MinimumelementedgelengthHminLabel = uilabel(app.MeshDefaultParametersButtonGroup);
            app.MinimumelementedgelengthHminLabel.Text = ' Minimum element edge length (Hmin)....................................';
            app.MaximumelementedgelengthHmaxLabel = uilabel(app.MeshDefaultParametersButtonGroup);
            app.MaximumelementedgelengthHmaxLabel.Text = 'Maximum element edge length (Hmax)..................................';
            app.MeshrefinementButtonGroup = uibuttongroup(app.GeneratetriangularmeshUIFigure);
            app.MeshrefinementButtonGroup.Title = 'Mesh refinement';
            app.Hface = uieditfield(app.MeshrefinementButtonGroup, 'text');
            app.Hface.Tooltip = {''};
            app.Hedge = uieditfield(app.MeshrefinementButtonGroup, 'text');
            app.Hedge.Tooltip = {''};
            app.Hvertex = uieditfield(app.MeshrefinementButtonGroup, 'text');
            app.Hvertex.Tooltip = {''};
            app.RefineelementsinfaceregionsHfaceOptionalLabel_2 = uilabel(app.MeshrefinementButtonGroup);
            app.RefineelementsinfaceregionsHfaceOptionalLabel_2.Text = 'Refine elements in face regions (Hface - Optional).................';
            app.RefineelementsinedgeregionsHedgeOptionalLabel_2 = uilabel(app.MeshrefinementButtonGroup);
            app.RefineelementsinedgeregionsHedgeOptionalLabel_2.Text = 'Refine elements in edge regions (Hedge - Optional)...............';
            app.RefineelementsinvertexregionsHvertexOptionalLabel_2 = uilabel(app.MeshrefinementButtonGroup);
            app.RefineelementsinvertexregionsHvertexOptionalLabel_2.Text = 'Refine elements in vertex regions (Hvertex - Optional)...........';
            app.ApplyButton = uibutton(app.GeneratetriangularmeshUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.GeneratetriangularmeshUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.GeneratetriangularmeshUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mesh2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneratetriangularmeshUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneratetriangularmeshUIFigure)
        end
    end
end