classdef Reinforcement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateStructuralReinforcementUIFigure  matlab.ui.Figure
        DoneButton      matlab.ui.control.Button
        ApplyButton     matlab.ui.control.Button
        StructuralReinforcementDataPanel  matlab.ui.container.Panel
        ThicknessLabel  matlab.ui.control.Label
        THICKNESS       matlab.ui.control.EditField
        EdgeIDLabel     matlab.ui.control.Label
        EDGEID          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
           warning off
           Pos = app.CreateStructuralReinforcementUIFigure.Position;
           app.CreateStructuralReinforcementUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);             
           appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
           app.CreateStructuralReinforcementUIFigure.Icon = appIcon;             
            if nargin == 2
                app.ParentApp = parentApp;
            end
            warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
           if isempty(app.EDGEID.Value)
               msg = 'Invalid Edge ID.';
               app.ParentApp.updateMessage(msg,uistyle('FontColor','r'));
               return
           end

           [~,edgeID] = evalc(app.EDGEID.Value);
           if ~isrounded(edgeID) || edgeID<=0
               msg = 'Edge ID must be a numeric rounded, positive scalar value';
               app.ParentApp.updateMessage(msg,uistyle('FontColor','r'));
               return               
           end

           if edgeID > size(app.ParentApp.MODEL.Geometry.geom,2)
               msg = ['Edge ID must not exceed (' num2str(size(app.ParentApp.MODEL.Geometry.geom,2)) ')'];
               app.ParentApp.updateMessage(msg,uistyle('FontColor','r'));
               return                              
           end

           if isempty(app.THICKNESS.Value)
               msg = 'Invalid thickness value.';
               app.ParentApp.updateMessage(msg,uistyle('FontColor','r'));
               return
           end         

           [~,thickness] = evalc(app.THICKNESS.Value); 
           if thickness <= 0
               msg = 'Thickness must be positive.';
               app.ParentApp.updateMessage(msg,uistyle('FontColor','r'));
               return
           end

           try
               saplab.structuralREI(app.ParentApp.MODEL,"Edge",edgeID,"Thickness",thickness);
           catch ME
               msg = ME.message;
               app.ParentApp.updateMessage(msg,uistyle('FontColor','r'));
               return
           end
            style = uistyle("FontColor",'k');
            msg = ['FRP Reinforcement applied successfully at Edge ID (' num2str(edgeID) '). Thickness value = ', num2str(thickness)];
            app.ParentApp.updateMessage(msg,style);
            app.ParentApp.plot2(app.ParentApp.MODEL.Geometry);
            if isempty(app.ParentApp.MODEL.StructuralFEInterface)
                style = uistyle("FontColor",[1 .5 .25]);
                msg = 'Warning: Structural reinforcements must be connected with the beam edge. To make connection press "Interface" button';
                app.ParentApp.updateMessage(msg,style);      
            else
                find = false;
                for i = 1:numel(app.ParentApp.MODEL.StructuralFEInterface.FEInterfaceAssignments)
                    fei = app.ParentApp.MODEL.StructuralFEInterface.FEInterfaceAssignments(i);
                    if strcmpi(fei.RegionType,'Edge') && fei.RegionID == edgeID
                        find = true;
                        break;
                    end
                end

                if ~find
                style = uistyle("FontColor",[1 .5 .25]);
                msg = ['Warning: Structural reinforcement attached to edge ID (' num2str(edgeID) ' needs to be connected with the beam edge'];
                app.ParentApp.updateMessage(msg,style);      
                return                    
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateStructuralReinforcementUIFigure = uifigure('Visible', 'off');
            app.CreateStructuralReinforcementUIFigure.Name = 'Create Structural Reinforcement';
            app.CreateStructuralReinforcementUIFigure.Icon = 'Application Icon.png';
            app.StructuralReinforcementDataPanel = uipanel(app.CreateStructuralReinforcementUIFigure);
            app.StructuralReinforcementDataPanel.Title = 'Structural Reinforcement Data';
            app.EDGEID = uieditfield(app.StructuralReinforcementDataPanel, 'text');
            app.EDGEID.Tooltip = {'EDGE ID'};
            app.EdgeIDLabel = uilabel(app.StructuralReinforcementDataPanel);
            app.EdgeIDLabel.Text = 'Edge ID';
            app.THICKNESS = uieditfield(app.StructuralReinforcementDataPanel, 'text');
            app.THICKNESS.Tooltip = {'X,Y Circle Center Coordinates'};
            app.ThicknessLabel = uilabel(app.StructuralReinforcementDataPanel);
            app.ThicknessLabel.Text = 'Thickness';
            app.ApplyButton = uibutton(app.CreateStructuralReinforcementUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.CreateStructuralReinforcementUIFigure, 'push');
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.CreateStructuralReinforcementUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reinforcement(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateStructuralReinforcementUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateStructuralReinforcementUIFigure)
        end
    end
end