classdef Release < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetReleaseConditionUIFigure  matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        RegionIDLabel                matlab.ui.control.Label
        REGIONID                     matlab.ui.control.EditField
        DoneButton                   matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        ReleaseOptionsButtonGroup    matlab.ui.container.ButtonGroup
        ReleasebendingmomentMzLabel  matlab.ui.control.Label
        ReleasebendingmomentMyLabel  matlab.ui.control.Label
        ReleasetorquemomentMxLabel   matlab.ui.control.Label
        ReleaseshearforcesTzLabel    matlab.ui.control.Label
        ReleaseshearforcesTyLabel    matlab.ui.control.Label
        ReleaseaxialforcesLabel      matlab.ui.control.Label
        MZCheckBox                   matlab.ui.control.CheckBox
        MYCheckBox                   matlab.ui.control.CheckBox
        MXCheckBox                   matlab.ui.control.CheckBox
        TZCheckBox                   matlab.ui.control.CheckBox
        TYCheckBox                   matlab.ui.control.CheckBox
        NXCheckBox                   matlab.ui.control.CheckBox
        ReleaseLocationButtonGroup   matlab.ui.container.ButtonGroup
        AtbeamendpointButton         matlab.ui.control.RadioButton
        AtbeamstartpointButton       matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        AnalysisType = 'static-planeframe';
        ParentApp; % Description
        ReleaseTag;
        NumLines;
        RegionID;
        Location = 'start';
    end
    
    methods (Access = public)
        
        function initApp(app)
            app.NXCheckBox.UserData = 'nx';
            app.TYCheckBox.UserData = 'ty';
            app.TZCheckBox.UserData = 'tz';
            app.MXCheckBox.UserData = 'mx';
            app.MYCheckBox.UserData = 'my';
            app.MZCheckBox.UserData = 'mz';
            
            switch app.AnalysisType
                case 'static-planeframe'
                    app.TZCheckBox.Enable = 'off';
                    app.MXCheckBox.Enable = 'off';
                    app.MYCheckBox.Enable = 'off';
                case 'static-spaceframe'
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            Pos = app.SetReleaseConditionUIFigure.Position;
            app.SetReleaseConditionUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);     
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.SetReleaseConditionUIFigure.Icon = appIcon;                  
            if nargin == 2
                app.NumLines = parentApp.MODEL.Geometry.NumLines;
                app.ParentApp = parentApp;
            end
            app.initApp()
        end

        % Value changed function: NXCheckBox
        function NXCheckBoxValueChanged(app, event)
            value = app.NXCheckBox.Value;
            if value
                app.TYCheckBox.Value = false;
                app.TZCheckBox.Value = false;
                app.MXCheckBox.Value = false;
                app.MYCheckBox.Value = false;
                app.MZCheckBox.Value = false;
            end
            app.ReleaseTag = app.NXCheckBox.UserData;
        end

        % Value changed function: TYCheckBox
        function TYCheckBoxValueChanged(app, event)
            value = app.TYCheckBox.Value;
            if value
                app.NXCheckBox.Value = false;
                app.TZCheckBox.Value = false;
                app.MXCheckBox.Value = false;
                app.MYCheckBox.Value = false;
                app.MZCheckBox.Value = false;
            end         
            app.ReleaseTag = app.TYCheckBox.UserData;
        end

        % Value changed function: TZCheckBox
        function TZCheckBoxValueChanged(app, event)
            value = app.TZCheckBox.Value;
            if value
                app.NXCheckBox.Value = false;
                app.TYCheckBox.Value = false;
                app.MXCheckBox.Value = false;
                app.MYCheckBox.Value = false;
                app.MZCheckBox.Value = false;
            end                
            app.ReleaseTag = app.TZCheckBox.UserData;
        end

        % Value changed function: MXCheckBox
        function MXCheckBoxValueChanged(app, event)
            value = app.MXCheckBox.Value;
            if value
                app.NXCheckBox.Value = false;
                app.TYCheckBox.Value = false;
                app.TZCheckBox.Value = false;
                app.MYCheckBox.Value = false;
                app.MZCheckBox.Value = false;
            end                
            app.ReleaseTag = app.MXCheckBox.UserData;
        end

        % Value changed function: MYCheckBox
        function MYCheckBoxValueChanged(app, event)
            value = app.MYCheckBox.Value;
            if value
                app.NXCheckBox.Value = false;
                app.TYCheckBox.Value = false;
                app.TZCheckBox.Value = false;
                app.MXCheckBox.Value = false;
                app.MZCheckBox.Value = false;
            end   
            app.ReleaseTag = app.MYCheckBox.UserData;
        end

        % Value changed function: MZCheckBox
        function MZCheckBoxValueChanged(app, event)
            value = app.MZCheckBox.Value;
            if value
                app.NXCheckBox.Value = false;
                app.TYCheckBox.Value = false;
                app.TZCheckBox.Value = false;
                app.MXCheckBox.Value = false;
                app.MYCheckBox.Value = false;
            end           
            app.ReleaseTag = app.MZCheckBox.UserData;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            try
                if isempty(app.REGIONID.Value)
                    style = uistyle('FontColor','r');
                    app.ParentApp.updateMessage('Invalid Region ID', style);
                    return
                end

                [~,app.RegionID] = evalc(app.REGIONID.Value);
    
                if ~isrounded(app.RegionID) || app.RegionID<=0
                    style = uistyle('FontColor','r');
                    app.ParentApp.updateMessage('Region ID must be a rounded and positive value', style);
                    return
                end

                MSG = [];
                if app.RegionID > app.NumLines
                    MSG = ['Line ID must not exceed (' num2str(app.NumLines) ')'];
                end
                app.NumLines
                if ~isempty(MSG)
                    app.ParentApp.updateMessage(MSG,uistyle('FontColor','r'));
                    return;
                end

                rt = app.ReleaseTag;

                if isempty(rt)
                    MSG = 'Release condition not defined.';
                    app.ParentApp.updateMessage(MSG,uistyle('FontColor','r'));
                    return;
                end

                saplab.setReleaseCondition(app.ParentApp.MODEL,"Line",app.RegionID,"Location",app.Location,"Type",rt);
                
            catch ME
                app.ParentApp.updateMessage(ME.message,uistyle("FontColor",'r')); return
            end
            msg = strcat('Release condition "', string(upper(rt)), '" has been applied to REGIONTYPE (Line) with ID (', num2str(app.RegionID), ') at (', string(app.Location), ') location.');
            app.ParentApp.updateMessage(msg)
            app.ParentApp.plot();
        end

        % Selection changed function: ReleaseLocationButtonGroup
        function ReleaseLocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ReleaseLocationButtonGroup.SelectedObject;
            if selectedButton == app.AtbeamstartpointButton
                app.Location = 'start';
            else
                app.Location = 'end';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetReleaseConditionUIFigure = uifigure('Visible', 'off');
            app.SetReleaseConditionUIFigure.Name = 'Set Release Condition';
            app.ReleaseLocationButtonGroup = uibuttongroup(app.SetReleaseConditionUIFigure);
            app.ReleaseLocationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReleaseLocationButtonGroupSelectionChanged, true);
            app.ReleaseLocationButtonGroup.Title = 'Release Location';
            app.AtbeamstartpointButton = uiradiobutton(app.ReleaseLocationButtonGroup);
            app.AtbeamstartpointButton.Text = 'At beam start point';
            app.AtbeamstartpointButton.Value = true;
            app.AtbeamendpointButton = uiradiobutton(app.ReleaseLocationButtonGroup);
            app.AtbeamendpointButton.Text = 'At beam end point';
            app.ReleaseOptionsButtonGroup = uibuttongroup(app.SetReleaseConditionUIFigure);
            app.ReleaseOptionsButtonGroup.Title = 'Release Options';
            app.NXCheckBox = uicheckbox(app.ReleaseOptionsButtonGroup);
            app.NXCheckBox.ValueChangedFcn = createCallbackFcn(app, @NXCheckBoxValueChanged, true);
            app.NXCheckBox.Text = ' NX';
            app.TYCheckBox = uicheckbox(app.ReleaseOptionsButtonGroup);
            app.TYCheckBox.ValueChangedFcn = createCallbackFcn(app, @TYCheckBoxValueChanged, true);
            app.TYCheckBox.Text = ' TY';
            app.TZCheckBox = uicheckbox(app.ReleaseOptionsButtonGroup);
            app.TZCheckBox.ValueChangedFcn = createCallbackFcn(app, @TZCheckBoxValueChanged, true);
            app.TZCheckBox.Text = ' TZ';
            app.MXCheckBox = uicheckbox(app.ReleaseOptionsButtonGroup);
            app.MXCheckBox.ValueChangedFcn = createCallbackFcn(app, @MXCheckBoxValueChanged, true);
            app.MXCheckBox.Text = ' MX';
            app.MYCheckBox = uicheckbox(app.ReleaseOptionsButtonGroup);
            app.MYCheckBox.ValueChangedFcn = createCallbackFcn(app, @MYCheckBoxValueChanged, true);
            app.MYCheckBox.Text = ' MY';
            app.MZCheckBox = uicheckbox(app.ReleaseOptionsButtonGroup);
            app.MZCheckBox.ValueChangedFcn = createCallbackFcn(app, @MZCheckBoxValueChanged, true);
            app.MZCheckBox.Text = ' MZ';
            app.ReleaseaxialforcesLabel = uilabel(app.ReleaseOptionsButtonGroup);
            app.ReleaseaxialforcesLabel.Text = 'Release axial-forces ....................................';
            app.ReleaseshearforcesTyLabel = uilabel(app.ReleaseOptionsButtonGroup);
            app.ReleaseshearforcesTyLabel.Text = 'Release shear-forces Ty .............................';
            app.ReleaseshearforcesTzLabel = uilabel(app.ReleaseOptionsButtonGroup);
            app.ReleaseshearforcesTzLabel.Text = 'Release shear-forces Tz .............................';
            app.ReleasetorquemomentMxLabel = uilabel(app.ReleaseOptionsButtonGroup);
            app.ReleasetorquemomentMxLabel.Text = 'Release torque-moment Mx........................';
            app.ReleasebendingmomentMyLabel = uilabel(app.ReleaseOptionsButtonGroup);
            app.ReleasebendingmomentMyLabel.Text = 'Release bending-moment My.....................';
            app.ReleasebendingmomentMzLabel = uilabel(app.ReleaseOptionsButtonGroup);
            app.ReleasebendingmomentMzLabel.Text = 'Release bending-moment Mz.....................';
            app.ApplyButton = uibutton(app.SetReleaseConditionUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.SetReleaseConditionUIFigure, 'push');
            app.DoneButton.Text = 'Done';
            app.Panel = uipanel(app.SetReleaseConditionUIFigure);
            app.REGIONID = uieditfield(app.Panel, 'text');
            app.REGIONID.Tooltip = {'POINT/VERTEX ID'};
            app.RegionIDLabel = uilabel(app.Panel);
            app.RegionIDLabel.Text = 'Region ID';
            % Show the figure after all components are created
            app.SetReleaseConditionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Release(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetReleaseConditionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetReleaseConditionUIFigure)
        end
    end
end