classdef ResultsPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SolutionOutputUIFigure  matlab.ui.Figure
        TextArea                matlab.ui.control.TextArea
    end

    
    methods (Access = public)
        
        function displaySolverOutput(app, model, elapsedTime)
                app.TextArea.Value = [app.TextArea.Value; " "];
                app.TextArea.Value = [app.TextArea.Value; 'SOLUTION OPTIONS'];
                app.TextArea.Value = [app.TextArea.Value; " "];
                app.TextArea.Value = [app.TextArea.Value; ['ELAPSED TIME ..................... ' num2str(elapsedTime)]];
                if contains(model.AnalysisType,'plane')
                app.TextArea.Value = [app.TextArea.Value; 'PROBLEM DIMENSIONALITY ........... 2D'];
                else
                app.TextArea.Value = [app.TextArea.Value; 'PROBLEM DIMENSIONALITY ........... 3D'];    
                end
                if contains(model.AnalysisType,'planeframe')
                app.TextArea.Value = [app.TextArea.Value; 'DEGREES OF FREEDOM ............... UX,UY,ROTZ'];
                elseif contains(model.AnalysisType,'spaceframe')
                app.TextArea.Value = [app.TextArea.Value; 'DEGREES OF FREEDOM ............... UX,UY,UZ,ROTX,ROTY,ROTZ'];
                else
                app.TextArea.Value = [app.TextArea.Value; 'DEGREES OF FREEDOM ............... UX,UY'];
                end
                app.TextArea.Value = [app.TextArea.Value; ['ANALYSIS TYPE .................... ',char(upper(model.AnalysisType))]];
                app.TextArea.Value = [app.TextArea.Value; " "];
                app.TextArea.Value = [app.TextArea.Value; 'MESH DATA'];
                app.TextArea.Value = [app.TextArea.Value; " "];
                if contains(model.AnalysisType,'planeframe')
                app.TextArea.Value = [app.TextArea.Value; 'ELEMENT TYPE ...................... BEAM23'];
                elseif contains(model.AnalysisType,'spaceframe')
                app.TextArea.Value = [app.TextArea.Value; 'ELEMENT TYPE ...................... BEAM26'];
                elseif contains(model.AnalysisType,'planestress')
                app.TextArea.Value = [app.TextArea.Value; 'ELEMENT TYPE ...................... TRI32PS'];
                else
                app.TextArea.Value = [app.TextArea.Value; 'ELEMENT TYPE ...................... TRI32PN'];    
                end
                app.TextArea.Value = [app.TextArea.Value; 'NUMBER OF NODES ................... ', num2str(size(model.Mesh.Nodes,2))];
                app.TextArea.Value = [app.TextArea.Value; 'NUMBER OF ELEMENTS ................ ', num2str(size(model.Mesh.Elements,2))];    
                app.TextArea.Value = [app.TextArea.Value; " "];
                app.TextArea.Value = [app.TextArea.Value; 'FEM EQUATIONS DATA'];
                app.TextArea.Value = [app.TextArea.Value; " "];
                app.TextArea.Value = [app.TextArea.Value; ['STIFFNESS MATRIX Ks: ........................ ', num2str(size(model.K,1)), 'x' num2str(size(model.K,1))]]; 
                app.TextArea.Value = [app.TextArea.Value; ['EXTERNAL FORCES VECTOR Fe: .................. ', num2str(size(model.F,1)) 'x1']]; 
                app.TextArea.Value = [app.TextArea.Value; ['ENFORCED DISPLACEMENT VECTOR Ud: ............ ', num2str(size(model.Ud,1)) 'x1']]; 
                app.TextArea.Value = [app.TextArea.Value; ['DIRICHLET BOUNDARY CONDITION MATRIX H: ...... ', num2str(size(model.H,1)) 'x' num2str(size(model.H,2))]]; 
                app.TextArea.Value = [app.TextArea.Value; ['DIRICHLET BOUNDARY CONDITION VECTOR R: ...... ', num2str(size(model.R,1)) 'x' num2str(size(model.R,2))]]; 
                app.TextArea.Value = [app.TextArea.Value; ['SOLUTION VECTOR U: .......................... ', num2str(size(model.U,1)) 'x1']]; 
                
                app.TextArea.Value = [app.TextArea.Value; " "];
                app.TextArea.Value = [app.TextArea.Value; 'SOLUTION'];
                app.TextArea.Value = [app.TextArea.Value; " "];                
                
                for i = 1:size(model.U,1)
                    app.TextArea.Value = [app.TextArea.Value; num2str(model.U(i),5)];
                end           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, model, elapsedTime, displayOpt)
            app.TextArea.Value = [app.TextArea.Value; upper('       SAPLAB - Structural Analysis Program Laboratory   ')];
            app.TextArea.Value = [app.TextArea.Value; app.TextArea.Value(1,:)];
            warning off
            Pos = app.SolutionOutputUIFigure.Position;
            app.SolutionOutputUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);                
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.SolutionOutputUIFigure.Icon = appIcon;         
            warning on
            if nargin==3
                app.displaySolverOutput(model, elapsedTime)
                return
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SolutionOutputUIFigure = uifigure('Visible', 'off');
            app.SolutionOutputUIFigure.Name = 'Solution Output';
            app.SolutionOutputUIFigure.Icon = 'Application Icon.png';
            app.TextArea = uitextarea(app.SolutionOutputUIFigure);
            app.TextArea.Value = {'***********************************************************'};
            % Show the figure after all components are created
            app.SolutionOutputUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResultsPlot(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SolutionOutputUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SolutionOutputUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SolutionOutputUIFigure)
        end
    end
end