classdef SectionProperties < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetStructuralSectionPropertiesUIFigure  matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        SECTIONPROPERTIESLabel      matlab.ui.control.Label
        Panel_2                     matlab.ui.container.Panel
        ViewElementLocalAxisButton  matlab.ui.control.Button
        DoneButton                  matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        PropertiesPanel             matlab.ui.container.Panel
        REGIONID                    matlab.ui.control.EditField
        RegionIDEditFieldLabel      matlab.ui.control.Label
        InterfacemechanicalpropertiesNormalStiffnessKNLabel_2  matlab.ui.control.Label
        InterfacemechanicalpropertiesNormalStiffnessKNLabel  matlab.ui.control.Label
        TorsionalConstantLabel      matlab.ui.control.Label
        CrosssectionalAreaLabel     matlab.ui.control.Label
        DescriptionLabel            matlab.ui.control.Label
        ValueLabel                  matlab.ui.control.Label
        CoefficientLabel            matlab.ui.control.Label
        Izz                         matlab.ui.control.EditField
        IzzLabel_2                  matlab.ui.control.Label
        IyyLabel_2                  matlab.ui.control.Label
        Iyy                         matlab.ui.control.EditField
        JxxLabel_2                  matlab.ui.control.Label
        Jxx                         matlab.ui.control.EditField
        ALabel_2                    matlab.ui.control.Label
        A                           matlab.ui.control.EditField
        SelectRegionButtonGroup     matlab.ui.container.ButtonGroup
        TypeofregionLabel           matlab.ui.control.Label
        StraightLineButton          matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        ParentApp; % Description
        RegionType;
        RegionID;
    end

    methods
        function set.RegionID(app, val)
            app.RegionID = val;
        end
        function reset(app)
            app.A.Value = '';
            app.Iyy.Value = '';
            app.Izz.Value = '';
            app.Jxx.Value = '';
            app.REGIONID.Value = '';
        end

        function [args, tf] = parseInputCoefficients(app)

            tf = false;

            if ~isempty(app.A.Value)
                [~,Area] = evalc(app.A.Value);
                args = {'Area', Area};
            else
                app.ParentApp.updateMessage('Invalid definition of Cross-Sectional Area.');
                tf = true; return;
            end
            if ~isempty(app.Iyy.Value) && ~isempty(app.Izz.Value)
                [~,IyyVal] = evalc(app.Iyy.Value);
                [~,IzzVal] = evalc(app.Izz.Value);
                args = [args, {'Inertia', [IyyVal,IzzVal]}];
            end
            if ~isempty(app.Iyy.Value) && isempty(app.Izz.Value)
                [~,IyyVal] = evalc(app.Iyy.Value);
                args = [args, {'Inertia', IyyVal}];
            end
            if isempty(app.Iyy.Value) && ~isempty(app.Izz.Value)
                [~,IzzVal] = evalc(app.Izz.Value);
                args = [args, {'Inertia', [0,IzzVal]}];
            end
            if ~isempty(app.Jxx.Value)
                [~,JxxVal] = evalc(app.Jxx.Value);
                args = [args, {'TorsionalConstant', JxxVal}];
            end       
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off
            Pos = app.SetStructuralSectionPropertiesUIFigure.Position;
            app.SetStructuralSectionPropertiesUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);           
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.SetStructuralSectionPropertiesUIFigure.Icon = appIcon;                  
            
            if nargin == 2
                app.ParentApp=parentApp;
            end         
            warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)


            [args, tf] = parseInputCoefficients(app);
            if tf
                app.ParentApp.updateMessage('Invalid definition of cross-sectional area value.');
                return;
            end

            [~,app.RegionID] = evalc(app.REGIONID.Value);

            if or(any(~isrounded(app.RegionID)), any(app.RegionID<=0)) || isempty(app.RegionID)
                app.ParentApp.updateMessage('Region ID must be a rounded and positive value');
                return
            end
            
            structuralModel = app.ParentApp.MODEL;
            for i=1:numel(app.RegionID)
                try
                    saplab.setSectionProperties(structuralModel,'Line',app.RegionID(i),args{:});
                catch ME
                    app.ParentApp.updateMessage(ME.message,uistyle('FontColor','r'));
                    return                    
                end
            end
            msg = ['Section Properties set successfully to REGIONTYPE (Line) with REGIONID (' num2str(app.RegionID) ')'];
            app.ParentApp.updateMessage(msg);

            app.reset();
            saplab.ui.updateLayersAnalysis(app.ParentApp);
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.StraightLineButton
                app.RegionType = 'Line';
            elseif selectedButton == app.FaceButton
                app.RegionType = 'Face';
            elseif selectedButton == app.ReinforcementButton
                app.RegionType = 'Reinforcement';
            end
            
        end

        % Callback function
        function DefineModelButtonPushed(app, event)

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetStructuralSectionPropertiesUIFigure = uifigure('Visible', 'off');
            app.SetStructuralSectionPropertiesUIFigure.Name = 'Set Structural Section Properties';
            app.SetStructuralSectionPropertiesUIFigure.Icon = 'Application Icon.png';
            app.GridLayout = uigridlayout(app.SetStructuralSectionPropertiesUIFigure);
            app.SelectRegionButtonGroup = uibuttongroup(app.GridLayout);
            app.SelectRegionButtonGroup.Title = 'Select Region ';
            app.StraightLineButton = uiradiobutton(app.SelectRegionButtonGroup);
            app.StraightLineButton.Tooltip = {'Analyze frames in XY plane'};
            app.StraightLineButton.Text = 'Straight Line';
            app.StraightLineButton.Value = true;
            app.TypeofregionLabel = uilabel(app.SelectRegionButtonGroup);
            app.TypeofregionLabel.Text = 'Type of region:';
            app.PropertiesPanel = uipanel(app.GridLayout);
            app.PropertiesPanel.Title = 'Properties';
            app.A = uieditfield(app.PropertiesPanel, 'text');
            app.A.Tooltip = {''};
            app.ALabel_2 = uilabel(app.PropertiesPanel);
            app.ALabel_2.Text = 'A';
            app.Jxx = uieditfield(app.PropertiesPanel, 'text');
            app.Jxx.Tooltip = {'MUST BE IN RANGE 0; +0.5'};
            app.JxxLabel_2 = uilabel(app.PropertiesPanel);
            app.JxxLabel_2.Text = 'Jxx';
            app.Iyy = uieditfield(app.PropertiesPanel, 'text');
            app.Iyy.Tooltip = {'MUST BE IN RANGE 0; +Inf'};
            app.IyyLabel_2 = uilabel(app.PropertiesPanel);
            app.IyyLabel_2.Text = 'Iyy';
            app.IzzLabel_2 = uilabel(app.PropertiesPanel);
            app.IzzLabel_2.Text = 'Izz';
            app.Izz = uieditfield(app.PropertiesPanel, 'text');
            app.Izz.Tooltip = {'MUST BE POSITIVE'};
            app.CoefficientLabel = uilabel(app.PropertiesPanel);
            app.CoefficientLabel.Text = 'Coefficient';
            app.ValueLabel = uilabel(app.PropertiesPanel);
            app.ValueLabel.Text = 'Value';
            app.DescriptionLabel = uilabel(app.PropertiesPanel);
            app.DescriptionLabel.Text = 'Description';
            app.CrosssectionalAreaLabel = uilabel(app.PropertiesPanel);
            app.CrosssectionalAreaLabel.Text = 'Cross-sectional Area';
            app.TorsionalConstantLabel = uilabel(app.PropertiesPanel);
            app.TorsionalConstantLabel.Text = 'Torsional Constant';
            app.InterfacemechanicalpropertiesNormalStiffnessKNLabel = uilabel(app.PropertiesPanel);
            app.InterfacemechanicalpropertiesNormalStiffnessKNLabel.Text = 'Inertia along y-y axis';
            app.InterfacemechanicalpropertiesNormalStiffnessKNLabel_2 = uilabel(app.PropertiesPanel);
            app.InterfacemechanicalpropertiesNormalStiffnessKNLabel_2.Text = 'Inertia along z-z axis';
            app.RegionIDEditFieldLabel = uilabel(app.PropertiesPanel);
            app.RegionIDEditFieldLabel.Text = 'Region ID';
            app.REGIONID = uieditfield(app.PropertiesPanel, 'text');
            app.Panel_2 = uipanel(app.GridLayout);
            app.ApplyButton = uibutton(app.Panel_2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.Panel_2, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ViewElementLocalAxisButton = uibutton(app.Panel_2, 'push');
            app.ViewElementLocalAxisButton.Icon = 'localAxes01.png';
            app.ViewElementLocalAxisButton.Text = 'View Element Local Axis';
            app.SECTIONPROPERTIESLabel = uilabel(app.GridLayout);
            app.SECTIONPROPERTIESLabel.Text = 'SECTION PROPERTIES';
            % Show the figure after all components are created
            app.SetStructuralSectionPropertiesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SectionProperties(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetStructuralSectionPropertiesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetStructuralSectionPropertiesUIFigure)
        end
    end
end