classdef ThermalLoad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        APPLYBODYFORCESUIFigure        matlab.ui.Figure
        ThermalloadTttrefNoteditableLabel  matlab.ui.control.Label
        ReferenceTemperaturetrefLabel  matlab.ui.control.Label
        TemperaturetLabel              matlab.ui.control.Label
        InputargumentsLabel            matlab.ui.control.Label
        RegionIDLabel_2                matlab.ui.control.Label
        HelpButton                     matlab.ui.control.Button
        DoneButton                     matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        RegionIDThermalloadpropertiesLabel  matlab.ui.control.Label
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        RegionIDLabel                  matlab.ui.control.Label
        REGIONID                       matlab.ui.control.EditField
        TLabel                         matlab.ui.control.Label
        DT                             matlab.ui.control.EditField
        trefLabel                      matlab.ui.control.Label
        REFTEMP                        matlab.ui.control.EditField
        tLabel                         matlab.ui.control.Label
        THERMAL                        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        RegionType;
        RegionID;
        ParentApp;
        NumRegions;
    end

    methods
        function initApp(app)
            app.RegionType = 'Face';
            if contains(app.ParentApp.MODEL.AnalysisType, 'frame')
                app.RegionType = 'Line';
                app.NumRegions = app.ParentApp.MODEL.Geometry.NumLines;
            else
                app.NumRegions = app.ParentApp.MODEL.Geometry.NumFaces;
            end
        end

        function reset(app)
            app.THERMAL.Value = '';
            app.RegionID.Value = '';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            Pos = app.APPLYBODYFORCESUIFigure.Position;
            app.APPLYBODYFORCESUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);  
            saplab.ui.custom.customButton(app.ApplyButton)
            saplab.ui.custom.customButton(app.DoneButton)
            saplab.ui.custom.customButton(app.HelpButton) 

            if nargin == 2
                app.ParentApp = parentApp;
                app.initApp();
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            try
                if isempty(app.REGIONID.Value)
                    style = uistyle('FontColor','r');
                    app.ParentApp.updateMessage('Invalid Region ID', style);
                    return
                end          

                [~,app.RegionID] = evalc(app.REGIONID.Value);

                if ~isrounded(app.RegionID) || app.RegionID<=0
                    style = uistyle('FontColor','r');
                    app.ParentApp.updateMessage('Region ID must be a rounded and positive value', style);
                    return
                end
                MSG = [];
                if app.RegionID > app.NumRegions
                    MSG = ['Region ID must not exceed (' num2str(app.NumLines) ')'];
                end
                if ~isempty(MSG)
                    app.ParentApp.updateMessage(MSG,uistyle('FontColor','r'));
                    return;
                end                

                if isempty(app.THERMAL.Value)
                    value = 0;
                else
                    [~,value] = evalc(app.THERMAL.Value);
                end                

                if ~isnumeric(value) && ~isscalar(value) 
                    MSG = 'Invalid temperature value.';
                    app.ParentApp.updateMessage(MSG,uistyle('FontColor','r'));
                    return;
                end    

                if isempty(app.REFTEMP.Value)
                    tref = 0;
                else
                    [~,tref] = evalc(app.REFTEMP.Value);
                end                

                if ~isnumeric(tref) && ~isscalar(tref) 
                    MSG = 'Invalid reference temperature value.';
                    app.ParentApp.updateMessage(MSG,uistyle('FontColor','r'));
                    return;
                end  

                [~,tref] = evalc(app.REFTEMP.Value);
                dt = value - tref;

                app.DT.Value = string(dt);
                structuralBodyLoad(app.ParentApp.MODEL,app.RegionType,app.RegionID,"Temperature",dt);
            
            catch ME
                app.ParentApp.updateMessage(ME.message,uistyle("FontColor",'r')); return
            end

            msg = strcat('Thermal load has been applied to REGIONTYPE (', string(app.RegionType) ,') with ID (', num2str(app.RegionID), ')');
            app.ParentApp.updateMessage(msg);
            
            app.ParentApp.plot2('Imported');
            saplab.ui.updateLayersAnalysis(app.ParentApp);
        end

        % Value changed function: REFTEMP
        function REFTEMPValueChanged(app, event)
            value = app.REFTEMP.Value;
            
        end

        % Value changed function: THERMAL
        function THERMALValueChanged(app, event)
            value = app.THERMAL.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.APPLYBODYFORCESUIFigure = uifigure('Visible', 'off');
            app.APPLYBODYFORCESUIFigure.Name = 'APPLY BODY FORCES';
            app.APPLYBODYFORCESUIFigure.Icon = 'Application Icon.png';
            app.ButtonGroup_3 = uibuttongroup(app.APPLYBODYFORCESUIFigure);
            app.THERMAL = uieditfield(app.ButtonGroup_3, 'text');
            app.THERMAL.ValueChangedFcn = createCallbackFcn(app, @THERMALValueChanged, true);
            app.THERMAL.Tooltip = {''};
            app.tLabel = uilabel(app.ButtonGroup_3);
            app.tLabel.Text = 't';
            app.REFTEMP = uieditfield(app.ButtonGroup_3, 'text');
            app.REFTEMP.ValueChangedFcn = createCallbackFcn(app, @REFTEMPValueChanged, true);
            app.REFTEMP.Tooltip = {''};
            app.REFTEMP.Value = '0';
            app.trefLabel = uilabel(app.ButtonGroup_3);
            app.trefLabel.Text = 'tref';
            app.DT = uieditfield(app.ButtonGroup_3, 'text');
            app.DT.Editable = 'off';
            app.DT.Tooltip = {''};
            app.TLabel = uilabel(app.ButtonGroup_3);
            app.TLabel.Text = 'ΔT';
            app.REGIONID = uieditfield(app.ButtonGroup_3, 'text');
            app.REGIONID.Tooltip = {''};
            app.RegionIDLabel = uilabel(app.ButtonGroup_3);
            app.RegionIDLabel.Text = 'Region ID';
            app.RegionIDThermalloadpropertiesLabel = uilabel(app.APPLYBODYFORCESUIFigure);
            app.RegionIDThermalloadpropertiesLabel.Text = 'RegionID/Thermal load properties';
            app.ApplyButton = uibutton(app.APPLYBODYFORCESUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.APPLYBODYFORCESUIFigure, 'push');
            app.DoneButton.Text = 'Done';
            app.HelpButton = uibutton(app.APPLYBODYFORCESUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.RegionIDLabel_2 = uilabel(app.APPLYBODYFORCESUIFigure);
            app.RegionIDLabel_2.Text = '- Region ID';
            app.InputargumentsLabel = uilabel(app.APPLYBODYFORCESUIFigure);
            app.InputargumentsLabel.Text = 'Input arguments:';
            app.TemperaturetLabel = uilabel(app.APPLYBODYFORCESUIFigure);
            app.TemperaturetLabel.Text = '- Temperature (t)';
            app.ReferenceTemperaturetrefLabel = uilabel(app.APPLYBODYFORCESUIFigure);
            app.ReferenceTemperaturetrefLabel.Text = '- Reference Temperature (tref)';
            app.ThermalloadTttrefNoteditableLabel = uilabel(app.APPLYBODYFORCESUIFigure);
            app.ThermalloadTttrefNoteditableLabel.Text = '- Thermal load (ΔT = t-tref - Not editable)';
            % Show the figure after all components are created
            app.APPLYBODYFORCESUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThermalLoad(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.APPLYBODYFORCESUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.APPLYBODYFORCESUIFigure)
        end
    end
end