classdef TrimEdge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrimEdgesUIFigure   matlab.ui.Figure
        DoneButton          matlab.ui.control.Button
        ApplyButton         matlab.ui.control.Button
        OptionsButtonGroup  matlab.ui.container.ButtonGroup
        DistanceLabel       matlab.ui.control.Label
        Distance            matlab.ui.control.EditField
        EdgeIDLabel         matlab.ui.control.Label
        EdgeID              matlab.ui.control.EditField
        SurfaceTagLabel     matlab.ui.control.Label
        Tag                 matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            warning off 
            Pos = app.TrimEdgesUIFigure.Position;
            app.TrimEdgesUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);  
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.TrimEdgesUIFigure.Icon = appIcon;          

            if nargin == 2
                app.ParentApp = parentApp;
            end        

            warning on
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            try
            tag = app.Tag.Value;
            [~,edgeID] = evalc(app.EdgeID.Value);
            [~,dist] = evalc(app.Distance.Value);
            gm=saplab.trimEdge(app.ParentApp.MODEL.Geometry,tag,'Edge',[edgeID,dist]);
            app.ParentApp.updadateCurrGeomDropDownValue(gm);
            app.ParentApp.updateMessage('Edge trimmed successfully!');
            msg = strcat('Generated New Composite Object with LABEL (', num2str(gm.tag), ')');
            app.ParentApp.updateMessage(msg);
            catch ME
            app.ParentApp.updateMessage(ME.message,uistyle('FontColor','r'));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrimEdgesUIFigure = uifigure('Visible', 'off');
            app.TrimEdgesUIFigure.Name = 'Trim Edges';
            app.OptionsButtonGroup = uibuttongroup(app.TrimEdgesUIFigure);
            app.OptionsButtonGroup.Title = 'Options';
            app.Tag = uieditfield(app.OptionsButtonGroup, 'text');
            app.Tag.Tooltip = {'POINT/VERTEX ID'};
            app.Tag.Value = 'R1';
            app.SurfaceTagLabel = uilabel(app.OptionsButtonGroup);
            app.SurfaceTagLabel.Text = 'Surface Tag';
            app.EdgeID = uieditfield(app.OptionsButtonGroup, 'text');
            app.EdgeID.Tooltip = {'POINT/VERTEX ID'};
            app.EdgeIDLabel = uilabel(app.OptionsButtonGroup);
            app.EdgeIDLabel.Text = 'Edge ID';
            app.Distance = uieditfield(app.OptionsButtonGroup, 'text');
            app.Distance.Tooltip = {'POINT/VERTEX ID'};
            app.DistanceLabel = uilabel(app.OptionsButtonGroup);
            app.DistanceLabel.Text = 'Distance';
            app.ApplyButton = uibutton(app.TrimEdgesUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DoneButton = uibutton(app.TrimEdgesUIFigure, 'push');
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.TrimEdgesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrimEdge(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrimEdgesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrimEdgesUIFigure)
        end
    end
end