classdef TrimLine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrimLineInEqualSegmentsUIFigure  matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        UntrimLineButton              matlab.ui.control.StateButton
        ApplyButton                   matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        OptionsPanel                  matlab.ui.container.Panel
        SelectlinetotrimSpinner       matlab.ui.control.Spinner
        SelectlinetotrimSpinnerLabel  matlab.ui.control.Label
        NDivisionLabel                matlab.ui.control.Label
        NDIV                          matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ParentApp; % Description
        Nargin;
        DeleteMode = false;
    end

    methods
        function reset(app)
            app.NDIV.Value = '';
        end

        function insertingMode(app)
           try
            if app.Nargin == 2
                Rid = app.SelectlinetotrimSpinner.Value;
                if Rid > app.ParentApp.MODEL.Geometry.NumLines
                msg = strcat('Line (', num2str(Rid),') not found.');
                app.ParentApp.updateMessage(msg);
                return
                end
                [~,nDiv] = evalc(app.NDIV.Value);
            end
                saplab.trimLine(app.ParentApp.MODEL,'Line',[Rid,nDiv]);
                msg = strcat('Line (', num2str(Rid),') has been trimmed in (',num2str(nDiv),') segments.');
                app.ParentApp.updateMessage(msg)
            catch ME
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage(ME.message,style);
            end
        end

        function deleteMode(app)
            try
            Rid = app.SelectlinetotrimSpinner.Value;
            if Rid > app.ParentApp.MODEL.Geometry.NumLines
            msg = strcat('Line (', num2str(Rid),') not found.');
            app.ParentApp.updateMessage(msg);
            return
            end 
            app.ParentApp.MODEL.Geometry.deleteTrim(Rid);
            msg = strcat('Line (', num2str(Rid),') has been untrimmed.');
            app.ParentApp.updateMessage(msg)
            catch ME
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage(ME.message,style);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, LineID)
            warning off
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.TrimLineInEqualSegmentsUIFigure.Icon = appIcon;
            Pos = app.TrimLineInEqualSegmentsUIFigure.Position;
            app.TrimLineInEqualSegmentsUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);   
            if nargin == 2
                app.ParentApp = parentApp;
                app.Nargin = nargin;
            end
            warning on
          
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            %trim line. Use try/catch to catch exceptions.
            if ~app.DeleteMode
                app.insertingMode();
            else
                app.deleteMode();
            end

            app.ParentApp.plot();
            reset(app)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete()
        end

        % Value changed function: UntrimLineButton
        function UntrimLineButtonValueChanged(app, event)
            value = app.UntrimLineButton.Value;
            if value
                app.DeleteMode = true;
                app.SelectlinetotrimSpinnerLabel.Text = 'Select line to untrim';
                app.NDIV.Enable = 'off';
                app.NDivisionLabel.Enable = 'off';
            else
                app.DeleteMode = false;
                app.SelectlinetotrimSpinnerLabel.Text = 'Select line to trim';
                app.NDIV.Enable = 'on';
                app.NDivisionLabel.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrimLineInEqualSegmentsUIFigure = uifigure('Visible', 'off');
            app.TrimLineInEqualSegmentsUIFigure.Name = 'Trim Line In Equal Segments';
            app.TrimLineInEqualSegmentsUIFigure.Icon = 'Application Icon.png';
            app.OptionsPanel = uipanel(app.TrimLineInEqualSegmentsUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.NDIV = uieditfield(app.OptionsPanel, 'text');
            app.NDIV.Tooltip = {'Set the number of segments to generate from line'};
            app.NDivisionLabel = uilabel(app.OptionsPanel);
            app.NDivisionLabel.Text = 'N. Division';
            app.SelectlinetotrimSpinnerLabel = uilabel(app.OptionsPanel);
            app.SelectlinetotrimSpinnerLabel.Text = 'Select line to trim';
            app.SelectlinetotrimSpinner = uispinner(app.OptionsPanel);
            app.SelectlinetotrimSpinner.Limits = [0 Inf];
            app.SelectlinetotrimSpinner.RoundFractionalValues = 'on';
            app.SelectlinetotrimSpinner.Value = 1;
            app.DoneButton = uibutton(app.TrimLineInEqualSegmentsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ApplyButton = uibutton(app.TrimLineInEqualSegmentsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Panel = uipanel(app.TrimLineInEqualSegmentsUIFigure);
            app.UntrimLineButton = uibutton(app.Panel, 'state');
            app.UntrimLineButton.ValueChangedFcn = createCallbackFcn(app, @UntrimLineButtonValueChanged, true);
            app.UntrimLineButton.Text = 'Untrim Line';
            % Show the figure after all components are created
            app.TrimLineInEqualSegmentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrimLine(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrimLineInEqualSegmentsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrimLineInEqualSegmentsUIFigure)
        end
    end
end