classdef TrimLineAtLocation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrimLineAtSpecificLocationUIFigure  matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        UntrimLineButton              matlab.ui.control.StateButton
        ApplyButton                   matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        CalculateDistanceFromButtonGroup  matlab.ui.container.ButtonGroup
        EndPointButton                matlab.ui.control.RadioButton
        StartPointButton              matlab.ui.control.RadioButton
        OptionsPanel                  matlab.ui.container.Panel
        SelectlinetotrimSpinner       matlab.ui.control.Spinner
        SelectlinetotrimSpinnerLabel  matlab.ui.control.Label
        DistanceLabel                 matlab.ui.control.Label
        DISTANCE                      matlab.ui.control.EditField
    end

    properties (Access = private)
        ParentApp; % Description
        Nargin;
        DeleteMode = false;
    end

    methods
        function reset(app)
            app.DISTANCE.Value = '';
        end

        function insertingMode(app)
            try
            Rid = app.SelectlinetotrimSpinner.Value;
            [~,dist] = evalc(app.DISTANCE.Value);
            if ~isscalar(dist)
            msg = strcat('Distance must be a numeric positive scalar');
            app.ParentApp.updateMessage(msg); return;
            end
            if Rid > app.ParentApp.MODEL.Geometry.NumLines
            msg = strcat('Line (', num2str(Rid),') not found.');
            app.ParentApp.updateMessage(msg);
            return
            end
            if app.CalculateDistanceFromButtonGroup.SelectedObject == app.StartPointButton
                loc = 'start';
            else
                loc = 'end';
            end
            saplab.trimLineAtLocation(app.ParentApp.MODEL,'Line',[Rid,dist],'origin',loc);
            msg = strcat('Line (', num2str(Rid),') has been trimmed at distance (',num2str(dist),') from ', loc, ' point');
            app.ParentApp.updateMessage(msg)
            catch ME           
            app.ParentApp.updateMessage(ME.message);
            end
        end
        function deleteMode(app)
            try
            Rid = app.SelectlinetotrimSpinner.Value;
            if Rid > app.ParentApp.MODEL.Geometry.NumLines
            msg = strcat('Line (', num2str(Rid),') not found.');
            app.ParentApp.updateMessage(msg);
            return
            end 
            g=app.ParentApp.MODEL.Geometry;
            g.deleteTrim(Rid);
            msg = strcat('Line (', num2str(Rid),') has been untrimmed.');
            app.ParentApp.updateMessage(msg)
            catch ME
                style = uistyle('FontColor','r');
                app.ParentApp.updateMessage(ME.message,style);
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, LineID)
            warning off
            appIcon = struct(matlab.ui.internal.toolstrip.Icon.MATLAB_24).ImageSource;
            app.TrimLineAtSpecificLocationUIFigure.Icon = appIcon;
            Pos = app.TrimLineAtSpecificLocationUIFigure.Position;
            app.TrimLineAtSpecificLocationUIFigure.Position = saplab.ui.internal.getNiceDialogLocation(Pos);             
            
            if nargin == 2
                app.ParentApp = parentApp;
                app.Nargin = nargin;
            end
            warning on
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.delete()
        end

        % Value changed function: UntrimLineButton
        function UntrimLineButtonValueChanged(app, event)
            value = app.UntrimLineButton.Value;
            if value
                app.DeleteMode = true;
                app.SelectlinetotrimSpinnerLabel.Text = 'Select line to untrim';
                app.DISTANCE.Enable = 'off';
                app.DistanceLabel.Enable = 'off';
            else
                app.DeleteMode = false;
                app.SelectlinetotrimSpinnerLabel.Text = 'Select line to trim';
                app.DISTANCE.Enable = 'on';
                app.DistanceLabel.Enable = 'on';
            end            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed2(app, event)
            if ~app.DeleteMode
                app.insertingMode();
            else
                app.deleteMode();
            end

            app.ParentApp.plot();
            app.reset();              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrimLineAtSpecificLocationUIFigure = uifigure('Visible', 'off');
            app.TrimLineAtSpecificLocationUIFigure.Name = 'Trim Line At Specific Location';
            app.TrimLineAtSpecificLocationUIFigure.Icon = 'Application Icon.png';
            app.OptionsPanel = uipanel(app.TrimLineAtSpecificLocationUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.DISTANCE = uieditfield(app.OptionsPanel, 'text');
            app.DISTANCE.Tooltip = {'Distance'; ''};
            app.DistanceLabel = uilabel(app.OptionsPanel);
            app.DistanceLabel.Text = 'Distance';
            app.SelectlinetotrimSpinnerLabel = uilabel(app.OptionsPanel);
            app.SelectlinetotrimSpinnerLabel.Text = 'Select line to trim';
            app.SelectlinetotrimSpinner = uispinner(app.OptionsPanel);
            app.SelectlinetotrimSpinner.Limits = [0 Inf];
            app.SelectlinetotrimSpinner.RoundFractionalValues = 'on';
            app.SelectlinetotrimSpinner.Value = 1;
            app.CalculateDistanceFromButtonGroup = uibuttongroup(app.TrimLineAtSpecificLocationUIFigure);
            app.CalculateDistanceFromButtonGroup.Title = 'Calculate Distance From';
            app.StartPointButton = uiradiobutton(app.CalculateDistanceFromButtonGroup);
            app.StartPointButton.Text = 'Start Point';
            app.StartPointButton.Value = true;
            app.EndPointButton = uiradiobutton(app.CalculateDistanceFromButtonGroup);
            app.EndPointButton.Text = 'End Point';
            app.DoneButton = uibutton(app.TrimLineAtSpecificLocationUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ApplyButton = uibutton(app.TrimLineAtSpecificLocationUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed2, true);
            app.ApplyButton.Text = 'Apply';
            app.Panel = uipanel(app.TrimLineAtSpecificLocationUIFigure);
            app.UntrimLineButton = uibutton(app.Panel, 'state');
            app.UntrimLineButton.ValueChangedFcn = createCallbackFcn(app, @UntrimLineButtonValueChanged, true);
            app.UntrimLineButton.Text = 'Untrim Line';
            % Show the figure after all components are created
            app.TrimLineAtSpecificLocationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrimLineAtLocation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrimLineAtSpecificLocationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrimLineAtSpecificLocationUIFigure)
        end
    end
end